classdef BRM_MicroscopeV2_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AcquisitionTab                 matlab.ui.container.Tab
        CancelAcquisitionButton        matlab.ui.control.Button
        Exposure_Units                 matlab.ui.control.DropDown
        AcquireImagesButton            matlab.ui.control.Button
        SaveDirectory                  matlab.ui.control.EditField
        SaveDirectoryLabel             matlab.ui.control.Label
        SaveDirLabel                   matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
        Exposure_time_2                matlab.ui.control.NumericEditField
        ExpTimemsLabel                 matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        CameraTab                      matlab.ui.container.Tab
        CameraPropertiesPanel          matlab.ui.container.Panel
        ResetCameraButton              matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        YOffsetEditField               matlab.ui.control.NumericEditField
        YOffsetEditFieldLabel          matlab.ui.control.Label
        XOffsetEditField               matlab.ui.control.NumericEditField
        XOffsetEditFieldLabel          matlab.ui.control.Label
        ResolutionLabel                matlab.ui.control.Label
        X0Y0Label                      matlab.ui.control.Label
        ROIPositionpxLabel             matlab.ui.control.Label
        CameraBinningDropDown          matlab.ui.control.DropDown
        CameraBinningDropDownLabel     matlab.ui.control.Label
        AutoContrastButton             matlab.ui.control.StateButton
        HeightEditField                matlab.ui.control.NumericEditField
        HeightpxEditFieldLabel         matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        WidthpxEditFieldLabel          matlab.ui.control.Label
        MotorTab                       matlab.ui.container.Tab
        UnselectButton                 matlab.ui.control.Button
        ClearAllButton                 matlab.ui.control.Button
        LocationLabelEditField         matlab.ui.control.EditField
        LocationLabelEditFieldLabel    matlab.ui.control.Label
        SaveLocationButton             matlab.ui.control.Button
        SavedLocationsListBox          matlab.ui.control.ListBox
        SavedLocationsListBoxLabel     matlab.ui.control.Label
        LinearPolarizersPanel          matlab.ui.container.Panel
        HomemotorsButton               matlab.ui.control.Button
        Rotate_Detection_2             matlab.ui.control.Button
        IlluminationEditField          matlab.ui.control.NumericEditField
        IlluminationEditFieldLabel     matlab.ui.control.Label
        DetectionEditField             matlab.ui.control.NumericEditField
        DetectionEditFieldLabel        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Rotate_Detection               matlab.ui.control.Button
        CurrentposLabel                matlab.ui.control.Label
        ill_polarizer                  matlab.ui.control.Label
        Det_polarizer                  matlab.ui.control.Label
        StageControlPanel              matlab.ui.container.Panel
        AutoFocusButton                matlab.ui.control.Button
        y_coord_text                   matlab.ui.control.Label
        x_coord_text                   matlab.ui.control.Label
        text54_4                       matlab.ui.control.Label
        text54_3                       matlab.ui.control.Label
        MoveXYmotorButton              matlab.ui.control.Button
        text54_2                       matlab.ui.control.Label
        text54                         matlab.ui.control.Label
        y_coord_entry                  matlab.ui.control.EditField
        x_coord_entry                  matlab.ui.control.EditField
        SampleTab                      matlab.ui.container.Tab
        SampleParametersPanel          matlab.ui.container.Panel
        SampleNotes                    matlab.ui.control.TextArea
        SampleNotestxtcreatedinFileTextAreaLabel  matlab.ui.control.Label
        SampleNameEditField            matlab.ui.control.EditField
        SampleNameEditFieldLabel       matlab.ui.control.Label
        ZStackStepSize                 matlab.ui.control.NumericEditField
        ZStackStepSizeLabel            matlab.ui.control.Label
        ZStackoffset                   matlab.ui.control.NumericEditField
        ZStackOffsetLabel              matlab.ui.control.Label
        mLabel_5                       matlab.ui.control.Label
        SamplethicknessOffsetLabel     matlab.ui.control.Label
        mLabel_4                       matlab.ui.control.Label
        mLabel_3                       matlab.ui.control.Label
        SampleThickness                matlab.ui.control.NumericEditField
        SampleThicknessEditFieldLabel  matlab.ui.control.Label
        GridTab                        matlab.ui.container.Tab
        SitchingParametersPanel        matlab.ui.container.Panel
        TileOverlapEditField           matlab.ui.control.NumericEditField
        TileOverlapEditFieldLabel      matlab.ui.control.Label
        SetGlobalPlaneButton           matlab.ui.control.Button
        SelectPositionButton           matlab.ui.control.Button
        UnSelectPositionButton         matlab.ui.control.Button
        msLabel_5                      matlab.ui.control.Label
        msLabel_4                      matlab.ui.control.Label
        ExposureTime2                  matlab.ui.control.NumericEditField
        ExposureTime2EditFieldLabel    matlab.ui.control.Label
        ExposureTime1                  matlab.ui.control.NumericEditField
        ExposureTime1Label             matlab.ui.control.Label
        LR_Location                    matlab.ui.control.Label
        LRLabel                        matlab.ui.control.Label
        SetLowerRightButton            matlab.ui.control.Button
        UL_Location_lbl                matlab.ui.control.Label
        ULLabel                        matlab.ui.control.Label
        SetUpperLeftButton             matlab.ui.control.Button
        GridSelectionDropDown          matlab.ui.control.DropDown
        GridSelectionDropDownLabel     matlab.ui.control.Label
        StitchingType                  matlab.ui.control.DropDown
        StitchingTypeDropDownLabel     matlab.ui.control.Label
        Grid_yentry                    matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        Grid_xentry                    matlab.ui.control.NumericEditField
        GridSizeLabel_3                matlab.ui.control.Label
        Grid_axis                      matlab.ui.control.UIAxes
        ROITab                         matlab.ui.container.Tab
        StitchingType_2                matlab.ui.control.DropDown
        StitchingTypeDropDownLabel_2   matlab.ui.control.Label
        SelectPositionButton_2         matlab.ui.control.Button
        UnSelectPositionButton_2       matlab.ui.control.Button
        ResetROIsButton                matlab.ui.control.Button
        ROIExposureEditFieldLabel      matlab.ui.control.Label
        ROIExposureEditField           matlab.ui.control.NumericEditField
        ROISelection                   matlab.ui.control.ListBox
        ROI_axis                       matlab.ui.control.UIAxes
        ZpositionTab                   matlab.ui.container.Tab
        zPositionLabel                 matlab.ui.control.Label
        ReadzpositionButton            matlab.ui.control.Button
        Camera_view                    matlab.ui.control.Button
        filename_ext                   matlab.ui.control.Label
        FileName                       matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        ImagePreviewPanel              matlab.ui.container.Panel
        Label_9                        matlab.ui.control.Label
        FPSLabel                       matlab.ui.control.Label
        LoadSamplingGridButton         matlab.ui.control.Button
        NumberofROIs                   matlab.ui.control.NumericEditField
        NumberofROIsEditFieldLabel     matlab.ui.control.Label
        RandomizedROIs                 matlab.ui.control.Button
        LoadWideFieldROIButton         matlab.ui.control.Button
        AddROIButton                   matlab.ui.control.Button
        ROI_Magnification              matlab.ui.control.DropDown
        MagnificationDropDown_2Label   matlab.ui.control.Label
        Image_axis                     matlab.ui.control.UIAxes
        Histogram_axis                 matlab.ui.control.UIAxes
        SystemStatusPanel              matlab.ui.container.Panel
        LoadAcqusitionButton           matlab.ui.control.Button
        StagePositionLabel             matlab.ui.control.Label
        Stage_pos                      matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        MagnificationDropDown          matlab.ui.control.DropDown
        MagnificationDropDownLabel     matlab.ui.control.Label
        AcquisitionType                matlab.ui.control.DropDown
        AcquisitionTypeLabel_3         matlab.ui.control.Label
        MicroscopeSetup                matlab.ui.control.DropDown
        Label_5                        matlab.ui.control.Label
        MicroscopeSetupLabel           matlab.ui.control.Label
        CurrentOperationLabel          matlab.ui.control.Label
        System_status                  matlab.ui.control.Label
        Grid_size_status               matlab.ui.control.Label
        GridSizeLabel_2                matlab.ui.control.Label
        Progress_Bar                   matlab.ui.control.UIAxes
        SettingsTab                    matlab.ui.container.Tab
        GirdSelectionPanel             matlab.ui.container.Panel
        LeftButton                     matlab.ui.control.Button
        UpButton                       matlab.ui.control.Button
        RightButton                    matlab.ui.control.Button
        DownButton                     matlab.ui.control.Button
        ROISelectionListBoxLabel       matlab.ui.control.Label
        AcquisitionPanel               matlab.ui.container.Panel
        AutoFocus                      matlab.ui.control.CheckBox
        Exposure_time                  matlab.ui.control.NumericEditField
        ExposureTimeLabel              matlab.ui.control.Label
        ImageSettingsPanel             matlab.ui.container.Panel
        GridLayoutPanel                matlab.ui.container.Panel
        AutofocusButton                matlab.ui.control.Button
        Random_ExposureTime            matlab.ui.control.NumericEditField
        ExposureTimeEditFieldLabel     matlab.ui.control.Label
        PrevButton                     matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        RandomLamp                     matlab.ui.control.Lamp
        FocusLevel                     matlab.ui.control.Label
        SetFocusButton                 matlab.ui.control.Button
        randomized_image_lbl           matlab.ui.control.Label
        GridStatusLamp                 matlab.ui.control.Lamp
        GridStatusLampLabel            matlab.ui.control.Label
        FocusPos2                      matlab.ui.control.Label
        CurrentFocusLabel              matlab.ui.control.Label
        Image_Axis_2                   matlab.ui.control.UIAxes
        Histogram_axis2                matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        x_coord_motor=55;
        y_coord_motor=37.5;
        det_degree_settings;
        ill_degree_settings;
        det_RCA_LCA_settings;
        ill_RCA_LCA_settings;
        motor_x;
        motor_y;
        motor_ill;
        motor_det;
        save_folder;
        z_step = 1; % 1 micron
        saved_coordinates = cell(1,1);
        saved_coordinates_string = cell(1,1);
        saved_z_coordinates = zeros(1,3);
        saved_z_coordinates_string = cell(1,1);
            
        zstack_display = zeros(1200,1600,41);
        zstack_fm = zeros(41,1);
        i_zstack = 0;

        % FOV in microns calculated from calibration slide for each magnification
        mag_list = {'4X','10X','16X','20X','32X','40X','64X','60X','96X'};
        mags = [4 10 16 20 32 40 64 60 96]';
        %pixels_per_100_microns = [54.054 136 210 270 420 542 840 815 1310]';
        pixels_per_100_microns = ((100.*[4 10 16 20 32 40 64 60 96])./4.5)';
        
        %FOV_pixels = [1200; 1600]; % [y, x] Old camera
        FOV_pixels = [2200; 3200]; % [y, x]
        mag_index = 4;
        mag = 20;
        obj_string = '20X';

        overlap_for_stitching = 0.1; % percent

        stitching_perimeter = zeros(1,2);
        stitching_coordinates = 0;
        rectangular_stitching = 0;
        perimeter_stitching = 0;
        rectangular_stitch_folder = '';
        perimeter_stitch_folder = '';
        ULx = 55;
        ULy = 37.5;
        LRx = 0;
        LRy = 250;
        grid_size = 0;

        grid_size_x = 2;
        grid_size_y = 2;
        x_grid_mesh;
        y_grid_mesh;
        x_img_mesh;
        y_img_mesh;
        empty_tile = false;
        z_img_mesh = 0;
        FOV_microns_y;
        FOV_microns_x;
        FOV_microns_stitching_y;
        FOV_microns_stitching_x;
        mmc;
        saved_z_positions;
        z_position;
        exposure_time = 400;

        %%%%%%%%%%%%%%%%%%%%%%%%%% add ons for new GUI %%%%%%%%%%%%%%%%%%%
        path_location = '\\ad\eng\users\a\l\algray\My Documents\MATLAB\qBRM\'; % main folder location
        Experimental_file_location = ''; % create experiment folder
        acquisiton_type;
        directory_created = 0;
        documents_folder;
        directory_counter = 2;
        filename_counter = 2 % Description

        correction_factor = 15; %Correction from 10X objective to 40x
        exp_times = [2 5 10 15 32 40 64 60 96]'; % Description
        rect_handler = gobjects(2,2); % create graphics array to store graphing handlers
        grid_select_index = [1,1];

        Grid_select_points; % number of selected grid points
        grid_plane; % intialize array to hold points for plane calculation
        stitched_img_location; % Location of current image that was stiched used for saving
        ROI_counter = 0; % Number of ROIs
        ROI_handler = gobjects(5,1); % create graphics array to store graphing handlers max is 5 ROIs
        ROI_position_handler; % get position for ROI imaging
        ROI_mag_index = 4; % magnification for ROI imaging from 20X images
        qBRM_step = 30; % Step size for the illumination/Detection movement during qBRM images
        autofocus_start =1; % Description
        ROI_1; % Handlers for listeners for ROIs
        ROI_2; % Handlers for listeners for ROIs
        ROI_3; % Handlers for listeners for ROIs
        ROI_struct; % Description
        ROI_string_array = ""; % Description
        current_ROI_img = 0; %handler for the most recent ROI image

        grid_select_size_handler = [2 2]; % keep track of how big the current grid is
        current_stitched_img; % Description
        text_numbers = '9788863884'; % Add people that you want to send text updates
        pixel_size = 7.4; % pixel size in microns
        save_folder_name='C:\'; % Description
        new_foldername=''; % Description
        default_save_folder='C:\PSOCT\Images';
        widefield_image; % create space to store most recent widefield image
        widefield_image_UL;
        widefield_folder;

        % randomized ROI location coordinates
        patchx_coord;
        patchy_coord;
        polygon_coord; % Description
        maxX;
        maxY;% Description
        Randomized_locations; %Locations of randomized 40X images
        random_sampling = 0; % Description
        random_focuses = []; % Description
        random_counter = 0; % Description
        random_exposure = [];


        %%%%%% attributes used for grid sampling %%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        pixels_4x_40X = [220 320]; % Description
        %image_size = [1200 1600];
        image_size = [2200 3200];
        patchx;
        patchy;
        grid_array;
        gridtile_array;
        image_rectangles; % Description % Description
        grid_locations = []; % (X,Y) locations of grid sampling (in stage space mm)
        widefield_loaded = 0;
        current_grid_selection =[1,1];
        grid_idx_selected = [0,0];
        grid_idx = []; % Description

        %%%%%%% attributes for new camera setup %%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        vid; % Description
        hImage;
        cam_info;
        video_setting;
        src;
        original_position;
        patch_handle;
        top_left;
        ROI_width_height;
        histo;
        camera_pixels = [3200, 2200];
        %mmc;
        current_z_position;
        objective_move = 100;
        selected_gridpoints;
        reset_grid = 0;
        camera_loaded = 0;

        myTimer; %timer to handle displaying motor stuff in the background
        saved_locations = [55;37.5]; % Description
        acquisition_flag = false; % Flag used to stop an acquisiton
        motor_x_coord;
        motor_y_coord;
    end

    methods (Access = private)
        function fm = measure_focus(app)
            img = snap_image(app.Exposure_time.Value,app.mmc);
            fm = fmeasure(img,'BREN');
            app.FocusLevel.Text = sprintf('Focus Level (au) = %10.2f', fm);
        end

        function [x_coord, y_coord] = read_motor_position(app)
            % read position of the motor
            x_coord = System.Decimal.ToDouble(app.motor_x.Position);
            y_coord = System.Decimal.ToDouble(app.motor_y.Position);
        end

        function [ill_coord, det_coord] = read_rotat_stage_position(app)
            % read position of the motor
            ill_coord = System.Decimal.ToDouble(app.motor_ill.Position);
            det_coord = System.Decimal.ToDouble(app.motor_det.Position);
            app.ill_polarizer.Text = sprintf('%.2f',ill_coord);
            app.Det_polarizer.Text = sprintf('%.2f',det_coord);
        end

        function move_motor(app, x_coord, y_coord)
            % moves XY stage to preset position
            moveto(app, app.motor_x, x_coord);
            moveto(app, app.motor_y, y_coord);
            % update motor position text
            [x_coord, y_coord] = read_motor_position(app);
            app.x_coord_text.Text = num2str(x_coord);
            app.y_coord_text.Text = num2str(y_coord);
        end

        function moveto(app, motor, position)
            try
                workDone=motor.InitializeWaitHandler(); % Initialise Waithandler for timeout
                motor.MoveTo(position, workDone);       % Move devce to position via .NET interface
                motor.Wait(100000);              % Wait for move to finish
            catch
                disp('Unable to Move Device. Device will be homed');
                motor.Home(100000);
            end
        end

        function ask_to_insert_16x_lens(app)
            % Ask user to insert 1.6x lens into detection path
            d = dialog('Position',[300 300 400 100],'Name','Insert 1.6x Lens');
            d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                'Click "Continue" once the 1.6x lens has been inserted into the detection path.');
            d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                'Callback','delete(gcf)');
            uiwait(d);
        end
        function ask_user_mmc(app, display_text)
            if(strcmp(app.camera_status.Text, 'Micro-Manager') == 1)
                d = dialog('Position',[300 300 400 100],'Name','Exit Micro-Manager');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    display_text);
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
        end

        function plot_grid(app, x_min, x_max, y_min, y_max)
            % Plotting
            hold(app.UIAxes,'on')
            plot(app.UIAxes, app.x_img_mesh,app.y_img_mesh,'.','Color','red');
            plot(app.UIAxes,app.x_grid_mesh,app.y_grid_mesh,'Color','blue');
            plot(app.UIAxes,app.x_grid_mesh',app.y_grid_mesh','Color','blue');

            ax = gca;
            ax.XLim = [x_min-1.5 x_max+1.5];
            ax.YLim = [y_min-1.5 y_max+1.5];
            ax.YDir = 'reverse';
            ax.XDir = 'reverse';
        end

        function load_camera_with_mmc(app)
            try
                disp('micromanager')
                % Load camera through Micro-Manager core
                import mmcorej.*;
                app.mmc = CMMCore;
                app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control

            catch ME
                message = 'Error Loading Camera, Restart Camera and Try again';
                uiwait(warndlg(message));
            end

        end

        function input_save_filename(app)
            %title = 'Enter the save name for this image set.';
            %user_answer = inputdlg('Enter the save name for this image set:',...
            %    title,[1 length(title)+30]);
            %save_string = user_answer{1,1};
            %generate_file_extension(app);

            save_string = ['\' num2str(app.FileName.Value)];
            cd(app.save_folder);
            mkdir(save_string);
            app.rectangular_stitch_folder = [app.save_folder,'\',save_string];
            cd(app.rectangular_stitch_folder);
        end

        % Save multi-exposure images for current xy location
        function save_multi_exp_images(app, exp_times, img_index, img, z_focus)
            for i = 1:length(exp_times)
                cd([app.rectangular_stitch_folder,'\',sprintf('exp%dms',exp_times(i,1))]);

                if(img_index < 10)
                    new_filename = sprintf('img_00%d.tif',img_index);
                elseif(img_index > 99)
                    new_filename = sprintf('img_%d.tif',img_index);
                else
                    new_filename = sprintf('img_0%d.tif',img_index);
                end
                % Save images with indexing for ImageJ Stitching plug-in
                imwrite(int16(img{y,x}(:,:,i)),new_filename);

                % Update saved Z-plane information
                save('z_focus.mat','z_focus');
            end
        end

        %disconnects all motors
        function disconnect_all_motors(app)
            app.motor_x.StopPolling();
            app.motor_x.Disconnect();

            app.motor_y.StopPolling();
            app.motor_y.Disconnect();

            app.motor_ill.StopPolling();
            app.motor_ill.Disconnect();

            app.motor_det.StopPolling();
            app.motor_det.Disconnect();
        end

        %opens window to choose magnification
        function [mag, mag_index] = choose_magnification(app)
            [app.mag_index,~] = listdlg('PromptString','Choose the magnification for stitching acquisition:','SelectionMode','Single',...
                'Name','Magnification Selection','ListSize',[300 200],'ListString',app.mag_list);
            mag = app.mags(app.mag_index,1);
            if(app.mag_index == 1 || app.mag_index == 2 || app.mag_index == 4 || app.mag_index == 6 || app.mag_index == 8)
                app.obj_string = app.mag_list{1,app.mag_index};
            else
                app.obj_string = app.mag_list{1,app.mag_index - 1};

                % Ask user to insert 1.6x lens into detection path
                d = dialog('Position',[300 300 400 100],'Name','Insert 1.6x Lens');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'Click "Continue" once the 1.6x lens has been inserted into the detection path.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
            mag_index = app.mag_index;
        end

        %find z position, so the sample is in focus
        function [img, current_z] = autofocusing(app, correction_factor, exp_time, objective, z_focus_last)
            % Variable correction_factor is used to correct for the slight displacement
            start_pos = 6600;
            %if auto focus with 40x do it with 4x first
            if strcmp(objective, '40X')
                [img, start_pos] = autofocusing(app, 0, 45, '10X', 'nothing');
            end
            %change_magnification(app, objective)
            %app.mmc.setConfig('Objective', objective);

            if  app.autofocus_start==0  % handles the first autofocus to get us in the general range for sample
                app.mmc.setPosition(start_pos);
                pause(1)
            end
            current_z = load_mmcore(app);
            done = 0;
            fine_adj = 0;
            n = 1;
            best_pos = [start_pos 0];
            coarse_increment = [75 75 50 45 40 30 30 30];
            fine_increment = [7 7 4 4 3 2 2 2];
            direction = -1; % negative to start first two
            update_progress_bar(app, 15);
            increment = coarse_increment(app.mag_index);
            temp_best = 0; %temporary val to compare values to
            %find the proper direction
            for x = 1:3
                fprintf('Iteration #%d \n',n);
                app.mmc.setPosition(current_z);
                pause(0.5);
                img = snap_image(exp_time,app.mmc);
                fm(n,1) = fmeasure(img,'BREN');
                fm_pos(n,1) = current_z;
                if fm(n,1) > best_pos(2)
                    temp_best = best_pos(2);
                    best_pos = [fm_pos(n,1) fm(n,1)];
                end
                fprintf('Best Position: %d %d \n', best_pos(1), best_pos(2))
                n = n+1;
                current_z = current_z + direction*increment; % Move objective in the current direction
                app.mmc.reset();
            end

            if best_pos(1) == start_pos
                direction = 1;
                current_z = best_pos(1);
                app.mmc.setPosition(current_z);
            end

            while ~(done)
                fprintf('Iteration #%d \n',n);
                if fine_adj
                    increment = fine_increment(app.mag_index);
                    fprintf('(fine) \n');
                end
                current_z = current_z + direction*increment; % Move objective in the current direction
                app.mmc.setPosition(current_z);
                pause(0.5);
                img = snap_image(exp_time,app.mmc);
                fm(n,1) = fmeasure(img,'BREN');
                fm_pos(n,1) = current_z;

                if fm(n,1) < (temp_best)
                    if fine_adj
                        done = 1;
                        figure
                        plot(fm_pos, fm, 'ro')
                    else
                        % if its less than the prev val then go back to
                        % our best position (offset by a little in case
                        % we missed out best point during course
                        % increment
                        current_z =  best_pos(1) - direction*increment;
                        fine_adj = 1;
                    end

                else
                    temp_best = best_pos(2);
                    best_pos = [fm_pos(n,1) fm(n,1)];
                end
                fprintf('Best Position: %d \n', best_pos(1))
                n = n+1;
            end


            %                 while ~(done)
            %                     fprintf('Iteration #%d  ',n-2);
            %                    if fine_adj
            %                         increment = fine_increment(app.mag_index);
            %                         fprintf('(fine) \n');
            %                     end
            %
            %                             % If most recent iteration was more in-focus than the second most recent iteration, continue in that direction
            %                     if(fm(n - 1,1) < fm(n - 2,1))
            %                         direction = -direction;
            %                     end
            %
            %                     current_z = current_z + direction*increment; % Move objective in the current direction
            %                     app.mmc.setPosition(current_z);
            %                     pause(0.5);
            %                     img = snap_image(exp_time,app.mmc);
            %                     fm(n,1) = fmeasure(img,'BREN');
            %                     fm_pos(n,1) = current_z;
            %
            %                     % After n = 3 iteration, the direction the z-stage needs to move has been determined
            %                     if(n > 7)
            %                         if(fm(n,1) < fm(n-1,1))
            %                             update_progress_bar(app, 65);
            %                             direction = -direction;
            %                             if(fine_adj == 0)
            %                                 figure
            %                                 plot(fm_pos, fm, 'r.')
            %                                 fine_adj = 1;
            %
            %                             else
            %                                 done = 1;
            %                             end
            %                         end
            %                     end
            %
            %                 end
            %
            update_progress_bar(app, 0);
            current_z = best_pos(1) - correction_factor; % Round to nearest integer

            pause(0.3)
        end

        function acquire_preview_image(app, main_window_preview)
            img = snap_image(app.Exposure_time.Value,app.mmc);
            fm = fmeasure(img,'BREN');
            app.FocusLevel.Text = sprintf('Focus Level (au) = %10.2f', fm);
            app.System_status.Text = 'Acquiring Image';
            
            if main_window_preview
                imshow(uint8(img), 'Parent', app.Image_Axis, 'InitialMagnification', 'fit');
            end
            imshow(uint8(img), 'Parent', app.Image_Axis_2, 'InitialMagnification', 'fit');
            histogram(app.Histogram_axis, img);
            histogram(app.Histogram_axis2, img);
            app.Label_9.Text = num2str(mean(mean(img)));
            app.widefield_loaded = 0;
            app.System_status.Text = 'Standby';
            
        end

        %take entry box input and create save directory (or CD to whatever
        %is already saved
        function create_save_directory(app)
            if (~strcmp(app.AcquisitionType.Value, 'Grid Sampling')) && (~strcmp(app.AcquisitionType.Value, 'ROI Imaging'))
                disp('New Folder')
                new_folder_name = app.SaveDirectory.Value;
                cd([app.save_folder_name]);
                if ~exist(new_folder_name, 'dir')
                    mkdir(new_folder_name);
                end
                app.save_folder = strcat(app.save_folder_name,'\',new_folder_name);
                cd(app.save_folder);
                app.directory_created = 1;

                % try create the file folder
                filedir = convertStringsToChars(strcat(app.FileName.Value, '_exp', string(app.Exposure_time.Value),...
                    'ms_mag', num2str(app.MagnificationDropDown.Value), 'X\'));
                if ~exist(filedir, 'dir')
                    mkdir(filedir);
                    cd([app.save_folder, '\', filedir]);
                else
                    filedir = convertStringsToChars(strcat(app.FileName.Value, '_exp', string(app.Exposure_time.Value), 'ms_mag',...
                        num2str(app.MagnificationDropDown.Value), '_', num2str(app.directory_counter), '\'));
                    mkdir(filedir);
                    cd([app.save_folder, '\', filedir]);
                    app.FileName.Value = strcat(app.FileName.Value, '_', num2str(app.directory_counter)); % update filename so it doesnt run into errors
                    app.filename_counter = app.filename_counter + 1;
                end
                app.save_folder = [app.save_folder, '\', filedir];

                %             new_folder_name = app.SaveDirectory.Value;
                %             cd([app.save_folder_name,'\Images']);
                %             if ~exist(new_folder_name, 'dir')
                %                 mkdir(new_folder_name);
                %             end
                %             app.save_folder = strcat(app.save_folder_name,'\Images\',new_folder_name);
                %             cd([app.save_folder_name,'\Images\',new_folder_name]);
                %             app.directory_created = 1;
                %
                %             % try create the file folder
                %             filedir = convertStringsToChars(strcat(app.FileName.Value, '_exp', string(app.Exposure_time.Value),...
                %                 'ms_mag', app.MagnificationDropDown.Value, '\'));
                %             if ~exist(filedir, 'dir')
                %                 mkdir(filedir);
                %                 cd([app.save_folder, '\', filedir]);
                %             else
                %                 filedir = convertStringsToChars(strcat(app.FileName.Value, '_exp', string(app.Exposure_time.Value), 'ms_mag',...
                %                     app.MagnificationDropDown.Value, '_', num2str(app.directory_counter), '\'));
                %                 mkdir(filedir);
                %                 cd([app.save_folder, '\', filedir]);
                %                 app.FileName.Value = strcat(app.FileName.Value, '_', num2str(app.directory_counter)); % update filename so it doesnt run into errors
                %                 app.filename_counter = app.filename_counter + 1;
                %             end
                %             app.save_folder = [app.save_folder, '\', filedir];
            end
        end

        function write_image_format(app, image, filename, format)
            imwrite(image, filename, format);
        end

        function update_grid_2(app)
            cla(app.Grid_axis,'reset') %reset axis
            %app.rect_handler = gobjects(app.Grid_xentry.Value,app.Grid_yentry.Value); % reinitialize the grid plot handler
            x = app.Grid_xentry.Value;
            y = app.Grid_yentry.Value;
            Generatearray = @(A,S,N) A+S*(0:N-1);
            
            x_max = app.ULx; % the actual position of the ROI in the Img space
            x_step = app.FOV_microns_stitching_x(app.mag_index)/1000;
            %x_min = x_max - x*x_step;
            center_posx = Generatearray(x_max,-x_step,x);
            %center_posx = x_max:-x_step:x_min;

            y_max = app.ULy;  % its actually the min value but its the top left of our matrix
            y_step = app.FOV_microns_stitching_y(app.mag_index)/1000;
            %y_min = y_max + y*y_step;
            center_posy = Generatearray(y_max,y_step,y);
            %center_posy = y_max:y_step:y_min;

            app.gridtile_array = gobjects(x,y); % reinitialize the grid plot handler
            %app.image_rectangles = gobjects(x,y); % reinitialize the grid plot handler
            for row = x:-1:1 
                for col = y:-1:1
                    app.gridtile_array(col, row) = rectangle('Position', [(row-1), (col-1), 1, 1],...
                        'Parent', app.Grid_axis, ...
                        'EdgeColor', 'red', ...
                        'FaceColor', [0, 0, 0]);
                end
            end
            app.gridtile_array(app.current_grid_selection(2), app.current_grid_selection(1)).EdgeColor = [1 1 0];
            [app.x_img_mesh, app.y_img_mesh] = meshgrid(center_posx, center_posy);
            hold(app.UIAxes,'on');
            app.Grid_axis.YDir = 'Reverse';
            %app.Grid_axis.XDir = 'Reverse';
            app.selected_gridpoints = [];
            if app.reset_grid
                app.current_grid_selection = [1,1];
                app.grid_idx_selected = [0,0];
                app.reset_grid = 0;
            end
            if size(app.grid_idx_selected, 1) > 1
                for x = 2:size(app.grid_idx_selected, 1)
                    app.gridtile_array(app.grid_idx_selected(x,2),app.grid_idx_selected(x,1)).FaceColor = 'Green';
                end
            end
        end

        function z_plane = calc_z_plane(app)
            if app.current_ROI_img
                grid_plane = app.ROI_struct(app.current_ROI_img).grid_plane;
                x_mesh = app.ROI_struct(app.current_ROI_img).gridx;
                y_mesh = app.ROI_struct(app.current_ROI_img).gridy;
            else
                grid_plane = app.grid_plane;
                x_mesh = app.x_img_mesh;
                y_mesh = app.y_img_mesh;

            end
            
            P1 = grid_plane(:,:,2); % extract data points to define plane
            P2 = grid_plane(:,:,3);
            P3 = grid_plane(:,:,4);
            N = cross(P1-P2,P1-P3); % find normal vector
            N = N./norm(N); % normalaze the value
            A = N(1); B=N(2); C=N(3); % define coordinates of normal vector
            x0 = P1(1); y0 = P1(2); z0 = P1(3); % define coordinated of the origin
            z_plane = (-A.*(x_mesh-x0)-B.*(y_mesh-y0)+C.*z0)./C; % find z values with plane equation
        end

        function reset_grid_parameters(app)
            app.Grid_select_points = 1;
            app.grid_plane;
            app.SelectPositionButton.Enable = 'on';
            app.UnSelectPositionButton.Enable = 'off';
        end

        function Fiji_stitching(app, directory, gridx, gridy, basic)
            app.System_status.Text = 'Running Image Stitching';
            update_progress_bar(app, 25);
            directory = join(split(directory,'\'),'\\');
            directory = directory{1};
            %%%% create macro for stitching with parameters
            overlap = app.overlap_for_stitching*100;
            names = 'img_{iii}.tif';
            if basic == 1
                names = 'img_basic{iii}.tif';
            end
            fileID = fopen('C:\Users\BMOadmin\Documents\MATLAB\Control GUI\APP_designer\macro.ijm','w');
            fprintf(fileID, 'run("Grid/Collection stitching", "type=[Grid: snake by rows] order=[Right & Down] grid_size_x=%d grid_size_y=%d tile_overlap=%d first_file_index_i=1 directory=%s file_names=%s output_textfile_name=TileConfiguration.txt fusion_method=[Linear Blending] regression_threshold=0.30 max/avg_displacement_threshold=2.50 absolute_displacement_threshold=3.50 compute_overlap computation_parameters=[Save memory (but be slower)] image_output=[Write to disk] output_directory=%s");', gridx,gridy,overlap,directory,names,directory);
            fclose(fileID);
            %%% run macro from command line with ImageJ headless window
            system('"C:\Program Files\Fiji.app\ImageJ-win64.exe" --headless --console -macro C:\Users\blank\OneDrive\_Documents\_BU\_Bigio\MATLAB\qBRM_BMO\APP_designer\macro.ijm')
            cd(directory)

            if strcmp(app.StitchingType.Value, 'Multi-Plane') && strcmp(app.AcquisitionType.Value, 'ROI Imaging')
                mkdir('IMG_SEQ'); %create image sequence folder to save multiplane stitches
                done = 0;
                counter = 1;
                while (~done)
                    try
                        loc = strcat(directory, '\', sprintf('img_t1_z%02d_c1', counter));
                        image_read = Tiff(loc);
                        image_read = read(image_read);
                        img_stack(:,:,counter) = image_read;
                        location = strcat(directory, '\IMG_SEQ\',sprintf('img_%03d.tif', counter));
                        imwrite(image_read, location, 'tiff')
                        filename = strcat(directory, '\IMG_SEQ\', 'MP_stitched_ROI.tif');
                        
                        imwrite(uint16(img_stack(:,:,counter)), filename, 'tif', 'WriteMode', 'append');
                        counter = counter +1;

                    catch ME
                        done= 1;
                    end
                end
            end

            if strcmp(app.AcquisitionType.Value, 'Widefield (4X)')
                app.stitched_img_location = directory;
                direct_loc = [directory '\Stitched_image'];
                movefile  img_t1_z1_c1 Stitched_image %rename file
                disp(direct_loc)
                image_read = Tiff(direct_loc);
                image_read = read(image_read);
                imwrite(uint16(image_read), 'Stitched_image.tif', 'tiff')
                imshow(uint16(image_read), 'Parent', app.Image_axis);
                app.widefield_image = uint16(image_read); % store widefield image as an uint8 image (doesnt need to be full 16 bit 
                app.widefield_image_UL = [app.ULx app.ULy];
                app.widefield_folder = app.save_folder;
                app.widefield_loaded = 1;
            end
            update_progress_bar(app, 0);
            app.System_status.Text = 'Writing Image to Disk';
            update_progress_bar(app, 50);

            update_progress_bar(app, 0);
            app.System_status.Text = 'Standby';
        end

        function save_multiplaneROI_imgseqeunce(app)
            disp('Hello')
        end

        function FIJI_save_img_seq(app, directory, filename)
            javaaddpath("C:\Program Files\MATLAB\R2020a\java\jar\mij.jar");
            MIJ.start('C:\Program Files\Fiji.app\plugins'); % actually start fiji with the correct plugins
            split_dir = strsplit(directory, '\');
            actual_dir = join(split_dir, '\\');
            actual_dir = actual_dir{1};
            cmd_str = sprintf('path=[%s]', strcat(directory, filename));
            MIJ.run('Open...', cmd_str);
            cd(directory);
            mkdir('IMG_SEQ');
            cd('IMG_SEQ');
            new_dir = [actual_dir 'IMG_SEQ\\'];
            cmd_string = sprintf('format=TIFF save =%s file=[%s]', new_dir, new_dir);
            message = sprintf('Make Sure to save Image sequence into %s folder for post processing',new_dir);
            uiwait(msgbox(message), 4);
            try
                MIJ.run('Image Sequence... ', cmd_string);
            catch ME
                disp('Image Sequence was Not saved')
            end

            %TURN OFF fIJI AND write the image to disk and display on GUI
            MIJ.closeAllWindows
            MIJ.exit
        end

        function update_progress_bar(app, value)
            rectangle(app.Progress_Bar,"FaceColor",'green','EdgeColor','green', 'Position',[0 0 value/100, 1]);
            app.Progress_Bar.Title.String = sprintf('%.0f%%',round(value));
            drawnow
            if value ==0
                cla(app.Progress_Bar);
                app.Progress_Bar.Title.String = ' ';
            end
        end

        function all_events(app, src, event, x, mag)
            name = event.EventName;
            label = split(src.Label);

            %update string without grid size
            new_label = {label{1} label{2}};
            src.Label = strjoin(new_label);

            label = strsplit(label{1},'-');
            roi_idx = str2num(label{2});
            disp('new')
            if strcmp(name, 'ROIMoved')
                ROI_mag_index = find(app.mags == mag); %Find index from the Mag character

                % cakc the actual grid from this.
                % Compute actual xy locations for rectangular grid acquisition
                x_max = app.ULx - event.CurrentPosition(1)/(10*app.pixels_per_100_microns(1)) - app.FOV_microns_x(ROI_mag_index)/2000; % the actual position of the ROI in the Img space
                x_min = app.ULx - event.CurrentPosition(1)/(10*app.pixels_per_100_microns(1)) - event.CurrentPosition(3)/(10*app.pixels_per_100_microns(1));
                x_step = app.FOV_microns_stitching_x(ROI_mag_index)/1000;
                x_img = x_max:-x_step:x_min;

                y_max = app.ULy + event.CurrentPosition(2)/(10*app.pixels_per_100_microns(1)) + app.FOV_microns_y(ROI_mag_index)/2000;
                y_min = app.ULy + event.CurrentPosition(2)/(10*app.pixels_per_100_microns(1)) + event.CurrentPosition(4)/(10*app.pixels_per_100_microns(1));
                y_step = app.FOV_microns_stitching_y(ROI_mag_index)/1000; % convert FOV in microns to mm
                y_img = y_max-(y_step/2):y_step:y_min;

                [x_mesh,y_mesh] = meshgrid(x_img,y_img);

                % change the size of the ROI rectangle to match the actual size of stitching (round up)
                x_size = 100*((size(x_mesh,2)+1)*app.FOV_microns_stitching_x(ROI_mag_index))/(app.pixels_per_100_microns(ROI_mag_index));
                y_size = 100*((size(x_mesh,1)+1)*app.FOV_microns_stitching_y(ROI_mag_index))/(app.pixels_per_100_microns(ROI_mag_index));
                src.Position = [event.CurrentPosition(1) event.CurrentPosition(2) x_size y_size];

                % update ROI string with the grid size on it
                src.Label = strcat(src.Label, sprintf(' (%dX%d)',size(x_mesh,2),size(x_mesh,1)));
                %update ROI struct to include mesh of points and
                %magnification
                app.ROI_struct(roi_idx).gridx = x_mesh;
                app.ROI_struct(roi_idx).gridy = y_mesh;
                app.ROI_struct(roi_idx).magnification = mag;
                app.ROI_struct(roi_idx).grid_selected = 1;
                app.ROI_struct(roi_idx).index_selected = [];
                app.ROI_struct(roi_idx).z_mesh = [];
                app.ROI_struct(roi_idx).grid_plane = [];
                app.ROI_struct(roi_idx).Exposure_time = 250;
                app.ROI_struct(roi_idx).position = event.CurrentPosition;
                app.ROI_struct(roi_idx).label = src.Label;
                app.ROI_struct(roi_idx).folder = app.save_folder;
                disp(x_mesh)
            end
        end

        function Check_LR_position(app)
            if (app.ULx < app.LRx) || (app.ULy > app.LRy)
                message = 'Invalid Location';
                uiwait(warndlg(message), 5);
                app.LRx = app.ULx - app.FOV_microns_stitching_x(app.mag_index)/1000;
                app.LRy = app.ULy + app.FOV_microns_stitching_y(app.mag_index)/1000;
            end
            update_UL_LR_lables(app);
        end

        function Check_UL_position(app)
            if (app.ULx < app.LRx) || (app.ULy > app.LRy)
                message = 'Invalid Location';
                uiwait(warndlg(message),5);
                app.LRx = app.ULx - app.FOV_microns_stitching_x(app.mag_index)/1000;
                app.LRy = app.ULy + app.FOV_microns_stitching_y(app.mag_index)/1000;
            end
            update_UL_LR_lables(app);
        end

        function update_UL_LR_lables(app)
            app.UL_Location_lbl.Text = sprintf('(%.2f,%.2f)', app.ULx, app.ULy);
            if strcmp(app.GridSelectionDropDown.Value, 'Two Corners')
                app.LR_Location.Text = sprintf('(%.2f,%.2f)', app.LRx, app.LRy);
            else
                app.LR_Location.Text = 'NA';
            end
        end

        function ff_images = take_flat_field_image(app)
            app.System_status.Text = 'Acquiring Flat-Field Images';
            drawnow;
            %save current motor position
            [x_pos, y_pos] = read_motor_position(app);
            % move sample "Home" to image flat-filed
            move_motor(app, 55.5, 37.5);
            pause(1);
            ff_images = take_qBFR_image(app);
            move_motor(app, x_pos, y_pos);
        end

        function dark_frame_avg = take_dark_field_image(app, num_images, exposure_time)
            app.System_status.Text = 'Acquiring Dark-Field Images';
            app.mmc.setConfig('lightpath','Eye'); % change light path to look at eyepiece (dark field)
            pause(1.5); % wait until shutter was changed
            dark_frame_avg = uint16(zeros(2200,3200));
            update_progress_bar(app, 0)
            for n = 1:num_images
                dark_frames = Image_snapshot(app, exposure_time);
                dark_frame_avg = dark_frame_avg + dark_frames;
                update_progress_bar(app, 100*n/num_images)
            end
            dark_frame_avg = dark_frame_avg./num_images;
            app.mmc.setConfig('lightpath','Side Port'); % change light path back to camera
            update_progress_bar(app, 0)
        end

        function img = take_qBFR_image(app)
            update_progress_bar(app, 0)
            img = cell(1,3);
            qBRM_point_degree = [0, 30, 90];
            for step = 1:3
                %%%%% Cancel Acquisiton Check %%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if app.acquisition_flag
                    cancel_acquisition(app);
                    return
                end

                app.motor_ill.MoveTo(qBRM_point_degree(step), 60000); % move the linear polarizer after each image
                if( step == 1)
                    pause(3); % Extra pause to allow the motorized stage to get to the first setting
                end
                pause(1)
                update_progress_bar(app, 100*(step/6));
                img{1,step} = double(Image_snapshot(app,app.Exposure_time.Value));
            end
        end


        % take in file location to look at all images in that folder and
        % perform correction and overwrite
        function BaSIC_Correction(app, images_dir, plot_val, varargin)
            try
                % read in Image set and do correction and overwrite all images
                files =dir([images_dir '*.tif']);
                for i = 1:length(files)
                    IF(:,:,i) = double(imread([images_dir files(i).name])); % original image
                end
                update_progress_bar(app, 10);
                % estimate flatfield and darkfield
                % For fluorescence images, darkfield estimation is often necessary (set
                % 'darkfield' to be true)
                [flatfield] = BaSiC(IF); % darkfield is usually just for fluorescence but we use just in case
                update_progress_bar(app, 65);
                % plot estimated shading files
                % note here darkfield does not only include the contribution of microscope
                % offset, but also scattering light that entering to the light path, which
                % adds to every image tile
                if plot_val
                    figure; subplot(121); imagesc(flatfield);colorbar; title('Estimated flatfield');
                end

                % image correction
                IF_corr = zeros(size(IF));
                for i = 1:length(files)
                    IF_corr(:,:,i) = (double(IF(:,:,i)))./flatfield;
                end
                update_progress_bar(app, 85);
                cd(images_dir)
                % save images for stitching (overwrite current files)
                for i = 1:length(files)
                    imwrite(uint16(IF_corr(:,:,i)),sprintf('img_basic%03d.tif', i));
                end
                update_progress_bar(app, 0);
            catch ME
                disp('Nope')
            end

        end

        function reset_ROI_settings(app)
            app.AddROIButton.Enable = 'off';
            app.ROI_Magnification.Enable = 'off';
            app.ROI_counter = 0;
            app.ROI_handler = gobjects(5,1); % create graphics array to store graphing handlers max is 5 ROIs
            app.ROI_position_handler; % get position for ROI imaging
            app.ROI_mag_index = 1; % magnification (Default 20X) for ROI imaging from 4X images
            app.ROISelection.Enable = 'off';
            app.ROISelection.Items = {};
            app.ROI_struct;
            app.current_ROI_img = 0;
            app.Grid_xentry.Enable = 'on';
            app.Grid_yentry.Enable = 'on';
            update_grid_2(app);
            app.StitchingType.Enable = 'on';
        end

        function SCC_upload(app, file_location)
            current = pwd; % save current dir so that we can go back into it later
            command = "qsub /project/gpumcml/Alex_qBRM/Output_stream/fit_qBRM_cores.qsub";
            % go into directory to use putty scp for upload to the SCC
            cd("C:\Users\blank\OneDrive\_Documents\_BU\_Bigio\MATLAB\qBRM_BMO\APP_designer\SCC\")

            % upload files with putty pscp command
            disp('Uploading to SCC')
            system(sprintf('pscp -pw WormTime103 -r "%s" algray@scc1.bu.edu:/project/gpumcml/Alex_qBRM/Images/', file_location));

            % SSH into SCC then execute qsub script for fitting
            disp('Running post Processing remotely')
            system("plink.exe -ssh algray@scc1.bu.edu -pw WormTime103 -m test_sh.sh");

            %system("plink.exe -ssh scc1.bu.edu -pw WormTime103 -l algray sh /project/gpumcml/Alex_qBRM/Output_stream/Run_fitting.sh");
            % this command generalizes this function to a specific command to the SCC
            %system(sprintf('plink.exe -ssh scc1.bu.edu -pw WormTime103 -l algray %s', command); %

            cd(current) % reset working directory for saving
        end

        function  [image, fmap] = focus_stacking(app, im_loc)
            addpath('C:\Users\blank\OneDrive\_Documents\_BU\_Bigio\MATLAB\qBRM_BMO\APP_designer')
            cd(im_loc)
            a = dir('*.tif');
            n = numel(a)-1;
            fprintf('number of images: %d',n)
            img = cell(1,n);
            for i = 1:n
                img{i} = imread(sprintf('img_%03d.tif',i));
            end
            [image, fmap] = fstack(img);
        end

        function corrected_images = ff_correction(app, raw, dark, ff)
            %% input raw images dark images and flatfield for perform flat field correction
            corrected_images = cell(1,length(raw));
            for ii = 1:length(raw)
                m = mean2(ff{1,ii} - dark);
                corrected_images{1,ii} = ((raw{1,ii} - dark).*m)./(ff{1,ii} - dark);
            end
        end

        function add_scale_bar_to_image(app,imageName, unitsPerPixel, barSize, units,color,pos)
            % SCALEBAR(imageFile, pixelSize, pixelSize, magnification, units, negative)
            % Adds a scale bar in the lower right-hand corner of an image in the
            % active set of axes. Be sure that your whole scale bar fits within the
            % image width (minus 10%). It should not touch the left edge of your
            % image.
            %
            % Required inputs are:
            % imageName : EITHER an image variable name (e.g. foo)
            % OR a file path and filename (e.g. ~/foo.png)
            % pixelSize (float) : the actual size of a camera pixel (e.g. 7.4)
            % magnification (float) : the microscope magnification (e.g. 40)
            % barScale (float) : bar size in image units (e.g. 100)
            % units (string) : the units of the image (e.g. micron)
            %
            % Adapted from SFNagle 2015 v1

            % read in the image and display
            if ischar(imageName)
                imageName = Tiff(imageName);
                image1 = read(imageName);
            else
                image1 = imageName;
            end
            % plot a scale bar in black first
            scaleBarWidth = floor( 1/(unitsPerPixel) * barSize);
            scaleBarHeight = 5;
            if pos == 1
                xPos = app.camera_pixels(1)*0.92 - scaleBarWidth;
            else
                xPos = app.camera_pixels(1)*0.08;
            end
            yPos = app.camera_pixels(1)*0.92 - scaleBarHeight;
            textCenterX = xPos + floor(scaleBarWidth/2);
            textCenterY = yPos + scaleBarHeight*4;
            rectPosition = [xPos, yPos, scaleBarWidth, scaleBarHeight];
            str = sprintf(['%4d ' units], barSize);
            hrect = annotation('textarrow','String',str,'HorizontalAlignment','center',...
                'FontSize',20,'FontWeight','bold','Color',color, 'LineWidth',5,'HeadStyle','none');
        end

        function zstack = acquire_z_stack(app, current_z, exposure)
            thickness = app.SampleThickness.Value/2;
            offset = app.ZStackoffset.Value;
            step = app.ZStackStepSize.Value;

            % create Z positions for Z_stack
            z_positions = (current_z-thickness-offset):(step):...
                (current_z + thickness + offset);

            num_z_steps = length(z_positions);
            zstack = zeros([app.HeightEditField.Value, app.WidthEditField.Value,num_z_steps]); % space for Z stack images

            for i = 1:length(z_positions)
                if app.acquisition_flag
                    cancel_acquisition(app);
                    return
                end
                app.System_status.Text = sprintf('Acquiring Z stack: (%d/%d)', i, length(z_positions));
                update_progress_bar(app, round(100*(i/length(z_positions))));
                app.mmc.setPosition(z_positions(i));
                zstack(:,:,i) = Image_snapshot(app, app.Exposure_time_2.Value);
            end

            update_progress_bar(app, 0);
            % enable all buttons back after acqusition
            
            app.mmc.setPosition(current_z); % reset the focus back to the center plane
            app.System_status.Text = 'Standby';

        end

        function corrected_qBRM = full_ff_correction(app, img, ff_images, dark_frame)
            corrected_qBRM = ff_correction(app, img, dark_frame, ff_images);
            no_sample_corrected = ff_correction(app, ff_images,dark_frame,ff_images);
            save('normal_files.mat', 'dark_frame','ff_images', 'img')
            %save('Corrected_file.mat', 'corrected_qBRM', 'no_sample_corrected')
            [num_row, num_col] = size(corrected_qBRM{1,1});
            N = num_row*num_col;
            for ii = 1:ceil(180/app.qBRM_step)
                % Correct for the birefringence of the system
                temp(ii).px_traces = double(corrected_qBRM{1,ii}) - double(no_sample_corrected{1,ii});
                temp(ii).px_traces = reshape(temp(ii).px_traces,[1,N]); % reshape to vector form
                sample_px_traces(:,ii) = temp(ii).px_traces; % add vector to storage
            end
            b = mat2cell(sample_px_traces, ones(1,N), [ceil(180/app.qBRM_step)]); % merge vectors into 1 px with N points
            corrected_qBRM = reshape(b, [num_row num_col]); % reshape back to image form

        end

        function [ff_images, dark_frame] = take_ff_dark_return_to_position(app)
            [current_x, current_y] = read_motor_position(app);
            % perform ff and dark field scans
            ff_images = take_flat_field_image(app);
            dark_frame = (take_dark_field_image(app, 10, app.Exposure_time_2.Value));
            move_motor(app,current_x,current_y); % return to initial position
        end

        function exposure = auto_exposure(app)
            disp('Need autoexposure to automate stuff')
        end

        function change_magnification(app, ~)
            z_val = app.load_mmcore;
            msg = 'Please pull out magnifier lens for this Objective';
            objective = app.mag_list(app.mag_index);
            if strcmp(objective, '64X')
                uiwait(msgbox(msg),2);
                app.mmc.setConfig('Objective', '40X'); % set magnification
            elseif strcmp(objective, '96X')
                uiwait(msgbox(msg),2);
                app.mmc.setConfig('Objective', '60X'); % set magnification
            elseif strcmp(objective, '32X')
                uiwait(msgbox(msg),2);
                app.mmc.setConfig('Objective', '20X'); % set magnification
            elseif strcmp(objective, '16X')
                uiwait(msgbox(msg),2);
                app.mmc.setConfig('Objective', '10X'); % set magnification
            else
                app.mmc.setConfig('Objective', objective); % set magnification
            end

            app.mmc.reset();
        end
        
        function Load_camera_default(app)
            try
                %%%% can use manual trigger mode to greatly increase frame
                %%% rate for the capture of single images
                app.image_size = app.vid.ROIPosition/2;
                app.camera_pixels = [3200, 2200];
%                 if strcmp(app.CameraBinningDropDown.Value, '2x2')
%                     app.camera_pixels = [1600 1100];
%                     app.image_size = app.vid.ROIPosition/2;
%                 end
                app.hImage = image(app.Image_axis, zeros(app.camera_pixels(1),app.camera_pixels(2),1), 'HitTest','off');
                app.Image_axis.XLim = [0,app.camera_pixels(1)];
                app.Image_axis.YLim = [0,app.camera_pixels(2)];
                app.Image_axis.XTick = [];
                app.Image_axis.YTick = [];
                pbaspect(app.Image_axis,[app.camera_pixels(1),app.camera_pixels(2),1])
                %daspect(app.Image_axis, [app.camera_pixels(1),app.camera_pixels(2),1])
                app.vid.FrameGrabInterval = 1;
                app.src = getselectedsource(app.vid);
%                 value = app.AutoContrastButton.Value;
                autocontrast = {'ON-MANUAL','ON-AUTO'};
                app.src.AutoContrastMax = 4095;
                app.src.AutoContrastMin = 0;
                app.src.AutoContrast = autocontrast{1};
                app.src.AutoContrast = autocontrast{1};
%                 app.src.Binning = app.CameraBinningDropDown.Value;
                app.src.Exposure = app.Exposure_time_2.Value;
                %Preview the images
                Camera_preview(app)
            catch ME
                uiwait(errordlg('Camera Was not found', 'ERROR'), 5);
                disable_all_buttons(app);
            end
        end

        function mypreview_fcn(app,~,event,himage)
            % Set the value of the text label.
            app.FPSLabel.Text = event.FrameRate;
            
            % Display the current image frame.
            set(himage, 'CData', event.Data);
            % mag = [4 10 16 20 32 40 60 64 96]
            scale_sizes = [200 100 75 50 25 25 20 20 20];
            %add_scale_bar_to_image(app,himage, app.mag/app.pixel_size, scale_sizes(app.mag_index), 'um','yellow',1)
            app.Label_9.Text = num2str(mean(mean(event.Data)));
            app.histo = histogram(event.Data,'Parent', app.Histogram_axis, 'EdgeColor', 'black', 'FaceColor', 'black');
        end
        
        function Camera_preview(app)
            app.ResolutionLabel.Text = sprintf('Resolution: %dx%d',app.camera_pixels(1),app.camera_pixels(2));
            preview(app.vid,app.hImage)
            setappdata(app.hImage,'UpdatePreviewWindowFcn',@app.mypreview_fcn);
            app.widefield_loaded = 0;
            app.camera_loaded = 1;
        end

        function current_z = load_mmcore(app)
            try
                stop(app.myTimer)
                app.mmc.loadSystemConfiguration("C:\Users\BMOadmin\Documents\MATLAB\functions\MMConfig_full_microscope_control.cfg");
                current_z = app.mmc.getPosition();
                app.current_z_position = current_z;
                start(app.myTimer)
            catch
                disp('couldnt load micro manager (likely microscope not turned on)')
                current_z = 0;
            end
        end
        
        function update_FOVsettings(app)
            app.FOV_microns_y = 100*app.camera_pixels(2)./app.pixels_per_100_microns; % First column --> y
            app.FOV_microns_x = 100*app.camera_pixels(1)./app.pixels_per_100_microns; % Second column --> x
            app.FOV_microns_stitching_y = app.FOV_microns_y.*(1 - app.overlap_for_stitching);
            app.FOV_microns_stitching_x = app.FOV_microns_x.*(1 - app.overlap_for_stitching);
            disp(app.FOV_microns_stitching_x)
            disp(app.FOV_microns_stitching_y)
        end
        
        function img = Image_snapshot(app, exposure)
            try
                img = getsnapshot(app.vid);
            catch
                unload_camera(app);
                pause(5)
                Load_camera_default(app);
            end
            
        end
        
        function unload_camera(app)
            stoppreview(app.vid)
            app.camera_loaded = 0;
        end
        
        function get_stagePos_background(app, obj, event)
            [x_coord, y_coord] = read_motor_position(app);
            app.motor_x_coord = x_coord;
            app.motor_y_coord = y_coord;
            app.Stage_pos.Text = sprintf('X: %5.2f Y: %5.2f',x_coord, y_coord );
        end
        
        function [phi, A, C] = qBRM_fitting(app, img)
            [num_row, num_col] = size(img{1,1});
            I1 = img{1,1};
            I2 = img{1,2};
            I3 = img{1,3};
            theta = 2*30*pi/180;
            % solve
            C_pr = (I1+I3)./2;
            asinb = (I1-C_pr);
            acosb = (I2-C_pr-asinb.*cos(theta))./sin(theta);
            A_pr = sqrt(asinb.^2+acosb.^2);
            phi_pr = -(1/2).*atan(asinb./acosb);
            phi_pr = phi_pr + (pi/2).*single(acosb < 0);

            A = reshape(abs(A_pr), [num_row num_col]);
            phi = reshape(mod(phi_pr, pi), [num_row num_col]);
            C =  reshape(C_pr, [num_row num_col]);
        end
        
        function [phi_axer_cmap, phi_axer_cmap_masked, r_axer_display, r_axer_binarized] = plot_qBRM_fast(app, phi_axer, r_axer_abs, display)
            % Perform thresholding before viewing images
            %[x_size, y_size] = size(phi_axer);
            addpath('C:\Users\BMOadmin\Documents\MATLAB\Control GUI\APP_designer\qBRM')
            load('cmap.mat');
            %[cmap, phi_for_cwheel] = create_colormap();

            % Binarize the retardance map for masking the phi map
            r_axer_binarized = imbinarize(mat2gray(r_axer_abs));

            phi_axer_cmap = zeros(size(phi_axer,1),size(phi_axer,2),3);
            phi_axer_cmap_masked = zeros(size(phi_axer,1),size(phi_axer,2),3);

            % Assign colors to phi map for sub-image
            % Determine color of each pixel based on the orientation angle extracted
            for i = 1:((length(phi_for_cwheel)-1)/2) % Only need to compare with first half of phi vector since phi domain is [0, pi]
                mask = (phi_axer > phi_for_cwheel(i) & phi_axer < phi_for_cwheel(i+1));
                for k = 1:3
                    cmap_mat(:,:,k) = cmap(i,k)*ones(size(phi_axer,1),size(phi_axer,2));
                end
                phi_axer_cmap = phi_axer_cmap + cmap_mat.*mask;
            end
            phi_axer_cmap_masked = phi_axer_cmap.*r_axer_binarized;
            % Display generated images

            %phi = figure('NumberTitle', 'off', 'Name', 'phi cmap');


            %phi_m = figure('NumberTitle', 'off', 'Name', 'phi cmap masked');
            %imshow(phi_axer_cmap_masked);

            %abs = figure('NumberTitle', 'off', 'Name', 'r abs');
            r_axer_display = uint16((r_axer_abs - min(min(r_axer_abs)))./ (max(max(r_axer_abs)) - min(min(r_axer_abs))).*(2^16 - 1)); % Scale data to be between 0 and (2^16 - 1)

            if display
                figure()
                title('Retardance Image')
                imshow(r_axer_display);
                figure()
                title('Phi image (Masked)')
                imshow(phi_axer_cmap_masked);
                figure()
                title('Retardance Image (Mask)')
                imshow(r_axer_binarized);
                figure()
                title('Phi image')
                imshow(phi_axer_cmap);
            end
            %abs_m = figure('NumberTitle', 'off', 'Name', 'r abs binarized');
            %imshow(r_axer_binarized);(app)
        end
        
        function write_sample_description(app)
            if ~isempty(app.SampleNotes.Value)
                message = app.SampleNotes.Value;
                fileID = fopen('Sample_Notes.txt', 'w');
                fprintf(fileID, sprintf('Sample description: %s;  \nSample Name: %s; \nSample Thickness: %d; \n',message{1,1}, app.SampleNameEditField.Value, app.SampleThickness.Value));
                fprintf(fileID, sprintf(['Magnification: %dX; \nExposure Time(s): %d (%d); \nExposure Units: %s; \nAcquisition Type: %s; \nMicroscopy Setup: %s; \n' ...
                    'Grid Size: %dx%d; \nOverlap: %5.2f; \nZ-Stack Step Size (microns): %d; \nPolarizer Settings Ill/Det (degrees): %5.2f/%5.2f; \nCamera Width: (%dx%d)'], ...
                    app.MagnificationDropDown.Value, app.Exposure_time_2.Value, app.ExposureTime2.Value, app.Exposure_Units.Value, ...
                    app.AcquisitionType.Value, app.MicroscopeSetup.Value, app.Grid_xentry.Value, app.Grid_yentry.Value, app.overlap_for_stitching, ...
                    app.ZStackStepSize.Value, app.IlluminationEditField.Value, app.DetectionEditField.Value, app.WidthEditField.Value, app.HeightEditField.Value));
                fclose(fileID);
            else 
                disp('No sample Notes found')
            end
        end
        
        function cancel_acquisition(app)
            app.acquisition_flag = false;
            update_progress_bar(app,0);
            uiwait(msgbox('Acqusition Canceled', 'Operation Message', "warn"), 5)
        end
        
        function update_log_file(app, pos_idx)
            message = app.SampleNotes.Value;
            fileID = fopen('Log_File.txt', 'w');
            fprintf(fileID, sprintf('Sample description: %s;  \nSample Name: %s; \nSample Thickness: %d; \n',message{1,1}, app.SampleNameEditField.Value, app.SampleThickness.Value));
            fprintf(fileID, sprintf(['Magnification: %dX; \nExposure Time(s): %d (%d); \nExposure Units: %s; \nAcquisition Type: %s; \nMicroscopy Setup: %s; \n' ...
                'Grid Size: %dx%d; \nOverlap: %5.2f; \nZ-Stack Step Size (microns): %d; \nPolarizer Settings Ill/Det (degrees): %5.2f/%5.2f; \nCamera Width: (%dx%d)'], ...
                app.MagnificationDropDown.Value, app.Exposure_time_2.Value, app.ExposureTime2.Value, app.Exposure_Units.Value, ...
                app.AcquisitionType.Value, app.MicroscopeSetup.Value, app.Grid_xentry.Value, app.Grid_yentry.Value, app.overlap_for_stitching, ...
                app.ZStackStepSize.Value, app.IlluminationEditField.Value, app.DetectionEditField.Value, app.WidthEditField.Value, app.HeightEditField.Value));
            fclose(fileID);
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd('C:\Users\BMOadmin\Documents')
            app.documents_folder = pwd;
            addpath('C:\Users\BMOadmin\Documents')
            addpath("C:\Program Files\Micro-Manager-2.0gamma")

            %%%% Load Micro Manager Core %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            try
                import mmcorej.*;
                app.mmc = CMMCore;
            catch
                disp('Couldnt import well see if we can load')
                app.mmc = CMMCore;
                disp('Loaded')
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            drawnow;
            app.UIFigure.WindowState = 'maximized';
            app.System_status.Text = 'Iniatializing...';

            matlab_folder = [app.documents_folder,'\MATLAB'];
            addpath(matlab_folder);

            %% add path for the BaSIC correction scripts
            addpath("C:\Users\BMOadmin\Documents\MATLAB\Control GUI\APP_designer\");
            addpath("C:\Users\BMOadmin\Documents\MATLAB\Control GUI\APP_designer\dcttool\");
            addpath('C:\Program Files\Thorlabs\Kinesis\')
            % Include path to functions
            addpath([matlab_folder,'\functions']);
            % Load stages and settings
            for nnn = 1:1
                calibration_path{1,1} = [matlab_folder,'\Calibration_files\crossed_polarizer_settings'];
                addpath(calibration_path{1,1});
                ill_degree_settings = load('ill_5_degree_settings.mat'); %illumination
                det_degree_settings = load('det_5_degree_settings.mat'); %detection
                calibration_path{2,1} = [matlab_folder,'\Calibration_files\circular_analyzer_settings'];
                addpath(calibration_path{2,1});
                ill_RCA_LCA_settings = load('ill_RCA_LCA_settings.mat');
                det_RCA_LCA_settings = load('det_RCA_LCA_settings.mat');
                addpath('C:\Program Files\Micro-Manager-2.0gamma'); % Add path to MMCoreJ_wrap.dll
                % Also must copy path to MMCoreJ.jar to classpath.txt located in %matlabroot\toolbox\local

                field_names = fieldnames(det_degree_settings);
                app.det_degree_settings = det_degree_settings.(field_names{1,1});
                field_names = fieldnames(ill_degree_settings);
                app.ill_degree_settings = ill_degree_settings.(field_names{1,1});
                field_names = fieldnames(det_RCA_LCA_settings);
                app.det_RCA_LCA_settings = det_RCA_LCA_settings.(field_names{1,1});
                field_names = fieldnames(ill_RCA_LCA_settings);
                app.ill_RCA_LCA_settings = ill_RCA_LCA_settings.(field_names{1,1});
                clearvars field_names

            end

            %app.ill_pol_display.Text = sprintf('%.4f degrees', app.ill_RCA_LCA_settings(1,1));
            %app.det_pol_display.Text = sprintf('%.4f degrees', app.det_RCA_LCA_settings(1,1));

            max_slider_val = 36;
            min_slider_val = 1;
            slider_steps1 = 1/(max_slider_val - min_slider_val);
            slider_steps2 = 3/(max_slider_val - min_slider_val);

            %%% get the FOV for the current camera and the 
            update_FOVsettings(app);

            % set grid size *(Default = [2,2])
            app.grid_size = [app.Grid_yentry.Value, app.Grid_xentry.Value];
            app.Grid_select_points = 1;
            %%%%%%%%%%% after Old GUI %%%%%%%%%%%%%%%%%%%%%

            cd([app.documents_folder,'\Images']);
            t = clock;
            % can replace with an experimental file
            new_folder_name = sprintf('%d_%02d_%02d_imaging_%02d_%02d_%02d',t(1),t(2),t(3),t(4),t(5),round(t(6))); % t = [year, month, day, hour, minute, second]
            app.FileName.Value = new_folder_name; % update default file name
            app.SaveDirectory.Value = new_folder_name;
            
            % load grid into window axis
            %plot_grid(app, x_min, x_max, y_min, y_max)

            %%%% set all default values
            app.MagnificationDropDown.Value = app.mags(app.mag_index);
            grid_1 = strcat(string(app.grid_size_x),'x');
            grid_string = strcat(grid_1,string(app.grid_size_y));
            app.Grid_size_status.Text = grid_string;
            app.overlap_for_stitching = app.TileOverlapEditField.Value/100;
            %% create grid onto axis
            app.System_status.Text = 'Loading Objective';
            
            update_grid_2(app)
            
            %load_camera_with_mmc(app);

            update_progress_bar(app, 10);
            % set up proper objective
            
            %app.mmc.setConfig('Objective', app.MagnificationDropDown.Value); % set magnification
            %app.mmc.setPosition(2500); % default to this position close to focal plane of most tissues
            pause(1)
            % load in the current focus position
            formatSpec = '%.1f';
            %app.z_position = app.mmc.getPosition;

            app.System_status.Text = 'Initializing Stage...';

            % initialize Motor Last
            SN_ill = '55001048';
            SN_det = '55001045';
            SN_xy_controller = '73109054';

            cd("C:\Users\BMOadmin\Documents\MATLAB\")
            addpath("C:\Users\BMOadmin\Documents\MATLAB\")
            addpath("C:\Users\BMOadmin\Documents\MATLAB\Control GUI")
            %create motor objects and home devices
            [app.motor_x, app.motor_y] = motor_init(SN_xy_controller);
            update_progress_bar(app, 25);
            app.System_status.Text = 'Initializing Illumination Stage';
            app.motor_ill = rotat_stage_init(SN_ill);
            update_progress_bar(app, 50);
            app.System_status.Text = 'Initializing Detection Stage';
            app.motor_det = rotat_stage_init(SN_det);
            update_progress_bar(app, 75);
            % Start in LCP-RCA configuration
            app.motor_ill.MoveTo(app.ill_RCA_LCA_settings(1,1), 60000);
            app.motor_det.MoveTo(app.det_RCA_LCA_settings(1,1), 60000);
            update_progress_bar(app, 85);
            app.System_status.Text = 'Loading Camera';
            % load camera and motor last
            imaqreset
            app.cam_info = imaqhwinfo;
            app.vid = videoinput('pmimaq_2019b',1,'PM-Cam 3200x2200');
            app.vid.FramesPerTrigger = 1;
            Load_camera_default(app);

            app.System_status.Text = 'Standby';
            update_progress_bar(app, 0);
            
            app.myTimer = timer('Period',0.075,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.myTimer.TimerFcn = @app.get_stagePos_background;
            start(app.myTimer)

            %change_magnification(app,app.MagnificationDropDown.Value)

            disp('Initialization finished');
        end

        % Button pushed function: AcquireImagesButton
        function AcquireImagesButtonPushed(app, event)
            type = app.AcquisitionType.Value;
            microscopy_type = app.MicroscopeSetup.Value;
            app.AddROIButton.Enable = 'off';
            app.ROI_Magnification.Enable = 'off';
            reset_ROI_settings(app);
            update_progress_bar(app, 0);
            app.System_status.Text = 'Standby';
            app.CancelAcquisitionButton.Enable = 'on';
            switch microscopy_type
                case 'CCP-BRM'
                    if (~strcmp(type, 'ROI Imaging')) || (~strcmp(type, 'Grid Sampling')) || (~strcmp(type, '40X Randomized'))
                        create_save_directory(app); % only update directory if its not ROI imaging
                        write_sample_description(app); % write a sample description if the user provides one 
                    end
                    switch type
                        case 'Single Image'
                            update_progress_bar(app, 10);
                            app.System_status.Text = 'Acquiring Image';
                            
                            % actually save the image as both a tif and a PNG
                            filename = strcat(app.FileName.Value, '_exp', string(app.Exposure_time.Value), 'ms_mag', num2str(app.MagnificationDropDown.Value), '.tiff');
                            update_progress_bar(app, 50);
                            img = Image_snapshot(app, app.Exposure_time_2.Value);
                            imwrite(img, filename);
                            update_progress_bar(app, 75);
                            Camera_preview(app)
                            app.System_status.Text = 'Standby';
                            update_progress_bar(app, 0);
                            %potentially write as a png instead of tiff
                            %write_image_format(app, img, filename, 'png');

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%                        Z-Stack Imaging                               %%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        case 'Single Z-Stack'
                            disp('Case 2')
                            app.System_status.Text = 'Creating Save Directory';
                           
                            cd(app.save_folder)
                            % get current position
                            current_z = load_mmcore(app);
                            %%%% autofocus if it is checked
                            if app.AutoFocus.Value
                                app.System_status.Text = 'Auto-Focusing';
                                [img, current_z] = autofocusing(app, app.correction_factor, app.Exposure_time.Value, '10X', 'nothing');
                            end
                            
                            %potentially write as a png instead of tiff
                            zstack = acquire_z_stack(app, current_z, app.ExposureTime1.Value);
                            app.mmc.setPosition(current_z); % reset the focus back to the center plane
                            app.mmc.reset(); % reset micromanager core 
                            Camera_preview(app)
                            %imshow3D(zstack);

                            %BaSIC_Correction(app, 0, 0, zstack(:,:,:));

                            % write as seperate images "Image Sequence"
                            cd(app.save_folder);
                            [coordx, coordy] = read_motor_position(app);
                            coordinates = [coordx, coordy, current_z];
                            save('coordinates.mat','coordinates')
                            filename = strcat(app.FileName.Value, '_exp', string(app.Exposure_time.Value), 'ms_mag', num2str(app.MagnificationDropDown.Value), '.tif');
                            for i = 1:size(zstack,3)
                                imwrite(uint16(zstack(:,:,i)),filename,'tif','WriteMode','append');
                            end

                            update_progress_bar(app, 0);
                            app.System_status.Text = 'Standby';

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%                        Sitching Imaging                              %%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        case 'Stitching'
                            disp('Case 3')
                            
                            if size(app.grid_idx_selected, 1) == 4
                                
                                app.System_status.Text = 'Creating Directories';
                                % create exposure time files
                                cd(app.save_folder);
                                exp_folder_1 = sprintf('exp%dms',app.ExposureTime1.Value);

                                mkdir(exp_folder_1);
                                if app.ExposureTime2.Value
                                    exp_folder_2 = sprintf('exp%dms',app.ExposureTime2.Value);
                                    mkdir(exp_folder_2);
                                end

                                z_size = 1; % the number of planes to take

                                % if it is multiplane then create the z values
                                if strcmp(app.StitchingType.Value,'Multi-Plane')
                                    [num_images_row,num_images_col] = size(app.x_img_mesh);
                                    z_mesh = zeros(num_images_row,num_images_col,(app.SampleThickness.Value + app.ZStackoffset.Value));
                                    z_mesh(:,:,1) = calc_z_plane(app) - ((app.SampleThickness.Value/2)+app.ZStackoffset.Value)*ones(num_images_row,num_images_col); % get bottom plane

                                    for z = 1:(app.SampleThickness.Value)+app.ZStackoffset.Value
                                        z_mesh(:,:, z+1) =  z_mesh(:, :, z) + ones(num_images_row,num_images_col);
                                    end
                                end
                                z_pos = load_mmcore(app);
                                y = 0;
                                index = 0;
                                app.System_status.Text = 'Stitching Acqusition';
                                for y_idx = 1:size(app.x_img_mesh,1)
                                    y = y + 1;
                                    if(mod(y,2) == 1)
                                        pause(25); %% every new y value we shgould take a break for 15-30 seconds
                                    end

                                    for x_idx = 1:size(app.x_img_mesh,2)
                                        if(mod(y,2) == 1)
                                            % For odd number rows, moving left to right
                                            x = x_idx;
                                        else
                                            % For even number rows, moving right to left
                                            x = size(app.x_img_mesh,2) - x_idx + 1;
                                        end
                                        %move to the preset point
                                        %disp(sprintf('X: %d Y: %d', app.x_img_mesh(y,x), app.y_img_mesh(y,x)))
                                        move_motor(app,app.x_img_mesh(y,x), app.y_img_mesh(y,x));
                                        pause(0.1);

                                        % Move objective to z_focus value for the current tile

                                        
                                        if strcmp(app.StitchingType.Value,'Multi-Plane')
                                            img = cell(2,1);
                                            img{1,1} = zeros(app.FOV_pixels(1,1),app.FOV_pixels(2,1),(app.SampleThickness.Value + app.ZStackoffset.Value));
                                            img{2,1} = zeros(app.FOV_pixels(1,1),app.FOV_pixels(2,1),(app.SampleThickness.Value + app.ZStackoffset.Value));
                                            for z = 1:size(z_mesh,3)
                                                 if app.acquisition_flag
                                                     cancel_acquisition(app);
                                                     return
                                                 end
                                                app.mmc.setPosition(z_mesh(y,x,z));
                                                pause(0.2);
                                                
                                                img{1,1}(:,:,z) = Image_snapshot(app, app.ExposureTime1.Value);
                                                if app.ExposureTime2.Value
                                                    img{2,1}(:,:,z) = Image_snapshot(app, app.ExposureTime2.Value);
                                                end
                                            end
                                        else

                                            app.mmc.setPosition(app.z_img_mesh(y,x));
                                            pause(0.15);
                                            img_exp_1 = Image_snapshot(app, app.ExposureTime1.Value);
                                            if app.ExposureTime2.Value
                                                app.src.Exposure = app.ExposureTime2.Value;
                                                img_exp_2 = Image_snapshot(app, app.ExposureTime2.Value);
                                                app.src.Exposure = app.ExposureTime1.Value;
                                            end
                                        end
                                        index = index + 1;        % round number of the current index/(total # of images)
                                        update_progress_bar(app, round(100*(index/(size(app.x_img_mesh,1)*size(app.x_img_mesh,2)))));


                                        % Save multi-exposure images for current xy location
                                        %cd([app.rectangular_stitch_folder,'\',sprintf('exp%dms',exp_times(i,1))]);
                                        new_filename = sprintf('img_%03d.tif',index);

                                        % save as either multiplane or single
                                        % plane
                                        if strcmp(app.StitchingType.Value,'Multi-Plane')
                                            cd([app.save_folder, '\', exp_folder_1]);
                                            for z = 1:(app.SampleThickness.Value + app.ZStackoffset.Value)
                                                imwrite(uint16(img{1,1}(:,:,z)),[new_filename],'tif','WriteMode','append');
                                            end

                                            if app.ExposureTime2.Value
                                                cd([app.save_folder, '\', exp_folder_2]);
                                                for z = 1:(app.SampleThickness.Value + app.ZStackoffset.Value)
                                                    imwrite(uint16(img{2,1}(:,:,z)),[new_filename],'tif','WriteMode','append');
                                                    %update_log_file(app, 'new','new')
                                                end
                                            end
                                        else
                                            % Save images with indexing for ImageJ Stitching plug-in
                                            cd([app.save_folder, '\', exp_folder_1]);
                                            imwrite(uint16(img_exp_1),new_filename);
                                            if app.ExposureTime2.Value
                                                cd([app.save_folder, '\', exp_folder_2]);
                                                imwrite(uint16(img_exp_2),new_filename);
                                            end
                                        end
                                    end
                                end

                                dir = [app.save_folder, exp_folder_1];
                                update_progress_bar(app, 0);
                                x_img_mesh = app.x_img_mesh;
                                y_img_mesh = app.y_img_mesh;
                                x_grid_mesh = app.x_grid_mesh;
                                y_grid_mesh = app.y_grid_mesh;
                                mag = app.mag;

                                save('stitching_parameters.mat','x_img_mesh','y_img_mesh','x_grid_mesh','y_grid_mesh','mag');
%                                 Data = [1 2 3;4 5 6; 7 8 9];
%                                 names = {'line1','line2','line3'};
%                                 fid = fopen('Sitching_parameters.txt','w');
%                                 fprintf(fid,);
%                                 fclose(fid);
                                %% perform background correction
                                app.System_status.Text = 'Performing Background Correction';
                                file_location1 = [app.save_folder, exp_folder_1, '\'];

                                echo off;
                                % only do background if we have single
                                % plane
                                if strcmp(app.StitchingType.Value,'Single Plane')
                                    BaSIC_Correction(app, file_location1, 0);
                                    if app.ExposureTime2.Value
                                        file_location2 = [app.save_folder, exp_folder_2, '\'];
                                        BaSIC_Correction(app, file_location2, 0);
                                    end
                                end
                                echo on;
                                % actually do the stitching in FIJI
                                %  if the grid
                                % size is small enough

                                %Fiji_stitching(app, dir, size(app.x_img_mesh,2), size(app.x_img_mesh,1))
                                Camera_preview(app)
                                app.System_status.Text = 'Standby';
                                update_progress_bar(app, 0);
                                app.mmc.reset()
                                message = sprintf('Stitching for images into file %s has completed', app.save_folder);
                                try
                                    send_text_message('9788863884','Verizon',message)
                                catch ME
                                    disp('could not send text message')
                                end
                            else
                                message = 'Grid Has not been initialized';
                                uiwait(warndlg(message), 10);
                            end

                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            %%%%%                        Widefield Acqusition                          %%%%%
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        case 'Widefield (4X)'
                            disp('Case 4')
                            if size(app.grid_idx_selected, 1) == 4
                                
                                app.System_status.Text = 'Creating Directories';
                                % create exposure time files
                                cd(app.save_folder);
                                [~] = app.load_mmcore;
                                exp_folder_1 = sprintf('exp%dms',app.ExposureTime1.Value);
                                mkdir(exp_folder_1);
                                if app.ExposureTime2.Value
                                    exp_folder_2 = sprintf('exp%dms',app.ExposureTime2.Value);
                                    mkdir(exp_folder_2);
                                end

                                z_size = 1; % the number of planes to take

                                y = 0;
                                index = 0;
                                app.System_status.Text = 'Widefield Acqusition';
                                for y_idx = 1:size(app.x_img_mesh,1)
                                    y = y + 1;
                                    for x_idx = 1:size(app.x_img_mesh,2)
                                        
                                        %%%%% Cancel Acquisiton Check %%%%
                                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                        if app.acquisition_flag
                                            cancel_acquisition(app);
                                            return
                                        end

                                        if(mod(y,2) == 1)
                                            % For odd number rows, moving left to right
                                            x = x_idx;
                                        else
                                            % For even number rows, moving right to left
                                            x = size(app.x_img_mesh,2) - x_idx + 1;
                                        end
                                        %move to the preset point
                                        move_motor(app,app.x_img_mesh(y,x), app.y_img_mesh(y,x));
                                        pause(0.1);

                                        % Move objective to z_focus value for the current tile
                                        app.mmc.setPosition(app.z_img_mesh(y,x));
                                        
                                        pause(0.35);

                                        % since widefield has larger structures
                                        % downsample to 8 bit for the images to
                                        % allow for larger stitching images
                                        img_exp_1 = Image_snapshot(app, app.ExposureTime1.Value); % make sure that we save as an uint8 image to save space
                                        if app.ExposureTime2.Value
                                            img_exp_2 = Image_snapshot(app, app.ExposureTime2.Value);
                                        end

                                        index = index + 1;
                                        
                                        new_filename = sprintf('img_%03d.tif',index);

                                        % save as either multiplane or single
                                        % plane

                                        % Save images with indexing for ImageJ Stitching plug-in
                                        cd([app.save_folder, '\', exp_folder_1]);
                                        imwrite(uint8(img_exp_1),new_filename);
                                        if app.ExposureTime2.Value
                                            cd([app.save_folder, '\', exp_folder_2]);
                                            imwrite(uint8(img_exp_2),new_filename);
                                        end
                                        update_progress_bar(app, round(100*(index/(size(app.x_img_mesh,1)*size(app.x_img_mesh,2)))));
                                    end
                                end
                                disp(app.save_folder)
                                dir = [app.save_folder, '\', exp_folder_1];

                                % actually do the stitching in FIJI
                                app.System_status.Text = 'Performing Background Correction';
                                file_location1 = [app.save_folder, exp_folder_1, '\'];

                                echo off;
                                BaSIC_Correction(app, file_location1, 0);
                                if app.ExposureTime2.Value
                                    file_location2 = [app.save_folder, exp_folder_2, '\'];
                                    BaSIC_Correction(app, file_location2, 0);
                                end
                                
                                %Fiji_stitching(app, dir, size(app.x_img_mesh,2), size(app.x_img_mesh,1),1)
                                echo on;
                                app.System_status.Text = 'Standby';
                                app.AddROIButton.Enable = 'on';
                                app.ROI_Magnification.Enable = 'on';

                                app.RandomizedROIs.Enable = 'on';
                                app.StitchingType.Enable = 'on'; % enable multiplane for the ROI imaging
                            else
                                message = 'Grid Has not been initialized';
                                uiwait(warndlg(message),10);
                            end

                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            %%%%%                        ROI Imaging                                   %%%%%
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        case 'ROI Imaging'
                            disp('ROI Imaging')
                            empty_idx = {};
                            idx_counter = 1;
                            try
                                for i = 1:size(app.ROI_struct,2)-1
                                    if isempty(app.ROI_struct(i).z_mesh)
                                        empty_idx{idx_counter} = num2str(idx_counter);
                                        idx_counter = idx_counter +1;
                                    end
                                end
                                if ~isempty(empty_idx)
                                    ind = strjoin(empty_idx, ',');
                                    message = sprintf('Z-Mesh for ROI %s is missing', ind);
                                    uiwait(warndlg(message),5);
                                end

                                %                             %ask user if there are
                                %
                                %                             qst_handler = sprintf('Z-Mesh for ROI %s is missing. Continue with remaining ROIs anyway?', ind);
                                %
                                %                             answer = questdlg(qst_handler, ...
                                % 	                        'ROI selection', ...
                                % 	                        'Yes','No','No');

                                %save the ROIs as
                                cd(app.ROI_struct(1).folder)
                                mkdir('ROIs')
                                cd('ROIs')
                                dir = pwd;
                                image_read = Tiff([app.stitched_img_location '\Stitched_image']);
                                image_read = read(image_read);
                                try
                                    imwrite(getframe(app.Image_Axis).cdata, 'Annotated.png')
                                catch ME
                                    for i = 1:size(app.ROI_struct,2)
                                        label_str{i} = app.ROI_struct(i).label;
                                        position(i, :) = app.ROI_struct(i).position;
                                    end
                                    RGB = insertObjectAnnotation(image_read,'rectangle',position,label_str,...
                                        'TextBoxOpacity',0.9, 'LineWidth', 8, 'Color', 'Red', 'FontSize',48);
                                    
                                    imwrite(RGB, [dir '\Annotated.tif'], 'tiff')
                                end


                                for i = 1:size(app.ROI_struct,2)

                                    change_magnification(app, app.ROI_struct(i).magnification)
                                    %app.mmc.setConfig('Objective', app.ROI_struct(i).magnification); % set magnification
                                    if isempty(app.ROI_struct(i).z_mesh)
                                        fprintf('Z-Mesh for ROI %s is missing and will be skipped for ROI acquisition', i)
                                    else

                                        direct = sprintf('ROI_%d', i);
                                        mkdir(direct)
                                        cd(direct)
                                        z_size = 1; % the number of planes to take


                                        if strcmp(app.StitchingType.Value,'Multi-Plane')
                                            [num_images_row,num_images_col] = size(app.ROI_struct(i).gridx);
                                            z_mesh = zeros(num_images_row,num_images_col,(app.SampleThickness.Value + app.ZStackoffset.Value));
                                            z_mesh(:,:,1) = app.ROI_struct(i).z_mesh - ((app.SampleThickness.Value/2)+app.ZStackoffset.Value)*ones(num_images_row,num_images_col); % get bottom plane

                                            for z = 1:(app.SampleThickness.Value)+app.ZStackoffset.Value
                                                %%%%% Cancel Acquisiton Check %%%%
                                                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                                if app.acquisition_flag
                                                    cancel_acquisition(app);
                                                    return
                                                end
                                                z_mesh(:,:, z+1) =  z_mesh(:, :, z) + ones(num_images_row,num_images_col);
                                            end
                                        end

                                        y = 0;
                                        index = 0;
                                        lbl = sprintf('ROI Acquisition #%d', i);
                                        app.System_status.Text = lbl;
                                        for y_idx = 1:size(app.ROI_struct(i).gridx,1)
                                            y = y + 1;
                                            for x_idx = 1:size(app.ROI_struct(i).gridx,2)
                                                if(mod(y,2) == 1)
                                                    % For odd number rows, moving left to right
                                                    x = x_idx;
                                                else
                                                    % For even number rows, moving right to left
                                                    x = size(app.ROI_struct(i).gridx,2) - x_idx + 1;
                                                end
                                                %move to the preset point
                                                move_motor(app,app.ROI_struct(i).gridx(y,x), app.ROI_struct(i).gridy(y,x));
                                                pause(0.1);

                                                % Move objective to z_focus value for the current tile
                                                app.mmc.setPosition(app.ROI_struct(i).z_mesh(y,x));
                                                pause(0.35);

                                                if strcmp(app.StitchingType.Value,'Multi-Plane')
                                                    img = cell(1,1);
                                                    img{1,1} = zeros(app.FOV_pixels(1,1),app.FOV_pixels(2,1),(app.SampleThickness.Value + app.ZStackoffset.Value));
                                                    for z = 1:size(z_mesh,3)
                                                        app.mmc.setPosition(z_mesh(y,x,z));
                                                        pause(0.2);
                                                        img{1,1}(:,:,z) = snap_image(app.ROI_struct(i).Exposure_time,app.mmc);
                                                        update_progress_bar(app, round((100*((size(z_mesh,3)*(index))+z))/(size(z_mesh,3)*size(app.ROI_struct(i).full_xgrid,2)*size(app.ROI_struct(i).full_xgrid,1))));
                                                    end
                                                else
                                                    img_exp_1 = snap_image(app.ROI_struct(i).Exposure_time, app.mmc);
                                                end

                                                index = index + 1;
                                                new_filename = sprintf('img_%03d.tif',index);
                                                % save as either multiplane or single
                                                % plane
                                                if strcmp(app.StitchingType.Value,'Multi-Plane')
                                                    for z = 1:(app.SampleThickness.Value + app.ZStackoffset.Value)
                                                        imwrite(uint8(img{1,1}(:,:,z)),[new_filename],'tif','WriteMode','append');
                                                    end
                                                else
                                                    imwrite(uint8(img_exp_1),new_filename);
                                                end
                                                ROI_coordinatesx = app.ROI_struct(i).gridx;
                                                ROI_coordinatesy = app.ROI_struct(i).gridy;
                                                pixel_positions = app.ROI_struct(i).position;
                                                magnification = app.ROI_struct(i).magnification;
                                                exp_time =  app.ROI_struct(i).Exposure_time;
                                                if ~strcmp(app.StitchingType.Value,'Multi-Plane')
                                                    z_mesh = app.ROI_struct(i).z_mesh(y,x);
                                                end
                                                save(sprintf('ROI%02d_coordinates.mat', i), 'ROI_coordinatesx', 'ROI_coordinatesy', 'pixel_positions','magnification', 'exp_time','z_mesh');
                                                update_progress_bar(app, round(100*(index/(size(app.ROI_struct(i).full_xgrid,2)*size(app.ROI_struct(i).full_xgrid,1)))));
                                            end
                                        end

                                        local_ROI = pwd;

                                        update_progress_bar(app,0);
                                        % actually do the stitching in FIJI
                                        app.System_status.Text = 'Performing Background Correction';
                                        location = [app.stitched_img_location direct];
                                        if strcmp(app.StitchingType.Value,'Single Plane')
                                            BaSIC_Correction(app, [local_ROI '\'], 0);
                                        end
                                        %Fiji_stitching(app, local_ROI, size(app.ROI_struct(i).gridx,2), size(app.ROI_struct(i).gridx,1))

                                        update_progress_bar(app,0);
                                        cd(app.ROI_struct(1).folder)
                                        cd('ROIs')
                                    end
                                end
                                message = sprintf('ROI acquisition into file: %s has completed', app.save_folder);
                                send_text_message('9788863884','Verizon',message)
                                send_text_message('6178386938','att',message)
% 
% 
                            catch ME
                                message = 'ROI Locations have not been initialized';
                                uiwait(warndlg(message));
                            end

                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            %%%%%                        40X randomized                                %%%%%
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        case '40X randomized'
                            disp('40 randomizer')
                            mkdir('Randomized')
                            cd('Randomized')
                            imwrite(getframe(app.Image_Axis).cdata, 'Annotated.png')
                            for ii = 1:size(app.Randomized_locations,2)
                                move_motor(app, app.Randomized_locations(1,ii), app.Randomized_locations(2,ii))
                                %z_stack_var = acquire_z_stack(app, app.random_focuses(1,i), app.random_focuses(2,i));
                                %%%%%%%%%%%%%%% make Z stack function work
                                %%%%%%%%%%%%%%% %%%%%%%%%%
                                app.System_status.Text = sprintf('Acuqiring Z-stack %d/%d', ii, size(app.Randomized_locations,2));
                                current_z = app.random_focuses(1,ii);
                                exposure = app.random_focuses(2,ii);
                                zstack = acquire_z_stack(app, current_z, exposure);

                                update_progress_bar(app, 0);
                                % enable all buttons back after acqusition
                                
                                app.mmc.setPosition(current_z); % reset the focus back to the center plane
                                app.System_status.Text = 'Standby';

                                % write z-stack to file location
                                filename = sprintf('Random_location_%d.tif', ii);
                                for i = 1:size(zstack,3)
                                    imwrite(uint8(zstack(:,:,i)) ,filename,'tif','WriteMode','append');
                                end
                            end
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            %%%%%                        Grid Sampling                                 %%%%%
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        case 'Grid Sampling'
                            if ~isempty(app.grid_locations)
                                app.widefield_folder
                                cd(app.widefield_folder)
                                mkdir('Grid Sampling')
                                cd('Grid Sampling')
                                %imwrite(getframe(app.Image_Axis).cdata, 'Annotated.png') % save the annotated grid sampled image
                                %imwrite(getframe(app.Image_Axis).cdata, 'Annotated.tiff') % save the annotated grid sampled image
                                for ii = 1:size(app.grid_locations,2)
                                    move_motor(app, app.grid_locations(1,ii), app.grid_locations(2,ii))
                                    %z_stack_var = acquire_z_stack(app, app.random_focuses(1,i), app.random_focuses(2,i));
                                    %%%%%%%%%%%%%%% make Z stack function work
                                    %%%%%%%%%%%%%%% %%%%%%%%%%
                                    app.System_status.Text = sprintf('Acuqiring Z-stack %d/%d (%d,%d)', ii, size(app.grid_locations,2), app.grid_idx(1,ii),app.grid_idx(2,ii));
                                    current_z = app.random_focuses(1,ii);
                                    exposure = app.random_focuses(2,ii);

                                    zstack = acquire_z_stack(app, current_z, exposure);

                                    update_progress_bar(app, 0);
                                    % enable all buttons back after acqusition
                                    
                                    app.mmc.setPosition(current_z); % reset the focus back to the center plane
                                    app.System_status.Text = 'Standby';

                                    % write z-stack to file location
                                    filename = sprintf('grid_location_(%d,%d).tif', app.grid_idx(1,ii), app.grid_idx(2,ii));
                                    for i = 1:size(zstack,3)
                                        imwrite(uint8(zstack(:,:,i)) ,filename,'tif','WriteMode','append');
                                    end
                                end
                            end
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%%%%                        qBRM                                          %%%%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 'qBRM'
                    %% create save directory
                    create_save_directory(app)
                    write_sample_description(app);
                    switch type
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %%%%%                        Single Image                                  %%%%%
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        case 'Single Image'
                            update_progress_bar(app, 10);
                            app.System_status.Text = 'Acquiring qBRM Image';
                            current_z = load_mmcore(app);
                            img = cell(1,3);
                            corrected_images = cell(1,3); % images after correction
                            current_folder = pwd;
                            mkdir('single')
                            cd('single')

                            qBRM_point_degree = [0, 30, 90];
                            for step = 1:3

                                %%%%% Cancel Acquisiton Check %%%%
                                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                if app.acquisition_flag
                                    cancel_acquisition(app);
                                    return
                                end

                                app.motor_ill.MoveTo(qBRM_point_degree(step), 60000); % move the linear polarizer after each image
                                if( step == 1)
                                    pause(3); % Extra pause to allow the motorized stage to get to the first setting
                                end
                                pause(1)
                                update_progress_bar(app, 100*(step/6));
                                img{1,step} = double(Image_snapshot(app,app.Exposure_time.Value));
                            end
                            [phi, Amplitude, Offset] = qBRM_fitting(app, img); % offset can be used to normalize images 
                            save('qBRM_maps.mat','phi','Amplitude','Offset')
                            figure()
                            title('Image offset (transmittance?)')
                            imshow(uint16(Offset))
                            plot_qBRM_fast(app, phi, Amplitude, 1);
                            
                            save('FF_Correction.mat', 'img')
                            update_progress_bar(app, 0);
                            save('image_set.mat','img','-v7.3');
                            % home the polarizers
                            app.System_status.Text = 'Reseting Illumination Polarizer';

                            num_images = 1;
                            [~, ~] = read_rotat_stage_position(app);
                            app.System_status.Text = 'Acquiring FF and Dark frame';
                            %drawnow;
                            %[ff_images, dark_frame] = take_ff_dark_return_to_position(app);
                            %corrected_qBRM = full_ff_correction(app, img, ff_images, dark_frame);

                            %save('Corrected_img.mat','corrected_qBRM');
                            

                            % upload ff corrected .mat file into correct SCC directory
                            %SCC_upload(app, current_folder)
                            app.mmc.reset();
                            app.System_status.Text = 'Standby';
                            update_progress_bar(app, 0);

                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            %%%%%                       qBRM Z-Stack                                   %%%%%
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        case 'Single Z-Stack'
                            main_folder = pwd;
                            
                            % get current position
                            current_z = load_mmcore(app);
                            app.src.Exposure = app.Exposure_time_2.Value;
                            % autofocus if it is checked
                            mkdir('zstack')
                            cd('zstack')
                            if app.AutoFocus.Value
                                app.System_status.Text = 'Auto-Focusing';
                                [~, ~] = autofocusing(app, app.correction_factor, app.Exposure_time.Value, '10X', 'nothing');
                                [~, current_z] = autofocusing(app, app.correction_factor, app.Exposure_time.Value, app.MagnificationDropDown.Value, 'nothing');
                            end

                            app.System_status.Text = 'Acquring Z-Stack';

                            thickness = app.SampleThickness.Value/2;
                            offset = app.ZStackoffset.Value;
                            step = app.ZStackStepSize.Value;

                            % create Z positions for Z_stack
                            z_positions = (current_z-thickness-offset):(step):...
                                (current_z + thickness + offset);
                            num_z_steps = length(z_positions);
                            for index = 1:num_z_steps
                                mkdir(sprintf('qBRM_ZStack_img_%03d', index));
                            end

                            [ff_images, dark_frame] = take_ff_dark_return_to_position(app); % get ff and dark frames and return to original position
                            save('ff_dark.mat','ff_images','dark_frame');

                            zstack = zeros([app.FOV_pixels',num_z_steps+1]); % space for Z stack images
                            for step = 1:6 % step 30 degrees from current to 180
                                app.System_status.Text = sprintf('Acquiring Angle #%d', step);
                                %drawnow
                                app.motor_ill.MoveTo(app.ill_degree_settings(1 + (step - 1)*(app.qBRM_step/5)), 60000); % move the linear polarizer after each image
                                if( step == 1)
                                    pause(3); % Extra pause to allow the motorized stage to get to the first setting
                                end
                                pause(1)
                                for i = 1:length(z_positions)
                                    %%%%% Cancel Acquisiton Check %%%%
                                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                    if app.acquisition_flag
                                        cancel_acquisition(app);
                                        return
                                    end
                                    new_filename = sprintf('img_%d', step);
                                    update_progress_bar(app, round(100*(i/length(z_positions))));
                                    
                                    app.mmc.setPosition(z_positions(i));
                                    if i ==1
                                        pause(0.5)
                                    end
                                    img = int16(Image_snapshot(app, app.Exposure_time_2.Value));
                                    % only save the first polarizer
                                    % position as the ZStack
                                    if (step ==1)
                                        zstack(:,:,i) = img;
                                    end
                                    cd([app.save_folder '/zstack/' sprintf('qBRM_Zstack_img_%03d',i)]); % go to folder
                                    save([new_filename,'.mat'],'img','-v7.3');
                                end
                            end
                            img_new = cell(1,ceil(180/app.qBRM_step));
                            %after acquisition do all of the corrections
%                             for ii = 1:length(z_positions)
%                                 for step = 1:ceil(180/app.qBRM_step)
%                                     cd([app.save_folder '/' sprintf('qBRM_Zstack_img_%03d',ii)]); % go to folder
%                                     img_new{1,step} = int16(load(sprintf('img_%d', step)).img);
%                                 end
%                                 corrected_qBRM = full_ff_correction(app, img_new, ff_images, dark_frame); % apply ff correction to images
%                                 save('Corrected_img.mat', 'corrected_qBRM')
%                             end

                            app.System_status.Text = 'Homing Illumination Polarizer';
                            app.mmc.setPosition(current_z); % reset the focus back to the center plane
                            app.mmc.reset();
                            app.motor_ill.MoveTo(app.ill_RCA_LCA_settings(1,1), 60000);
                            pause(3)
                            [~, ~] = read_rotat_stage_position(app);

                            update_progress_bar(app, 0);
                            % enable all buttons back after acqusition
                            
                            
                            pause(0.25)
                            save('Z-Stack_positions.mat', 'z_positions')
                            %SCC_upload(app, main_folder)

                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            %%%%%                       qBRM stitch                                    %%%%%
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                        case 'Stitching'
                            disp('Stitching')
                            qBRM_point_degree = [0, 30, 90];
                            if size(app.grid_idx_selected, 1) == 4
                                % Make folders for the different illumination polarizer angles
                                num_folders = size(app.x_img_mesh,1)*size(app.x_img_mesh,2);
                                step = app.ZStackStepSize.Value;
                                size_z = 1;
                                current_z = load_mmcore(app);
                                
                                if strcmp(app.StitchingType.Value,'Multi-Plane')
                                    [num_images_row,num_images_col] = size(app.x_img_mesh);
                                    z_mesh = zeros(num_images_row,num_images_col,(app.SampleThickness.Value + app.ZStackoffset.Value));
                                    z_mesh(:,:,1) = calc_z_plane(app) - ((app.SampleThickness.Value/2)+app.ZStackoffset.Value)*ones(num_images_row,num_images_col); % get bottom plane

                                    for z = 1:(app.SampleThickness.Value)+app.ZStackoffset.Value
                                        z_mesh(:,:, z+1) =  z_mesh(:, :, z) + ones(num_images_row,num_images_col);
                                    end
                                    size_z = size(z_mesh,3);
                                else
                                    size_z = 1;
                                end
                           
                                current_folder = pwd;
                                mkdir('Stitch')
                                cd('Stitch')
                                x_mesh = app.x_img_mesh;
                                y_mesh = app.y_img_mesh;
                                z = app.z_img_mesh;
                                save('qBRM_Coordinates.mat', 'z','x_mesh','y_mesh')
                                current = pwd;
                                
                                count_idx = 1;
                                for index = 1:numel(app.x_img_mesh)
                                    cd(current)
                                    mkdir(sprintf('qBRM_img%03d', index));
                                    cd(sprintf('qBRM_img%03d', index))
                                    if strcmp(app.StitchingType.Value,'Multi-Plane')
                                        for ii = 1:size_z
                                            mkdir(sprintf('z_position_%03d', ii))
                                        end
                                    end
                                end

                                %%% dont take FF unless you explicitly want
                                %%% it
                                cd(current)
                                [ff_images, dark_frame] = take_ff_dark_return_to_position(app); % get ff and dark frames and return to original position
                                save('ff_dark.mat','ff_images','dark_frame');
                                y = 0;
                                counter = 1;
                                counter_z = 1;
                                for y_loop = 1:size(app.x_img_mesh,1)
                                    y = y + 1;
                                    if(mod(y,2) == 1)
                                        pause(25); %% every new y value we shgould take a break for 15-30 seconds
                                    end
                                    for x_loop = 1:size(app.x_img_mesh,2)
                                        
                                        app.System_status.Text = sprintf('Acquiring Position #%03d', (y_loop-1)*size(app.x_img_mesh,2) + x_loop);
                                        drawnow
                                        if(mod(y,2) == 1)
                                            % For odd number rows, moving left to right
                                            x = x_loop;
                                        else
                                            % For even number rows, moving right to left
                                            x = size(app.x_img_mesh,2) - x_loop + 1;
                                        end
                                        cd(current)
                                        save('index.mat', 'x', 'y');
                                        % move to correct motor position
                                        y_current = app.y_img_mesh(y,x);
                                        x_current = app.x_img_mesh(y,x);
                                        move_motor(app, x_current, y_current);

                                        
                                        cd(sprintf('qBRM_img%03d',counter))

                                        for step = 1:length(qBRM_point_degree)
                                            
                                            %%%%% Cancel Acquisiton Check %%%%
                                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                            if app.acquisition_flag
                                                cancel_acquisition(app);
                                                return
                                            end

                                            app.System_status.Text = sprintf('Acquiring Position #%03d (Step: %d)', (y_loop-1)*size(app.x_img_mesh,2) + x_loop, step);
                                            drawnow
                                            app.motor_ill.MoveTo(qBRM_point_degree(step), 60000); % move the linear polarizer after each image
                                            if( step == 1)
                                                pause(3.5); % Extra pause to allow the motorized stage to get to the first setting
                                            end
                                            pause(1)
                                            for z_pos = 1:size_z
                                                cd(current)
                                                cd(sprintf('qBRM_img%03d',counter))
                                                if strcmp(app.StitchingType.Value,'Multi-Plane')
                                                    cd(sprintf('z_position_%03d', z_pos))
                                                end
                                                new_filename = sprintf('img_%d', step);
                                                if exist('z_mesh','var') == 1
                                                    update_progress_bar(app, round(100*(z_pos/size(z_mesh,3))));
                                                    app.mmc.setPosition(z_mesh(y,x,z_pos));
                                                else
                                                    update_progress_bar(app, round(100*(step/ceil(180/app.qBRM_step))));
                                                    app.mmc.setPosition(app.z_img_mesh(y,x));
                                                end
                                                pause(0.35);
                                                img = Image_snapshot(app,app.Exposure_time_2.Value);
                                                save([new_filename,'.mat'],'img','-v7.3');
                                            end
                                            update_progress_bar(app, 0);
                                        end
                                        counter = counter + 1;
                                        counter_z = counter_z+1;
                                        cd(current)
                                        
                                    end
                                end
                                [~, ~] = read_rotat_stage_position(app);
                                
                                % upload ff corrected .mat file into correct SCC directory
                                %SCC_upload(app, current_folder)
                                app.System_status.Text = 'Standby';
                                update_progress_bar(app, 0);
                                app.mmc.reset()
                            else
                                message = 'Grid Has not been initialized';
                                uiwait(warndlg(message),5);
                            end

                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            %%%%%                       qBRM Widefield                                 %%%%%
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        case 'Widefield'
                            disp('Widefield')
                    end

                    app.System_status.Text = 'Homing Illumination Polarizer';
                    app.motor_ill.MoveTo(app.ill_RCA_LCA_settings(1,1), 60000);
                    app.motor_det.MoveTo(app.det_RCA_LCA_settings(1,1), 60000);
                    ill_coord = System.Decimal.ToDouble(app.motor_ill.Position);
                    det_coord = System.Decimal.ToDouble(app.motor_det.Position);
                    app.ill_polarizer.Text = sprintf('%.2f',ill_coord);
                    app.Det_polarizer.Text = sprintf('%.2f',det_coord);
                    app.IlluminationEditField.Value = ill_coord;
                    app.DetectionEditField.Value = det_coord;

                    message = sprintf('Finished qBRM Imaging saved into Folder: %s', app.save_folder);
                    %send_text_message('9788863884','Verizon',message)
                    app.System_status.Text = 'Standby';
                    drawnow;
            end
            app.CancelAcquisitionButton.Enable = 'off';
            update_progress_bar(app, 0);
            %             currentFolder = pwd;
            %             app.CurrentDirectory.Text = strcat('Current Directory: ',  currentFolder);
        end

        % Value changed function: MagnificationDropDown
        function MagnificationDropDownValueChanged(app, event)
            value = app.MagnificationDropDown.Value;
            app.mag_index = find(app.mags == value); %Find index from the Mag character
            change_magnification(app, app.MagnificationDropDown.Value)
            % can automate the exposure time for each magnification
            %             app.Exposure_time.Value = app.exp_times(app.mag_index);
            %             app.Exposure_time_2.Value = app.exp_times(app.mag_index);
            %
            update_grid_2(app)
            % have a little check in case the buttons are accidently
            % disabled
            

        end

        % Button pushed function: MoveXYmotorButton
        function MoveXYmotorButtonPushed(app, event)
            app.x_coord_motor = str2double(app.x_coord_entry.Value);
            app.y_coord_motor = str2double(app.y_coord_entry.Value);
            move_motor(app,app.x_coord_motor, app.y_coord_motor);
        end

        % Callback function
        function SnapShotPreviewButtonPushed(app, event)
            %acquire_preview_image(app,1);
            app.AddROIButton.Enable = 'off';
            app.ROI_Magnification.Enable = 'off';
        end

        % Button pushed function: Camera_view
        function Camera_viewPushed(app, event)
            %             if (strcmp(app.Current_camera.Text, 'MATLAB'))
            %                 app.System_status.Text = 'Moving to Micro-Manager';
            %                 app.FocusDownButton.Enable = 'off';
            %                 app.FocusUpButton.Enable = 'off';
            %                 app.SnapShotPreviewButton.Enable = 'off';
            %                 app.AcquireImagesButton.Enable = 'off';
            %                 % Set camera to Eye peice on microscope
            %                 %app.mmc.setConfig('lightpath','Eye'); % change light path to look at eyepiece (dark field)
            %                 app.mmc.reset();
            %                 !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe
            %                 app.Current_camera.Text = 'Micro-Manager Eye-Piece';
            %                 app.Current_camera_2.Text = 'Micro-Manager Eye-Piece';
            %                 app.System_status.Text = 'Standby';
            %             else
            %                 app.System_status.Text = 'Loading Camera to MATLAB';
            %                 load_camera_with_mmc(app);
            %                 app.mmc.setConfig('lightpath','Side Port'); % change light path to look at eyepiece (dark field)
            %                 formatSpec = '%.1f';
            %                 app.z_position = app.mmc.getPosition;
            %                 app.FocusPos.Text = num2str(app.z_position, formatSpec);
            %                 app.Current_camera.Text = 'MATLAB';
            %                 app.Current_camera_2.Text = 'MATLAB';
            %                 app.System_status.Text = 'Standby';
            %                 app.FocusDownButton.Enable = 'on';
            %                 app.FocusUpButton.Enable = 'on';
            %                 app.SnapShotPreviewButton.Enable = 'on';
            %                 app.AcquireImagesButton.Enable = 'on';
            %             end
        end

        % Callback function
        function SetZFocusButtonPushed(app, event)
            if isempty(app.x_img_mesh)
                % error
                d = dialog('Position',[300 300 400 100],'Name','Grid Information is Missing');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'Imaging coordinates have not been set. Close this dialog box and set them.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Close',...
                    'Callback','delete(gcf)');
                uiwait(d);
                return;
            else
                % Plotting
                fig1 = figure('Position',[300 50 1200 900]);
                hold on;
                plot(app.x_img_mesh,app.y_img_mesh,'.','Color','red');
                plot(app.x_grid_mesh,app.y_grid_mesh,'Color','blue');
                plot(app.x_grid_mesh',app.y_grid_mesh','Color','blue');
                x_min = min(app.x_img_mesh(1,:));
                x_max = max(app.x_img_mesh(1,:));
                y_min = min(app.y_img_mesh(:,1));
                y_max = max(app.y_img_mesh(:,1));
                xlim([x_min-1.5 x_max+1.5]);
                ylim([y_min-1.5 y_max+1.5]);
                ax = gca;
                ax.YDir = 'reverse';
                ax.XDir = 'reverse';
                xlabel('X coordinates (mm)');
                ylabel('Y coordinates (mm)');
                title('Select Upper Left sample tile for focusing.'); % Start with upper left

                % Ask user to switch to correct objective and view with Micro-Manager
                d = dialog('Position',[300 300 400 100],'Name','Use Micro-Manager for Live View');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    ['Move ',app.obj_string,' into place and view sample with Micro-Manager.']);
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);

                % Manually select tiles, go to selected coordinates, and ask user to designate if empty
                z_focus_corners = zeros(3,1);
                corner_indices = zeros(3,2);

                count = 0;
                while count < 3
                    %read mouse position
                    [x,y] = ginput(1);
                    %end function by closing interface
                    if(isempty(x) == true && isempty(y) == true)
                        break;
                    else
                        % calculating based on where you click what lines of rectangle to
                        % highlight
                        [~,x_ind] = min(abs(x*ones(1,length(app.x_img_mesh(1,:))) - app.x_img_mesh(1,:)));
                        [~,y_ind] = min(abs(y*ones(length(app.y_img_mesh(:,1)),1) - app.y_img_mesh(:,1)));
                        closed_rect = [app.x_grid_mesh(y_ind,x_ind) app.y_grid_mesh(y_ind,x_ind);...
                            app.x_grid_mesh(y_ind,x_ind) app.y_grid_mesh(y_ind+1,x_ind+1);...
                            app.x_grid_mesh(y_ind+1,x_ind+1) app.y_grid_mesh(y_ind+1,x_ind+1);...
                            app.x_grid_mesh(y_ind+1,x_ind+1) app.y_grid_mesh(y_ind,x_ind);...
                            app.x_grid_mesh(y_ind,x_ind) app.y_grid_mesh(y_ind,x_ind)];
                        tile_highlight1 = plot(closed_rect(:,1),closed_rect(:,2),'Color','red','LineWidth',5);

                        % Go to selected XY coordinates
                        y_coord = app.y_img_mesh(y_ind,1);
                        x_coord = app.x_img_mesh(1,x_ind);
                        move_motor(app, x_coord, y_coord);

                        % Ask user to confirm that they would like to use this tile for determining objective focus for the current corner
                        use_tile_ans = questdlg('Use this tile for determining Z-focus position in this corner of the sample?',...
                            'Choose corner tile for determining Z-focus','Yes','No - choose a new tile','Yes');
                        if(strcmp(use_tile_ans,'Yes') == 1)
                            % Tell user to close Micro-Manager to get objective Z-position
                            d = dialog('Position',[300 300 400 100],'Name','Bring sample into focus and close Micro-Manager to get objective Z-position');
                            d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                                'Close Micro-Manager before clicking "Continue".');
                            d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                                'Callback','delete(gcf)');
                            %waits until you click continue
                            uiwait(d);

                            % Load microscope hardware through Micro-Manager core and get objective position
                            import mmcorej.*;
                            app.mmc = CMMCore;
                            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control

                            count = count + 1;
                            z_focus_corners(count,1) = app.mmc.getPosition;
                            corner_indices(count,:) = [y_ind,x_ind];
                            app.mmc.reset();
                            if(count < 3)
                                !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe
                                app.camera_status.Text = 'Micro-Manager';
                            end

                            % Display recorded z-focus and indicate next corner to focus
                            if(count == 1)
                                P1 = [x_coord, y_coord, z_focus_corners(count,1)];
                                fprintf('Upper Left Z-focus = %f microns\n',z_focus_corners(count,1));
                                fprintf('Next, select Upper Right sample tile for focusing.\n');
                                figure(1);
                                title('Select Upper Right sample tile for focusing.'); % UL --> UR
                            elseif(count == 2)
                                P2 = [x_coord, y_coord, z_focus_corners(count,1)];
                                fprintf('Upper Right Z-focus = %f microns\n',z_focus_corners(count,1));
                                fprintf('Next, select Lower Left sample tile for focusing.\n');
                                figure(1);
                                title('Select Lower Left sample tile for focusing.'); % UR --> LL
                            elseif(count == 3)
                                P3 = [x_coord, y_coord, z_focus_corners(count,1)];
                                fprintf('Lower Left Z-focus = %f microns\n',z_focus_corners(count,1));
                                fprintf('Next, select Lower Right sample tile for focusing.\n');
                                figure(1);
                                title('Select Lower Right sample tile for focusing.'); % LL --> LR
                            end
                            delete(tile_highlight1);
                            patch(closed_rect(:,1),closed_rect(:,2),'green');

                        elseif(strcmp(use_tile_ans,'No - choose a new tile') == 1)
                            disp('Choose a new tile to use for focusing.');
                        end
                    end
                end

                % Calculate Z-focus values across the entire set of tiles
                % use 3 selected points on the plane to find plane equation
                N = cross(P1-P2,P1-P3); % find normal vector
                N = N./norm(N); % normalaze the value
                A = N(1); B=N(2); C=N(3); % define coordinates of normal vector
                x0 = P1(1); y0 = P1(2); z0 = P1(3); % define coordinated of the origin
                x = app.x_img_mesh; %set values for x,y
                y = app.y_img_mesh;
                z_img_mesh = (-A.*(x-x0)-B.*(y-y0)+C.*z0)./C; % find z values with plane equation

                app.z_img_mesh = z_img_mesh;
                cd(app.rectangular_stitch_folder);

                save('image_z_coords.mat','z_img_mesh','-v7.3');
                disp('Saved Z-focus values to image_z_coords.mat');
                close(fig1);
            end

        end

        % Callback function
        function set_up_left_buttonButtonPushed(app, event)
            % read position of the motor
            [app.ULx, app.ULy] = read_motor_position(app);

            if(all([app.ULx, app.ULy, app.grid_size]) == 1)
                % If all XY stitching coordinates have been entered
                app.rectangular_stitching = 1;

                % Choose magnification
                %[mag, app.mag_index] = choose_magnification(app);
                mag = app.mag;
                % Compute actual xy locations for rectangular grid acquisition
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                x_max = app.ULx;
                x_step =app.FOV_microns_stitching_x(app.mag_index)/1000; % convert FOV in microns to mm
                grid_size_x = app.grid_size(1,2);

                y_min = app.ULy;
                y_step = app.FOV_microns_stitching_y(app.mag_index)/1000; % convert FOV in microns to mm
                grid_size_y = app.grid_size(1,1);

                x_min = x_max-x_step*(grid_size_x-1); % minimum coordinate for x axis
                x_img = x_min:x_step:x_max;
                x_plot_grid = (x_min - x_step/2):x_step:(x_max + x_step/2); % Step is divided by 2 to center frame over boundary

                y_max = y_min+y_step*(grid_size_y-1); % maximum coordinate for y axis
                y_img = y_min:y_step:y_max;
                y_plot_grid = (y_min-y_step/2):y_step:(y_max+y_step/2); % Step is divided by 2 to center frame over boundary
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                % x_img and y_img - center points of each image
                [app.x_grid_mesh,app.y_grid_mesh] = meshgrid(x_plot_grid,y_plot_grid);
                [app.x_img_mesh,app.y_img_mesh] = meshgrid(x_img,y_img);

                %grid plotting
                plot_grid(app, x_min, x_max, y_min, y_max);
                input_save_filename(app);

                %save coordinates for opposite corner
                app.LRx = x_min;
                app.LRy = y_max;
                %app.LRx_coord.Text = num2str(app.LRx);
                %app.LRy_coord.Text = num2str(app.LRy);

                %save coordinates
                x_img_mesh = app.x_img_mesh;
                y_img_mesh = app.y_img_mesh;
                x_grid_mesh = app.x_grid_mesh;
                y_grid_mesh = app.y_grid_mesh;
                rect = app.rectangular_stitching;
                save('image_xy_coords.mat','x_img_mesh','y_img_mesh','x_grid_mesh','y_grid_mesh','mag','rect');
                disp('XY coordinates for stitching acquisition saved and ready.');
                %app.stitching_acquisition_display.Text = 'Ready';
            else
                % error
                % Missing XY stitching coordinates (UL and LR)
                d = dialog('Position',[300 300 400 100],'Name','Coordinates or Grid Size Missing');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'The upper left and lower right coordinates have not been set. Close this dialog box and set them.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Close',...
                    'Callback','delete(gcf)');
                uiwait(d);
                return;
            end
        end

        % Value changed function: Grid_yentry
        function Grid_yentryValueChanged(app, event)
            app.rect_handler = gobjects(app.Grid_xentry.Value,app.Grid_yentry.Value); % reinitialize the grid plot handler
            reset_grid_parameters(app)
            app.grid_idx_selected = [0,0];
            app.reset_grid = 1;
            update_grid_2(app)
            app.Grid_size_status.Text = sprintf('%dX%d', app.Grid_xentry.Value, app.Grid_yentry.Value);
        end

        % Value changed function: Grid_xentry
        function Grid_xentryValueChanged(app, event)
            app.rect_handler = gobjects(app.Grid_xentry.Value,app.Grid_yentry.Value); % reinitialize the grid plot handler
            reset_grid_parameters(app);
            app.reset_grid = 1;
            update_grid_2(app)
            app.grid_idx_selected = [0, 0];
            app.Grid_size_status.Text = sprintf('%dX%d', app.Grid_xentry.Value, app.Grid_yentry.Value);
        end

        % Value changed function: Exposure_time
        function Exposure_timeValueChanged(app, event)
            value = app.Exposure_time.Value;
            app.Exposure_time_2.Value = value;
            app.src.Exposure = value;
            app.ExposureTime1.Value = value;
            % acquire Preview image whenever the Exposure time changes
            %acquire_preview_image(app,0);

        end

        % Value changed function: Exposure_time_2
        function Exposure_time_2ValueChanged(app, event)
            value = app.Exposure_time_2.Value;
            app.Exposure_time.Value = value;
            app.ExposureTime1.Value = value;
            app.src.Exposure = value;
            % acquire Preview image whenever the Exposure time changes
            %acquire_preview_image(app,1);

        end

        % Button pushed function: BrowseButton
        function BrowseButton_2Pushed(app, event)
            %selpath = uigetdir('C:\Users\blank\OneDrive\_Documents\_BU\_Bigio\Images','Get Save Directory');
            selpath = uigetdir(app.default_save_folder,'Get Save Directory');
            if selpath
                C = strsplit(selpath,'\');
                path = C{:,end};
                selpath = erase(selpath, path);

                if ~isequal(selpath,0) % if a directory is selected
                    app.save_folder_name = selpath;
                    app.SaveDirectory.Value = path;
                    app.SaveDirLabel.Text = app.save_folder_name;
                end
            end
        end

        % Value changed function: AcquisitionType
        function AcquisitionTypeValueChanged(app, event)

            app.StitchingType.Enable = 'on';
            % disable all extra stuff that was enabled for different
            % acqusitions
            app.random_sampling = 0;
            app.NextButton.Enable = 'off';
            app.PrevButton.Enable = 'off';
            app.MagnificationDropDown.Enable = 'on';

            if strcmp('Widefield (4X)', app.AcquisitionType.Value)
                app.StitchingType.Enable = 'off';
                app.mag_index = 1;
                app.MagnificationDropDown.Value = app.mags(app.mag_index);
                app.MagnificationDropDown.Value = app.mags(app.mag_index);

                app.mag_index = 1; %Find index from the Mag character
                change_magnification(app,app.MagnificationDropDown.Value)
                %app.mmc.setConfig('Objective', app.MagnificationDropDown.Value); % set magnification
                app.MagnificationDropDown.Enable = 'off';
            end
            if strcmp('40X randomized', app.AcquisitionType.Value)
                if app.Randomized_locations
                    app.random_sampling = 1;
                    app.random_counter = 1;
                    app.MagnificationDropDown.Value = '40X';
                    app.MagnificationDropDown.Enable = 'off';
                    app.NextButton.Enable = 'on';
                    app.PrevButton.Enable = 'on';
                    change_magnification(app,app.MagnificationDropDown.Value)
                    %app.mmc.setConfig('Objective', app.MagnificationDropDown.Value); % set magnification
                    app.random_focuses = zeros(size(app.Randomized_locations));
                    move_motor(app,app.Randomized_locations(1,1),app.Randomized_locations(2,1))
                    %acquire_preview_image(app,0);
                    app.randomized_image_lbl.Text = sprintf('Image #: 1/%d', size(app.Randomized_locations,2));
                else
                    app.AcquisitionType.Value = 'Single image';
                    app.Acquisition_method.Text = app.AcquisitionType.Value;
                end
            end

            if strcmp('Grid Sampling', app.AcquisitionType.Value)
                if ~isempty(app.grid_locations)
                    app.random_sampling = 1;
                    app.random_counter = 1;
                    app.MagnificationDropDown.Value = '60X';
                    app.MagnificationDropDown.Enable = 'off';
                    app.NextButton.Enable = 'on';
                    app.PrevButton.Enable = 'on';
                    change_magnification(app,app.MagnificationDropDown.Value)
                    %app.mmc.setConfig('Objective', app.MagnificationDropDown.Value); % set magnification
                    app.random_focuses = zeros(size(app.grid_locations));
                    move_motor(app,app.grid_locations(1,1),app.grid_locations(2,1))
                    %acquire_preview_image(app,0);
                    app.randomized_image_lbl.Text = sprintf('Image #: 1/%d', size(app.grid_locations,2));
                else
                    app.AcquisitionType.Value = 'Single image';
                    app.Acquisition_method.Text = app.AcquisitionType.Value;
                end
            end
            if strcmp(app.AcquisitionType.Value, 'Stitching')
                if app.Grid_select_points <4
                    app.GridStatusLamp.Color = 'red';
                end
            end
            update_grid_2(app);
        end

        % Button pushed function: SetUpperLeftButton
        function SetUpperLeftButtonPushed(app, event)
            % read position of the motor
            [app.ULx, app.ULy] = read_motor_position(app);
            app.grid_idx_selected = [0,0];
            app.grid_plane = [];
            
            app.reset_grid = 1;

            %reset_grid_parameters(app);
            app.SelectPositionButton.Enable = 'on';
            Check_UL_position(app); % check to make sure the UL and LR positions are good
            app.current_ROI_img = 0;
            app.grid_idx_selected = [0,0];
            if strcmp(app.GridSelectionDropDown.Value, 'Two Corners')
                %calc grid size then just do normal update
                x_min = app.LRx;
                x_max = app.ULx;
                x_step = app.FOV_microns_stitching_x(app.mag_index)/1000; % convert FOV in microns to mm
                x_max = x_max + x_step/2; % increase to include the point itself
                x_img = x_min:x_step:x_max;
                x_plot_grid = (x_min-x_step/2):x_step:(x_max+x_step/2); % Step is divided by 2 to center frame over boundary
                app.Grid_xentry.Value = length(x_plot_grid);

                y_min = app.ULy;
                y_max = app.LRy;
                y_step = app.FOV_microns_stitching_y(app.mag_index)/1000; % convert FOV in microns to mm
                y_max = y_max + y_step/2;  % increase to include the point itself
                y_img = y_min:y_step:y_max;
                y_plot_grid = (y_min-y_step/2):y_step:(y_max+y_step/2); % Step is divided by 2 to center frame over boundary
                app.Grid_yentry.Value = length(y_plot_grid);
            end

            update_grid_2(app)
        end

        % Button pushed function: SetLowerRightButton
        function SetLowerRightButtonPushed(app, event)
            [app.LRx, app.LRy] = read_motor_position(app);
            Check_LR_position(app); % check UL and LR to make sure they are valid
            app.SelectPositionButton.Enable = 'on';
            app.LR_Location.Text = sprintf('(%.2f,%.2f)', app.LRx, app.LRy);
            app.grid_plane = [];
            %reset_grid_parameters(app);
            %calc grid size then just do normal update
            x_min = app.LRx;
            x_max = app.ULx;
            x_step = app.FOV_microns_stitching_x(app.mag_index)/1000; % convert FOV in microns to mm
            x_max = x_max + x_step/2; % increase to include the point itself
            x_img = x_min:x_step:x_max;
            x_plot_grid = (x_min-x_step/2):x_step:(x_max+x_step/2); % Step is divided by 2 to center frame over boundary
            app.Grid_xentry.Value = length(x_plot_grid);

            y_min = app.ULy;
            y_max = app.LRy;
            y_step = app.FOV_microns_stitching_y(app.mag_index)/1000; % convert FOV in microns to mm
            y_max = y_max + y_step/2;  % increase to include the point itself
            y_img = y_min:y_step:y_max;
            y_plot_grid = (y_min-y_step/2):y_step:(y_max+y_step/2); % Step is divided by 2 to center frame over boundary
            app.Grid_yentry.Value = length(y_plot_grid);

            update_grid_2(app)
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            if (app.grid_select_index(1) >1)
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                app.grid_select_index(1) = app.grid_select_index(1) -1;
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'green';
                % move motor based on index of the x and y image mesh grid
                if app.current_ROI_img
                    move_motor(app, app.ROI_struct(app.current_ROI_img).gridx(app.grid_select_index(1),app.grid_select_index(2)),...
                        app.ROI_struct(app.current_ROI_img).gridy(app.grid_select_index(1),app.grid_select_index(2)))
                    disp('---------------')
                    disp(app.ROI_struct(app.current_ROI_img).full_xgrid)
                else
                    move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                        app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
                end

                %acquire_preview_image(app,0);
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            if (app.grid_select_index(2) < app.Grid_xentry.Value)
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                app.grid_select_index(2) = app.grid_select_index(2) +1;
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'green';

                % move motor based on index of the x and y image mesh grid
                if app.current_ROI_img
                    move_motor(app, app.ROI_struct(app.current_ROI_img).gridx(app.grid_select_index(1),app.grid_select_index(2)),...
                        app.ROI_struct(app.current_ROI_img).gridy(app.grid_select_index(1),app.grid_select_index(2)))
                else
                    move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                        app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
                end
                %acquire_preview_image(app,0);
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            if (app.grid_select_index(2) > 1)
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                app.grid_select_index(2) = app.grid_select_index(2) -1;
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'green';

                % move motor based on index of the x and y image mesh grid
                if app.current_ROI_img
                    move_motor(app, app.ROI_struct(app.current_ROI_img).gridx(app.grid_select_index(1),app.grid_select_index(2)),...
                        app.ROI_struct(app.current_ROI_img).gridy(app.grid_select_index(1),app.grid_select_index(2)))
                else
                    move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                        app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
                end
                %acquire_preview_image(app,0);
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            if (app.grid_select_index(1) < app.Grid_yentry.Value) %change to more general value (app.grid_size_y)
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                app.grid_select_index(1) = app.grid_select_index(1) +1;
                app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'green';
                % move motor based on index of the x and y image mesh grid

                if app.current_ROI_img
                    move_motor(app, app.ROI_struct(app.current_ROI_img).gridx(app.grid_select_index(1),app.grid_select_index(2)),...
                        app.ROI_struct(app.current_ROI_img).gridy(app.grid_select_index(1),app.grid_select_index(2)))
                else
                    move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                        app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
                end


                %acquire_preview_image(app,0);
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            app.x_img_mesh
            app.y_img_mesh
            switch key
                case 'uparrow'
                    if (app.grid_select_index(2) < app.Grid_yentry.Value)
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                        app.grid_select_index(2) = app.grid_select_index(2) +1;
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'green';
                        % move motor based on index of the x and y image mesh grid
                        move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                            app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))

                        %acquire_preview_image(app,0);
                    end
                case 'downarrow'
                    if (app.grid_select_index(2) > 1)
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                        app.grid_select_index(2) = app.grid_select_index(2) -1;
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'green';
                        % move motor based on index of the x and y image mesh grid
                        move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                            app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))

                        %acquire_preview_image(app,0);
                    end
                case 'rightarrow'
                    if (app.grid_select_index(1) < app.Grid_xentry.Value)
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                        app.grid_select_index(1) = app.grid_select_index(1) +1;
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'green';
                        % move motor based on index of the x and y image mesh grid
                        move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                            app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))

                        %acquire_preview_image(app,0);
                    end
                case 'leftarrow'
                    if (app.grid_select_index(1) > 1)
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
                        app.grid_select_index(1) = app.grid_select_index(1) -1;
                        app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'green';
                        % move motor based on index of the x and y image mesh grid
                        move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
                            app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))

                        %acquire_preview_image(app,0);
                    end
            end
        end

        % Button pushed function: SelectPositionButton
        function SelectPositionButtonPushed(app, event)

            if app.current_ROI_img
                grid_select_points = app.ROI_struct(app.current_ROI_img).grid_selected;
            else
                grid_select_points = app.Grid_select_points;
            end
            [grid_already_selected, ~] = ismember(app.current_grid_selection, app.grid_idx_selected, 'rows');
            if grid_already_selected
                message = 'Location already Selected for Stitching';
                uiwait(warndlg(message),4);
            else
                % if auto focusing is selected then find the correct values
                if app.AutoFocus.Value
                    app.System_status.Text = 'Auto-Focusing';
                    app.autofocus_start = 0;
                    %[img, current_z] = autofocusing(app, 0, app.Exposure_time.Value, '10X', 'nothing');
                    [~, current_z] = autofocusing(app, 0, app.Exposure_time.Value, app.MagnificationDropDown.Value, 'nothing');
                    app.autofocus_start = 1;
                    disp(current_z)
                end
                app.grid_idx_selected = [app.grid_idx_selected; app.current_grid_selection];
                
                update_grid_2(app)
                % get all positions and save to a global Plane var to calculate
                % plane later on
                z_focus = app.load_mmcore;
                app.mmc.reset();
                [x_pos, y_pos] = read_motor_position(app);
                app.grid_plane(:,:, end+1) = [x_pos, y_pos, z_focus];
%                 if app.current_ROI_img
%                     app.ROI_struct(app.current_ROI_img).grid_plane(:,:,grid_select_points) = [x_pos, y_pos, z_focus];
%                     app.ROI_struct(app.current_ROI_img).grid_selected = app.ROI_struct(app.current_ROI_img).grid_selected + 1;
%                     app.ROI_struct(app.current_ROI_img).index_selected(end+1,:) = [app.grid_select_index(1),app.grid_select_index(2)];
%                     disp('selected')
%                     disp(app.ROI_struct(app.current_ROI_img).index_selected(end,:))
%                 else
%                     app.grid_plane(:,:,grid_select_points) = [x_pos, y_pos, z_focus];
%                     app.Grid_select_points = app.Grid_select_points + 1;
%                 end
                grid_select_points = grid_select_points + 1;
                app.UnSelectPositionButton.Enable = 'on';

                %acquire_preview_image(app,0);

%                 % move to a new location
%                 if (app.grid_select_index(1) < app.Grid_yentry.Value) && (grid_select_points == 2)
%                     app.gridtile_array(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
%                     app.grid_select_index(1) = app.grid_select_index(1) +1;
%                     app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'green';
% 
%                     if app.current_ROI_img
%                         move_motor(app, app.ROI_struct(app.current_ROI_img).gridx(app.grid_select_index(1),app.grid_select_index(2)),...
%                             app.ROI_struct(app.current_ROI_img).gridy(app.grid_select_index(1),app.grid_select_index(2)))
%                     else
%                         move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
%                             app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
%                     end
% 
%                 elseif (app.grid_select_index(2) < app.Grid_xentry.Value) && (grid_select_points == 3)
%                     app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'black';
%                     app.grid_select_index(2) = app.grid_select_index(2) +1;
%                     app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'green';
%                     if app.current_ROI_img
%                         move_motor(app, app.ROI_struct(app.current_ROI_img).gridx(app.grid_select_index(1),app.grid_select_index(2)),...
%                             app.ROI_struct(app.current_ROI_img).gridy(app.grid_select_index(1),app.grid_select_index(2)))
% 
%                     else
%                         move_motor(app, app.x_img_mesh(app.grid_select_index(1),app.grid_select_index(2)),...
%                             app.y_img_mesh(app.grid_select_index(1),app.grid_select_index(2)))
%                     end
% 
%                 end

                %acquire_preview_image(app,0);

                if size(app.grid_idx_selected,1) >3
                    app.SelectPositionButton.Enable = 'off';
                    z_plane = calc_z_plane(app);
                    if app.current_ROI_img
                        app.ROI_struct(app.current_ROI_img).z_mesh = z_plane;
                    else
                        app.z_img_mesh = z_plane;
                    end

                    app.GridStatusLamp.Color = 'Green';

                end
            end
        end

        % Button pushed function: UnSelectPositionButton
        function UnSelectPositionButtonPushed(app, event)
            if app.current_ROI_img
                grid_select_points = app.ROI_struct(app.current_ROI_img).grid_selected;
            else
                disp('nope')
            end
            grid_select_points = size(app.grid_plane,3);
            [grid_already_selected, idx] = ismember(app.current_grid_selection, app.grid_idx_selected, 'rows');
            if grid_already_selected
                app.grid_idx_selected(idx,:) = [];

                if grid_select_points >1
                    if app.current_ROI_img
                        app.ROI_struct(app.current_ROI_img).grid_plane(:,:,grid_select_points) = [0 0 0];
                        app.ROI_struct(app.current_ROI_img).grid_selected = app.ROI_struct(app.current_ROI_img).grid_selected - 1;
                    else
                        app.grid_plane(:,:,grid_select_points) = [0 0 0];
                        app.Grid_select_points = app.Grid_select_points - 1;
                    end
                    grid_select_points = grid_select_points - 1;

                end

                if grid_select_points < 4
                    app.SelectPositionButton.Enable = 'on';
                end
            else
                message = 'Location is not currently Selected for Stitching';
                uiwait(warndlg(message),4);
            end
            update_grid_2(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.mmc.reset()
                disp('Camera Disconnected')
            catch
                disp('Could not reset camera')
            end
            try
                disconnect_all_motors(app)
                disp('Motors Disconnected')
            catch
                disp('Couldnt Disconnect Motor')
            end

            delete(app)

        end

        % Callback function
        function OpenMicroManagerButtonPushed(app, event)
            try
                app.System_status.Text = 'Moving to Micro-Manager';
                app.mmc.reset();
                app.FocusDownButton.Enable = 'off';
                app.FocusUpButton.Enable = 'off';

                app.AcquireImagesButton.Enable = 'off';
                !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe
            catch
                disp('MicroManager is already Loaded')
            end
        end

        % Callback function
        function Step_sizeValueChanged(app, event)
            value = app.Step_size.Value;
            app.Step_size.Value = value;
            app.Step_size_2.Value = value;

        end

        % Callback function
        function Step_size_2ValueChanged(app, event)
            value = app.Step_size_2.Value;
            app.Step_size.Value = value;
            app.Step_size_2.Value = value;
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            %% allow up to 3 ROIs
            app.ROI_counter = app.ROI_counter + 1;
            app.ROISelection.Enable = 'on';

            %generate random starting position and size (needs to be added)
            %x = a + (b-a).*rand(n,1);
            if app.ROI_counter < 10 % 10 ROIs maximum just to simplify the
                ROI_string = sprintf("ROI-%d %sX", app.ROI_counter, app.ROI_Magnification.Value);
                if app.ROI_counter ==1
                    app.ROI_string_array = ROI_string;
                else
                    app.ROI_string_array(end+1) = ROI_string; % add new string to our listbox for handling later on
                end
                index = find(app.mags == app.ROI_Magnification.Value);
                disp(index)
                x = 2;
                y = 2;
                % calc the actual grid from this.
                % Compute actual xy locations for rectangular grid acquisition
                 Generatearray = @(A,S,N) A+S*(0:N-1);
                 x_max = app.ULx+0.25*app.FOV_microns_stitching_x(index)/1000; % the actual position of the ROI in the Img space
                 x_step = app.FOV_microns_stitching_x(index)/1000;
                 %x_min = x_max - x*x_step;
                 x_img = Generatearray(x_max,x_step,x);
                 %center_posx = x_max:-x_step:x_min;

                 y_max = app.ULy-0.25*app.FOV_microns_stitching_x(index)/1000;  % its actually the min value but its the top left of our matrix
                 y_step = app.FOV_microns_stitching_y(index)/1000;
                 %y_min = y_max + y*y_step;
                 y_img = Generatearray(y_max,-y_step,y);
                 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                [x_mesh,y_mesh] = meshgrid(x_img,y_img);
                disp(x_mesh)
                %update ROI struct to include mesh of points and
                %magnification
                app.ROI_struct(app.ROI_counter).gridx = x_mesh;
                app.ROI_struct(app.ROI_counter).gridy = y_mesh;
                app.ROI_struct(app.ROI_counter).magnification = app.ROI_Magnification.Value;
                app.ROI_struct(app.ROI_counter).grid_selected = 1;
                app.ROI_struct(app.ROI_counter).index_selected = [];
                app.ROI_struct(app.ROI_counter).z_mesh = [];
                app.ROI_struct(app.ROI_counter).grid_plane = [];
                app.ROI_struct(app.ROI_counter).Exposure_time = 250;
                app.ROI_struct(app.ROI_counter).position = [0 0 500 500];
                app.ROI_struct(app.ROI_counter).label = ROI_string;
                app.ROI_struct(app.ROI_counter).folder = app.save_folder;

                app.ROISelection.Items = [app.ROI_string_array]; % append the new string to the ROI_selection label
                app.ROISelection.ItemsData(app.ROI_counter) = [app.ROI_counter];
                %app.ROI_param{app.ROI_counter} = {app.ROI_Magnification.Value, app.ROI_mesh_x, app.ROI_mesh_y}
                app.ROI_handler(app.ROI_counter,1) = drawrectangle('Parent', app.Image_axis, 'Position', [0 0 500 500], 'Label',ROI_string...
                    ,'FaceAlpha', 0.25);
                addlistener(app.ROI_handler(app.ROI_counter,1),'ROIMoved', @(src,event)app.all_events(src,event,app.ROI_counter, app.ROI_struct(app.ROI_counter).magnification));
            else
                message = 'Max # of ROIs Reached';
                uiwait(warndlg(message),3);
            end
        end

        % Value changed function: ROI_Magnification
        function ROI_MagnificationValueChanged(app, event)
            value = app.ROI_Magnification.Value;
            app.ROI_mag_index = find(app.mags == value); %Find index from the Mag character
        end

        % Value changed function: ROISelection
        function ROISelectionValueChanged(app, event)
            value = app.ROISelection.Value;
            app.MagnificationDropDown.Value = app.ROI_struct(value).magnification;
            change_magnification(app,app.ROI_struct(value).magnification)

            if app.ROI_struct(value).grid_selected >3
                app.SelectPositionButton.Enable = 'off';
                app.UnSelectPositionButton.Enable = 'on';
            else
                app.SelectPositionButton.Enable = 'on';
                app.UnSelectPositionButton.Enable = 'off';
            end

            %update exposure_time
            app.ROIExposureEditField.Value = app.ROI_struct(value).Exposure_time;
            app.Exposure_time.Value = app.ROI_struct(value).Exposure_time;
            app.Exposure_time_2.Value = app.ROI_struct(value).Exposure_time;


            app.current_ROI_img = value; %update current value so we know where to save the grid and plane
            [y, x] = size(app.ROI_struct(value).gridx);
            app.grid_select_size_handler = [x y];
            temp_ULx = app.ROI_struct(value).gridx(1,1);
            temp_ULy = app.ROI_struct(value).gridy(1,1);

            index = find(app.mags == app.ROI_struct(value).magnification);
            %%% need to update the grid with the selected ROI
            cla(app.ROI_axis,'reset') %reset axis

            %update grid size
            app.Grid_yentry.Enable = 'off';
            app.Grid_xentry.Enable = 'off';

            app.Grid_xentry.Value = x;
            app.Grid_yentry.Value = y;

            %update the handler to create the rectangle grid
            app.rect_handler = gobjects(y,x); % reinitialize the grid plot handler
            center_position_actualx = zeros(1,x);
            center_position_actualy = zeros(1,y);

            %%%%%%%%%%% acutally create grid %%%%%%%%%%%%%%%%%%%%%%%%%%%
            for x_ind = 1:x %start at top left index
                for y_ind = 1:y
                    %get the top left position for each rectangle depending
                    %on the index (x,y) positions
                    posx = temp_ULx - ((x_ind-2)*app.FOV_microns_stitching_x(index)/1000); % rectangle corners at each index
                    posy = temp_ULy + ((y_ind-2)*app.FOV_microns_stitching_y(index)/1000);% rectangle corner at each index
                    center_posx = posx + app.FOV_microns_stitching_x(index)/2000; % x retangle center (plotting)
                    center_posy = posy + app.FOV_microns_stitching_y(index)/2000; % y retangle center (plotting)

                    %%% calc the actual (x,y) positions for imaging (actual)
                    center_position_actualx(x_ind) = posx - app.FOV_microns_x(index)/2000; % The actual position for x
                    center_position_actualy(y_ind) = posy + app.FOV_microns_y(index)/2000; % The actual position for y

                    app.rect_handler(y_ind,x_ind) = rectangle(app.ROI_axis, 'Position', ...
                        [posx posy app.FOV_microns_stitching_x(index)/1000 app.FOV_microns_stitching_y(index)/1000], 'FaceColor', 'White');
                    hold(app.ROI_axis,'on');
                    app.UIAxes.XDir = 'Reverse';
                    app.UIAxes.YDir = 'Reverse';

                    plot(app.ROI_axis, center_posx, center_posy, 'r*');
                end
            end

            app.grid_select_index = [1,1];
            [app.ROI_struct(value).full_xgrid, app.ROI_struct(value).full_ygrid] = meshgrid(center_position_actualx, center_position_actualy);

            app.rect_handler(app.grid_select_index(1), app.grid_select_index(2)).EdgeColor = 'green';

            for x = 1:size(app.ROI_struct(app.current_ROI_img).index_selected, 1)
                app.rect_handler(app.ROI_struct(app.current_ROI_img).index_selected(x,1),app.ROI_struct(app.current_ROI_img).index_selected(x,2)).FaceColor = 'Green';
            end
        end

        % Button pushed function: ResetROIsButton
        function ResetROIsButtonPushed(app, event)
            reset_ROI_settings(app);
        end

        % Value changed function: GridSelectionDropDown
        function GridSelectionDropDownValueChanged(app, event)
            if strcmp(app.GridSelectionDropDown.Value, 'Two Corners')
                app.SetLowerRightButton.Enable = 'on';
                app.LRx = app.ULx - app.FOV_microns_stitching_x(app.mag_index)/1000;
                app.LRy = app.ULy + app.FOV_microns_stitching_x(app.mag_index)/1000;
                app.LR_Location.Text = sprintf('(%.2f,%.2f)', app.LRx, app.LRy);

                %calc grid size then just do normal update from top left
                x_min = app.LRx;
                x_max = app.ULx;
                x_step = app.FOV_microns_stitching_x(app.mag_index)/1000; % convert FOV in microns to mm
                x_max = x_max + x_step/2; % increase to include the point itself
                x_img = x_min:x_step:x_max;
                x_plot_grid = (x_min-x_step/2):x_step:(x_max+x_step/2); % Step is divided by 2 to center frame over boundary
                app.Grid_xentry.Value = length(x_plot_grid);

                y_min = app.ULy;
                y_max = app.LRy;
                y_step = app.FOV_microns_stitching_y(app.mag_index)/1000; % convert FOV in microns to mm
                y_max = y_max + y_step/2;  % increase to include the point itself
                y_img = y_min:y_step:y_max;
                y_plot_grid = (y_min-y_step/2):y_step:(y_max+y_step/2); % Step is divided by 2 to center frame over boundary
                app.Grid_yentry.Value = length(y_plot_grid);

                app.Grid_xentry.Enable = 'off';
                app.Grid_yentry.Enable = 'off';

                update_grid_2(app)
            else
                app.SetLowerRightButton.Enable = 'off';
                app.LR_Location.Text = 'NA';
                app.Grid_xentry.Enable = 'on';
                app.Grid_yentry.Enable = 'on';
                app.Grid_xentry.Value = 2;
                app.Grid_yentry.Value = 2;
                update_grid_2(app)
            end

        end

        % Value changed function: MicroscopeSetup
        function MicroscopeSetupValueChanged(app, event)
            value = app.MicroscopeSetup.Value;
            if strcmp(value, 'qBRM')
                msg = 'Switching to qBRM imaging requires removing the Illumination Waveplate';
                uiwait(msgbox(msg),3);
            end
        end

        % Button pushed function: AutoFocusButton
        function AutoFocusButtonPushed(app, event)
            app.autofocus_start = 0;
            app.System_status.Text = sprintf('Setting focus with %s', app.MagnificationDropDown.Value);
            [img, app.z_position] = autofocusing(app, 0, app.Exposure_time.Value, app.MagnificationDropDown.Value, 'nothing');
            app.autofocus_start = 1;

            imshow(uint8(img), 'Parent', app.Image_Axis, 'InitialMagnification', 'fit');
            app.widefield_loaded = 0;
            imshow(uint8(img), 'Parent', app.Image_Axis_2, 'InitialMagnification', 'fit');

        end

        % Button pushed function: LoadWideFieldROIButton
        function LoadWideFieldROIButtonPushed(app, event)
            if ~isempty(app.widefield_image)
                app.widefield_folder = app.save_folder;
                app.widefield_loaded = 1;
                unload_camera(app)
                app.hImage = image(app.Image_axis, zeros(size(app.widefield_image,2),size(app.widefield_image,1),1));
                app.Image_axis.XLim = [0,size(app.widefield_image,2)];
                app.Image_axis.YLim = [0,size(app.widefield_image,1)];
                app.Image_axis.XTick = [];
                app.Image_axis.YTick = [];
                pbaspect(app.Image_axis,[size(app.widefield_image,2),size(app.widefield_image,1),1])
                imshow(uint16(app.widefield_image), 'Parent', app.Image_axis, 'InitialMagnification', 'fit');
                app.widefield_loaded = 1;
            else
                message = 'No Widefield Image has been Acquired yet.';
                uiwait(msgbox(message), 4);
            end
        end

        % Value changed function: ROIExposureEditField
        function ROIExposureEditFieldValueChanged(app, event)
            value = app.ROIExposureEditField.Value;
            app.ROI_struct(app.current_ROI_img).Exposure_time = value;
            app.Exposure_time.Value = value;
            app.Exposure_time_2.Value = value;


            % acquire Preview image whenever the Exposure time changes
            %acquire_preview_image(app,0);
        end

        % Value changed function: SaveDirectory
        function SaveDirectoryValueChanged(app, event)
            app.new_foldername = app.SaveDirectory.Value;
        end

        % Button pushed function: RandomizedROIs
        function RandomizedROIsPushed(app, event)
            if app.widefield_loaded
                polygon = drawpolygon('Parent', app.Image_axis, 'FaceAlpha',0);
                app.polygon_coord = polygon.Position;
                app.maxX = round(max(app.polygon_coord(:,1)));
                app.maxY = round(max(app.polygon_coord(:,2)));
                delete(polygon)
                app.Randomized_locations = [];
                app.patchx_coord = [];
                app.patchy_coord = [];
                cla(app.UIAxes)
                imshow(app.widefield_image, 'Parent', app.Image_axis)
                app.widefield_loaded = 1;
                patch(app.polygon_coord(:,1), app.polygon_coord(:,2),'red', 'EdgeColor','blue','FaceColor','none','LineWidth',2, 'LineStyle', '--', 'Parent', app.Image_Axis);
                number_points = app.NumberofROIs.Value;
                counter = 1;
                tries = 0;
                %mag_list = {'4X','10X','16X','20X','32X','40X','64X','60X','96X'};
                ROI_image_size_pixels = [1200 1600; 480 640; 300 400;240 320;150 200; 120 160; 72 96; 80 107; 50 66.7];
                for i = 1:number_points
                    flagisin = 0;
                    while ~flagisin
                        tries = tries + 1;
                        x(i) = randperm(app.maxX,1)-1;
                        y(i) = randperm(app.maxY,1)-1;
                        patchx = [x(i); x(i) + ROI_image_size_pixels(6,2); x(i)+ROI_image_size_pixels(6,2); x(i)];
                        patchy = [y(i)-ROI_image_size_pixels(6,1); y(i) - ROI_image_size_pixels(6,1); y(i); y(i)];
                        got_inside = inpolygon(patchx, patchy, app.polygon_coord(:,1),app.polygon_coord(:,2));
                        if got_inside
                            safe = 1;
                            if app.patchx_coord
                                for ii = 1:size(app.patchx_coord,2)
                                    test_inside_any = inpolygon(patchx, patchy, app.patchx_coord(:,ii), app.patchy_coord(:,ii));
                                    if any(test_inside_any)
                                        safe = 0;
                                    end
                                end
                            end
                            if safe
                                offset = [0.415 0.225]; % offset caused by issues with imperfect stage and stitching (need better solution
                                app.patchx_coord = [app.patchx_coord patchx];
                                app.patchy_coord = [app.patchy_coord patchy];
                                convert_stage_space = [app.ULx - patchx(1)/(10*app.pixels_per_100_microns(1)) + app.FOV_microns_x(6)/2000 - offset(1); app.ULy + patchy(3)/(10*app.pixels_per_100_microns(1)) - app.FOV_microns_y(6)/2000 - offset(2)];
                                app.Randomized_locations = [app.Randomized_locations convert_stage_space];
                                counter = counter + 1;
                                flagisin = 1;
                            end
                            if tries > 50000
                                disp('Too many tries couldny find solution')
                                break;
                            end
                        end
                    end
                end
                patch(app.patchx_coord, app.patchy_coord,'red', 'EdgeColor','red','FaceColor','none','LineWidth',2, 'Parent', app.Image_axis);
            end
        end

        % Button pushed function: SetFocusButton
        function SetFocusButtonPushed(app, event)
            if app.random_sampling || (strcmp('Grid Sampling',  app.AcquisitionType.Value))
                current_z = load_mmcore(app);
                app.random_focuses(:, app.random_counter) = [current_z; app.Random_ExposureTime.Value];
                if all(app.random_focuses)
                    app.RandomLamp.Color = 'green';
                end
                app.random_counter = app.random_counter + 1;

                if strcmp('Grid Sampling', app.AcquisitionType.Value)
                    if app.random_counter > size(app.grid_locations,2)
                        app.random_counter = 1;
                    end
                    move_motor(app, app.grid_locations(1, app.random_counter), app.grid_locations(2, app.random_counter))
                    %acquire_preview_image(app,0);
                    app.randomized_image_lbl.Text = sprintf('Image # %d/%d', app.random_counter, size(app.grid_locations,2));
                elseif strcmp('40X randomized', app.AcquisitionType.Value)
                    if app.random_counter > size(app.Randomized_locations,2)
                        app.random_counter = 1;
                    end
                    move_motor(app, app.Randomized_locations(1, app.random_counter), app.Randomized_locations(2, app.random_counter))
                    %acquire_preview_image(app,0);
                    app.randomized_image_lbl.Text = sprintf('Image # %d/%d', app.random_counter, size(app.Randomized_locations,2));
                end
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.random_counter = app.random_counter + 1;
            if strcmp('Grid Sampling', app.AcquisitionType.Value)
                if app.random_counter > size(app.grid_locations,2)
                    app.random_counter = 1;
                end
                move_motor(app, app.grid_locations(1, app.random_counter), app.grid_locations(2, app.random_counter))
                app.randomized_image_lbl.Text = sprintf('Image # %d/%d', app.random_counter, size(app.grid_locations,2));
            elseif strcmp('40X randomized', app.AcquisitionType.Value)
                if app.random_counter > size(app.Randomized_locations,2)
                    app.random_counter = 1;
                end
                move_motor(app, app.Randomized_locations(1, app.random_counter), app.Randomized_locations(2, app.random_counter))
                app.randomized_image_lbl.Text = sprintf('Image # %d/%d', app.random_counter, size(app.Randomized_locations,2));
            end
            img = snap_image(app.Random_ExposureTime.Value,app.mmc);
            fm = fmeasure(img,'BREN');
            app.FocusLevel.Text = sprintf('Focus Level (au) = %10.2f', fm);
            
            imshow(uint8(img), 'Parent', app.Image_Axis_2, 'InitialMagnification', 'fit');
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            app.random_counter = app.random_counter - 1;

            if strcmp('Grid Sampling', app.AcquisitionType.Value)
                if app.random_counter > size(app.grid_locations,2)
                    app.random_counter = 1;
                end
                move_motor(app, app.grid_locations(1, app.random_counter), app.grid_locations(2, app.random_counter))
                app.randomized_image_lbl.Text = sprintf('Image # %d/%d', app.random_counter, size(app.grid_locations,2));
            elseif strcmp('40X randomized', app.AcquisitionType.Value)
                if app.random_counter > size(app.Randomized_locations,2)
                    app.random_counter = 1;
                end
                move_motor(app, app.Randomized_locations(1, app.random_counter), app.Randomized_locations(2, app.random_counter))
                app.randomized_image_lbl.Text = sprintf('Image # %d/%d', app.random_counter, size(app.Randomized_locations,2));
            end
            img = snap_image(app.Random_ExposureTime.Value,app.mmc);
            fm = fmeasure(img,'BREN');
            app.FocusLevel.Text = sprintf('Focus Level (au) = %10.2f', fm);
            
            imshow(uint8(img), 'Parent', app.Image_Axis_2, 'InitialMagnification', 'fit');
        end

        % Value changed function: Random_ExposureTime
        function Random_ExposureTimeValueChanged(app, event)
            value = app.Random_ExposureTime.Value;
            app.Exposure_time.Value = value;
            app.Exposure_time_2.Value = value;

            app.ExposureTime1.Value = value;
            img = snap_image(app.Exposure_time.Value,app.mmc);
            fm = fmeasure(img,'BREN');
            app.FocusLevel.Text = sprintf('Focus Level (au) = %10.2f', fm);
            
            imshow(uint8(img), 'Parent', app.Image_Axis_2, 'InitialMagnification', 'fit');
        end

        % Button pushed function: AutofocusButton
        function AutofocusButtonPushed(app, event)
            %Focus up
            % move z focus
            for step_size = [50 25 12 6 3 1]
                position_0 = load_mmcore(app);
                fm_0 = measure_focus(app);

                position_1 = position_0 + step_size;
                app.mmc.setPosition(position_1);
                pause(0.35)
                fm_1 = measure_focus(app);

                position_2 = position_0 - step_size;
                app.mmc.setPosition(position_2);
                pause(0.35)
                fm_2 = measure_focus(app);

                fm = [fm_0, fm_1, fm_2];
                position = [position_0, position_1, position_2];
                [~, idx] = max(fm);
                app.mmc.setPosition(position(idx));
                pause(0.35)
            end
            % acquire Image and update position
            formatSpec = '%.1f';
            app.z_position = load_mmcore(app);
            
           
            %acquire_preview_image(app,0);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if strcmp(app.TabGroup.SelectedTab.Title,'Acquisition')%% check if we are in correct tab for this
                %%%% if grid tab is open then check if we selected one of the locations
                if strcmp(app.TabGroup2.SelectedTab.Title,'Grid')
                    if (app.Grid_axis.CurrentPoint(1,1) > 0) && (app.Grid_axis.CurrentPoint(1,1) < app.Grid_xentry.Value)...
                            && (app.Grid_axis.CurrentPoint(1,2) > 0) && (app.Grid_axis.CurrentPoint(1,2) <app.Grid_yentry.Value)
                        X = floor(app.Grid_axis.CurrentPoint(1,1)) + 1;
                        Y = floor(app.Grid_axis.CurrentPoint(1,2)) + 1;
                        app.current_grid_selection = [X,Y];
                        
                        update_grid_2(app)
                        move_motor(app, app.x_img_mesh(Y,X),app.y_img_mesh(Y,X));
                    end
                end
                %%%% check if we have a sampling grid open ("Fractionater")
                if app.widefield_loaded
                    % make sure we are in the image area
                    if (app.Image_axis.CurrentPoint(1,1) > 0) && (app.Image_axis.CurrentPoint(1,1) < app.image_size(2))...
                            && (app.Image_axis.CurrentPoint(1,2) > 0) && (app.Image_axis.CurrentPoint(1,2) < app.image_size(1))
                        ROI_image_size_pixels = [1200 1600; 480 640; 300 400;240 320;150 200; 120 160; 72 96; 80 107; 50 66.7];
                        mag_selection = 7;
                        X = floor(app.Image_Axis.CurrentPoint(1,1)/(2*ROI_image_size_pixels(mag_selection,2))) + 1;
                        Y = floor(app.Image_Axis.CurrentPoint(2,2)/(2*ROI_image_size_pixels(mag_selection,1))) + 1;
                        idx = [X; Y];
                        try
                            if (app.grid_array(X,Y).FaceColor == [0 1 0])
                                app.grid_array(X,Y).FaceColor = [0 0 0 0];
                            else
                                app.grid_array(X,Y).FaceColor = [0 1 0 0.5];
                                offset = [0.415 0.0075]; % offset caused by issues with imperfect stage and stitching (need better solution
                                convert_stage_space = ...
                                    [app.widefield_image_UL(1) - (X-1)*2*ROI_image_size_pixels(mag_selection,2)/(10*app.pixels_per_100_microns(1)) + (app.FOV_microns_x(6)/2000) - offset(1);...
                                    app.widefield_image_UL(2) + (Y-1)*2*ROI_image_size_pixels(mag_selection,1)/(10*app.pixels_per_100_microns(1)) - (app.FOV_microns_y(6)/2000) - offset(2)];
                                app.grid_locations = [app.grid_locations convert_stage_space];
                                app.grid_idx = [app.grid_idx, idx];
                                move_motor(app, convert_stage_space(1),convert_stage_space(2));
                            end
                        catch ME
                            disp('Just started')
                        end
                    end
                end
            end
        end

        % Button pushed function: LoadSamplingGridButton
        function LoadSamplingGridButtonPushed(app, event)
            if app.widefield_loaded
                app.grid_locations = [];
                app.grid_idx = [];
                ROI_image_size_pixels = [1200 1600; 480 640; 300 400;240 320;150 200; 120 160; 72 96; 80 107; 50 66.7];
                [rows, columns] = size(app.widefield_image);
                app.image_size = [rows, columns];
                mag_selection = 7; % force it to be the 60X objective
                app.grid_array = gobjects(ceil(rows/(2*ROI_image_size_pixels(mag_selection,2))),ceil(columns/(2*ROI_image_size_pixels(mag_selection,1)))); % reinitialize the grid plot handler
                app.image_rectangles = gobjects(ceil(rows/(2*ROI_image_size_pixels(mag_selection,2))),ceil(columns/(2*ROI_image_size_pixels(mag_selection,1)))); % reinitialize the grid plot handler
                for row = 1: ceil(columns/(2*ROI_image_size_pixels(mag_selection,2)))
                    for col = 1 : ceil(rows/(2*ROI_image_size_pixels(mag_selection,1)))
                        app.grid_array(row, col) = rectangle('Position', [(row-1)*2*ROI_image_size_pixels(mag_selection,2), (col-1)*2*ROI_image_size_pixels(mag_selection,1), 2*ROI_image_size_pixels(mag_selection,2), 2*ROI_image_size_pixels(mag_selection,1)],...
                            'Parent', app.Image_Axis, ...
                            'EdgeColor', 'red', ...
                            'FaceColor', [0, 0, 0, 0.1]);
                    end
                end
            end
        end

        % Button pushed function: Rotate_Detection_2
        function Rotate_Detection_2ButtonPushed(app, event)
            app.motor_ill.MoveTo(app.IlluminationEditField.Value, 60000);

            ill_coord = System.Decimal.ToDouble(app.motor_ill.Position);
            det_coord = System.Decimal.ToDouble(app.motor_det.Position);
            app.ill_polarizer.Text = sprintf('%.2f',ill_coord);
            app.Det_polarizer.Text = sprintf('%.2f',det_coord);
            app.IlluminationEditField.Value = ill_coord;
            app.DetectionEditField.Value = det_coord;
        end

        % Button pushed function: Rotate_Detection
        function Rotate_DetectionButtonPushed(app, event)
            app.motor_det.MoveTo(app.DetectionEditField.Value, 60000);

            ill_coord = System.Decimal.ToDouble(app.motor_ill.Position);
            det_coord = System.Decimal.ToDouble(app.motor_det.Position);
            app.ill_polarizer.Text = sprintf('%.2f',ill_coord);
            app.Det_polarizer.Text = sprintf('%.2f',det_coord);
            app.IlluminationEditField.Value = ill_coord;
            app.DetectionEditField.Value = det_coord;
        end

        % Button pushed function: HomemotorsButton
        function HomemotorsButtonPushed(app, event)
            app.motor_ill.MoveTo(app.ill_RCA_LCA_settings(1,1), 60000);
            app.motor_det.MoveTo(app.det_RCA_LCA_settings(1,1), 60000);
            ill_coord = System.Decimal.ToDouble(app.motor_ill.Position);
            det_coord = System.Decimal.ToDouble(app.motor_det.Position);
            app.ill_polarizer.Text = sprintf('%.2f',ill_coord);
            app.Det_polarizer.Text = sprintf('%.2f',det_coord);
            app.IlluminationEditField.Value = ill_coord;
            app.DetectionEditField.Value = det_coord;
        end

        % Value changed function: HeightEditField, WidthEditField
        function WidthpxEditFieldValueChanged(app, event)
            stoppreview(app.vid)
            app.hImage = image(app.Image_axis, zeros(app.WidthEditField.Value,app.HeightEditField.Value,1));
            app.Image_axis.XLim = [0,round(app.WidthEditField.Value)];
            app.Image_axis.YLim = [0,round(app.HeightEditField.Value)];
            app.Image_axis.XTick = [];
            app.Image_axis.YTick = [];
            pbaspect(app.Image_axis,[round(app.WidthEditField.Value),round(app.HeightEditField.Value),1])
            app.image_size = app.vid.ROIPosition;
            center = [1600 1100];
            app.vid.ROIPosition = [(center(1) - round(app.WidthEditField.Value/2)) (center(2)-round(app.HeightEditField.Value/2)) round(app.WidthEditField.Value) round(app.HeightEditField.Value)];
            Camera_preview(app)
        end

        % Value changed function: CameraBinningDropDown
        function CameraBinningDropDownValueChanged(app, event)
            app.src.Binning = app.CameraBinningDropDown.Value;
            stoppreview(app.vid)
            if strcmp(app.src.Binning,'2x2')
                app.WidthEditField.Value = round(app.WidthEditField.Value/2);
                app.HeightEditField.Value = round(app.HeightEditField.Value/2);
            else
                app.WidthEditField.Value = round(app.WidthEditField.Value*2);
                app.HeightEditField.Value = round(app.HeightEditField.Value*2);
            end
            app.camera_pixels = [app.WidthEditField.Value, app.HeightEditField.Value];
            
            app.hImage = image(app.Image_axis, zeros(app.camera_pixels(1),app.camera_pixels(2),1));
            app.Image_axis.XLim = [0,app.camera_pixels(1)];
            app.Image_axis.YLim = [0,app.camera_pixels(2)];
            app.Image_axis.XTick = [];
            app.Image_axis.YTick = [];
            Camera_preview(app)

        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab;
            if strcmp(selectedTab, 'grid')
                disp('im in grid')
            end
        end

        % Button pushed function: ResetCameraButton
        function ResetCameraButtonPushed(app, event)
            imaqreset
            update_progress_bar(app, 5);
            app.cam_info = imaqhwinfo;
            app.vid = videoinput('pmimaq_2019b',1,'PM-Cam 3200x2200');
            app.vid.FramesPerTrigger = 1;

            Load_camera_default(app);
            app.mmc.reset();
        end

        % Button pushed function: SetGlobalPlaneButton
        function SetGlobalPlaneButtonPushed(app, event)
            disp('option to set this plane as the z plane (no slope)')
        end

        % Value changed function: Exposure_Units
        function Exposure_UnitsValueChanged(app, event)
            app.src.ExpRes = app.Exposure_Units.Value;
            if strcmp(app.Exposure_Units.Value,'us')
                value = 500;
            else
                value = 5;
            end
            app.Exposure_time_2.Value = value;
            app.ExposureTime1.Value = value;
            app.src.Exposure = app.Exposure_time_2.Value;
            app.msLabel_4.Text = app.Exposure_Units.Value;
            app.msLabel_5.Text = app.Exposure_Units.Value;
        end

        % Value changed function: TileOverlapEditField
        function TileOverlapEditFieldValueChanged(app, event)
            app.overlap_for_stitching = app.TileOverlapEditField.Value/100;
            disp('still need to add code to change grid')
        end

        % Value changed function: SavedLocationsListBox
        function SavedLocationsListBoxValueChanged(app, event)
            value = app.SavedLocationsListBox.Value;
            move_motor(app, app.saved_locations(1,value),app.saved_locations(2,value))
        end

        % Button pushed function: SaveLocationButton
        function SaveLocationButtonPushed(app, event)
            [x_coord, y_coord] = read_motor_position(app);
            app.saved_locations = [app.saved_locations, [x_coord;y_coord]];
            app.SavedLocationsListBox.ItemsData = [app.SavedLocationsListBox.ItemsData,length(app.saved_locations)];
            disp(app.SavedLocationsListBox.ItemsData)
            app.SavedLocationsListBox.Items = [app.SavedLocationsListBox.Items, sprintf('%s-(%5.1f,%5.1f)', app.LocationLabelEditField.Value,x_coord, y_coord)]; % append the new string to the ROI_selection label
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.saved_locations = [55;37.5];
            app.SavedLocationsListBox.ItemsData = [1];
            app.SavedLocationsListBox.Items = {'Home (55,37.5)'};
        end

        % Button pushed function: UnselectButton
        function UnselectButtonPushed(app, event)
            app.SavedLocationsListBox.Value={};
        end

        % Button pushed function: CancelAcquisitionButton
        function CancelAcquisitionButtonPushed(app, event)
            app.acquisition_flag = true;
        end

        % Button pushed function: LoadAcqusitionButton
        function LoadAcqusitionButtonPushed(app, event)
            disp('load acquisiton')
            selpath = uigetdir(app.default_save_folder,'Get Save Directory');
            
            if selpath
                %%%% need to load in the acquisition information
                current_main = pwd;
                cd(selpath)

                idx = load('index.mat');
                mesh = load('qBRM_Coordinates.mat');
                x_start = idx.x;
                y_start = idx.y;
                x_mesh = mesh.x_mesh;
                y_mesh = mesh.y_mesh;
                z_mesh = mesh.z;
                disp('Stitching')
                qBRM_point_degree = [0, 30, 90];

                % Make folders for the different illumination polarizer angles
                num_folders = size(app.x_img_mesh,1)*size(app.x_img_mesh,2);
                step = app.ZStackStepSize.Value;
                size_z = 1;
                current_z = load_mmcore(app);
                size_z = size(z_mesh,3);

                %%% dont take FF unless you explicitly want
                %%% it
                %[ff_images, dark_frame] = take_ff_dark_return_to_position(app); % get ff and dark frames and return to original position
                %save('ff_dark.mat','ff_images','dark_frame');
                counter = 1;
                counter_z = 1;
                cd(selpath)

                for y_loop = 1:size(x_mesh,1)
                    for x_loop = 1:size(x_mesh,2)

                        app.System_status.Text = sprintf('Acquiring Position #%03d', (y_loop-1)*size(x_mesh,2) + x_loop);
                        drawnow
                        if(mod(y_loop,2) == 1)
                            % For odd number rows, moving left to right
                            x_idx = x_loop;
                        else
                            % For even number rows, moving right to left
                            x_idx = size(x_mesh,2) - x_loop + 1;
                        end
                        current = pwd;
                        if ~((x_loop <x_start) && (y_loop <= y_start))
                            % move to correct motor position
                            y_current = y_mesh(y_loop,x_idx);
                            x_current = x_mesh(y_loop,x_idx);
                            %fprintf('X: %5.2f Y: %5.2f -------------- ', y_current, x_current)
                            move_motor(app, x_current, y_current);

                            
                            cd(sprintf('qBRM_img%03d',counter))

                            for step = 1:length(qBRM_point_degree)

                                %%%%% Cancel Acquisiton Check %%%%
                                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                if app.acquisition_flag
                                    cancel_acquisition(app);
                                    return
                                end

                                app.System_status.Text = sprintf('Acquiring Position #%03d (Step: %d)', (y_loop-1)*size(x_mesh,2) + x_loop, step);
                                drawnow
                                app.motor_ill.MoveTo(qBRM_point_degree(step), 60000); % move the linear polarizer after each image
                                if( step == 1)
                                    pause(3.5); % Extra pause to allow the motorized stage to get to the first setting
                                end
                                pause(1)
                                cd(current)
                                cd(sprintf('qBRM_img%03d',counter))

                                new_filename = sprintf('img_%d', step);
                                update_progress_bar(app, round(100*(step/ceil(180/app.qBRM_step))));
                                app.mmc.setPosition(z_mesh(y_loop,x_idx));
                                pause(0.35);
                                img = int16(Image_snapshot(app,app.Exposure_time.Value));
                                save([new_filename,'.mat'],'img','-v7.3');
                                
                            end
                        end
                        counter = counter + 1;
                        counter_z = counter_z+1;
                        cd(current)
                        x = x_idx;
                        y = y_loop;
                        save('index.mat', 'x', 'y');
                    end                    
                end
                [~, ~] = read_rotat_stage_position(app);

                % upload ff corrected .mat file into correct SCC directory
                %SCC_upload(app, current_folder)
                app.System_status.Text = 'Standby';
                update_progress_bar(app, 0);
                app.mmc.reset()
            end


%{
                for y_loop = y-1:size(x_mesh,1)
                    y = y + 1;
                    for x_loop = x:size(x_mesh,2)
                        app.System_status.Text = sprintf('Acquiring Position #%03d', (y_loop-1)*size(x_mesh,2) + x_loop);
                        drawnow
                        if(mod(y,2) == 1)
                            % For odd number rows, moving left to right
                            x = x_loop;
                        else
                            % For even number rows, moving right to left
                            x = size(x_mesh,2) - x_loop + 1;
                        end
                        % move to correct motor position
                        y_current = y_mesh(y,x);
                        x_current = x_mesh(y,x);
                        move_motor(app, x_current, y_current);
                        
                        disp(pwd)
                        current = pwd;
                        cd(sprintf('qBRM_img%03d',counter))

                        for step = 1:length(qBRM_point_degree)

                            %%%%% Cancel Acquisiton Check %%%%
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            if app.acquisition_flag
                                cancel_acquisition(app);
                                return
                            end

                            app.System_status.Text = sprintf('Acquiring Position #%03d (Step: %d)', (y_loop-1)*size(x_mesh,2) + x_loop, step);
                            drawnow
                            app.motor_ill.MoveTo(qBRM_point_degree(step), 60000); % move the linear polarizer after each image
                            if( step == 1)
                                pause(3.5); % Extra pause to allow the motorized stage to get to the first setting
                            end
                            pause(1)
                            for z_pos = 1:size_z
                                cd(current)
                                cd(sprintf('qBRM_img%03d',counter))

                                cd(sprintf('z_position_%03d', z_pos))

                                new_filename = sprintf('img_%d', step);
                                if exist('z_mesh','var') == 1
                                    update_progress_bar(app, round(100*(z_pos/size(z_mesh,3))));
                                    app.mmc.setPosition(z_mesh(y,x,z_pos));
                                else
                                    update_progress_bar(app, round(100*(step/ceil(180/app.qBRM_step))));
                                    app.mmc.setPosition(app.z_img_mesh(y,x));
                                end
                                pause(0.35);
                                img = int16(Image_snapshot(app,app.Exposure_time.Value));
                                save([new_filename,'.mat'],'img','-v7.3');
                            end
                            update_progress_bar(app, 0);
                        end
                        counter = counter + 1;
                        counter_z = counter_z+1;
                        cd(current)
                        save('index.mat', 'x', 'y');
                    end
                end
                [~, ~] = read_rotat_stage_position(app);

                % upload ff corrected .mat file into correct SCC directory
                %SCC_upload(app, current_folder)
                app.System_status.Text = 'Standby';
                update_progress_bar(app, 0);
                app.mmc.reset()
            end
%}
        end

        % Button pushed function: ReadzpositionButton
        function ReadzpositionButtonPushed(app, event)
            app.zPositionLabel.Text = num2str(load_mmcore(app));
            app.mmc.reset();
        end

        % Button down function: Image_axis
        function Image_axisButtonDown(app, event)
            % need to add a function that when you click on the image you
            % can measure the size of something or you can move to that
            % area on the tissue
            x = (app.Image_axis.CurrentPoint(1,1)-(1600))/app.pixels_per_100_microns(app.mag_index)*0.1;
            y = (app.Image_axis.CurrentPoint(1,2)-(1100))/app.pixels_per_100_microns(app.mag_index)*0.1;

            move_motor(app,app.motor_x_coord - x,app.motor_y_coord + y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.SystemStatusPanel = uipanel(app.AcquisitionTab);
            app.SystemStatusPanel.Title = 'System Status:';
            app.Progress_Bar = uiaxes(app.SystemStatusPanel);
            title(app.Progress_Bar, '0%')
            app.Progress_Bar.XLim = [0 1];
            app.Progress_Bar.YLim = [0 1];
            app.Progress_Bar.XTick = [];
            app.Progress_Bar.XTickLabel = '';
            app.Progress_Bar.YTick = [];
            app.Progress_Bar.YTickLabel = '';
            app.GridSizeLabel_2 = uilabel(app.SystemStatusPanel);
            app.GridSizeLabel_2.Text = 'Grid Size: ';
            app.Grid_size_status = uilabel(app.SystemStatusPanel);
            app.Grid_size_status.Text = '2x2';
            app.System_status = uilabel(app.SystemStatusPanel);
            app.System_status.Text = 'Standby';
            app.CurrentOperationLabel = uilabel(app.SystemStatusPanel);
            app.CurrentOperationLabel.Text = 'Current Operation:';
            app.MicroscopeSetupLabel = uilabel(app.SystemStatusPanel);
            app.MicroscopeSetupLabel.Text = 'Microscope Setup:';
            app.Label_5 = uilabel(app.SystemStatusPanel);
            app.Label_5.Text = '......................................................................................................................................................................................................';
            app.MicroscopeSetup = uidropdown(app.SystemStatusPanel);
            app.MicroscopeSetup.Items = {'CCP-BRM', 'qBRM', 'Brightfield Microscopy', 'Rotating Linear Polarizer'};
            app.MicroscopeSetup.ValueChangedFcn = createCallbackFcn(app, @MicroscopeSetupValueChanged, true);
            app.MicroscopeSetup.Value = 'CCP-BRM';
            app.AcquisitionTypeLabel_3 = uilabel(app.SystemStatusPanel);
            app.AcquisitionTypeLabel_3.Text = 'Acquisition Type:';
            app.AcquisitionType = uidropdown(app.SystemStatusPanel);
            app.AcquisitionType.Items = {'Single Image', 'qBRM (3 point)', 'Single Z-Stack', 'Stitching', 'Widefield (4X)', 'ROI Imaging', '40X randomized', 'Grid Sampling'};
            app.AcquisitionType.ValueChangedFcn = createCallbackFcn(app, @AcquisitionTypeValueChanged, true);
            app.AcquisitionType.Value = 'Single Image';
            app.MagnificationDropDownLabel = uilabel(app.SystemStatusPanel);
            app.MagnificationDropDownLabel.Text = 'Magnification:';
            app.MagnificationDropDown = uidropdown(app.SystemStatusPanel);
            app.MagnificationDropDown.Items = {'4X', '10X', '16X', '20X', '32X', '40X', '60X', '64X', '96X'};
            app.MagnificationDropDown.ItemsData = [4 10 16 20 32 40 60 64 96];
            app.MagnificationDropDown.ValueChangedFcn = createCallbackFcn(app, @MagnificationDropDownValueChanged, true);
            app.MagnificationDropDown.Value = 20;
            app.Label_8 = uilabel(app.SystemStatusPanel);
            app.Label_8.Text = '......................................................................................................................................................................................................';
            app.Stage_pos = uilabel(app.SystemStatusPanel);
            app.Stage_pos.Text = 'X: 0 Y: 0';
            app.StagePositionLabel = uilabel(app.SystemStatusPanel);
            app.StagePositionLabel.Text = 'Stage Position:';
            app.LoadAcqusitionButton = uibutton(app.SystemStatusPanel, 'push');
            app.LoadAcqusitionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAcqusitionButtonPushed, true);
            app.LoadAcqusitionButton.Text = 'Load Acqusition';
            app.ImagePreviewPanel = uipanel(app.AcquisitionTab);
            app.ImagePreviewPanel.Title = 'Image Preview';
            app.Histogram_axis = uiaxes(app.ImagePreviewPanel);
            app.Histogram_axis.XLim = [0 255];
            app.Histogram_axis.XTick = [];
            app.Histogram_axis.YTick = [];
            app.Image_axis = uiaxes(app.ImagePreviewPanel);
            zlabel(app.Image_axis, 'Z')
            app.Image_axis.XTick = [];
            app.Image_axis.YTick = [];
            app.Image_axis.ButtonDownFcn = createCallbackFcn(app, @Image_axisButtonDown, true);
            app.MagnificationDropDown_2Label = uilabel(app.ImagePreviewPanel);
            app.MagnificationDropDown_2Label.Enable = 'off';
            app.MagnificationDropDown_2Label.Text = 'Magnification';
            app.ROI_Magnification = uidropdown(app.ImagePreviewPanel);
            app.ROI_Magnification.Items = {'4X', '10X', '16X', '20X', '32X', '40X', '60X', '64X', '96X'};
            app.ROI_Magnification.ItemsData = {'4', '10', '16', '20', '32', '40', '60,64,96'};
            app.ROI_Magnification.ValueChangedFcn = createCallbackFcn(app, @ROI_MagnificationValueChanged, true);
            app.ROI_Magnification.Enable = 'off';
            app.ROI_Magnification.Value = '20';
            app.AddROIButton = uibutton(app.ImagePreviewPanel, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Enable = 'off';
            app.AddROIButton.Text = 'Add ROI';
            app.LoadWideFieldROIButton = uibutton(app.ImagePreviewPanel, 'push');
            app.LoadWideFieldROIButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWideFieldROIButtonPushed, true);
            app.LoadWideFieldROIButton.Text = 'Load WideField ROI';
            app.RandomizedROIs = uibutton(app.ImagePreviewPanel, 'push');
            app.RandomizedROIs.ButtonPushedFcn = createCallbackFcn(app, @RandomizedROIsPushed, true);
            app.RandomizedROIs.Enable = 'off';
            app.RandomizedROIs.Text = 'Randomized ROIs';
            app.NumberofROIsEditFieldLabel = uilabel(app.ImagePreviewPanel);
            app.NumberofROIsEditFieldLabel.Text = 'Number of ROIs';
            app.NumberofROIs = uieditfield(app.ImagePreviewPanel, 'numeric');
            app.NumberofROIs.Value = 5;
            app.LoadSamplingGridButton = uibutton(app.ImagePreviewPanel, 'push');
            app.LoadSamplingGridButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSamplingGridButtonPushed, true);
            app.LoadSamplingGridButton.Text = 'Load Sampling Grid';
            app.FPSLabel = uilabel(app.ImagePreviewPanel);
            app.FPSLabel.Text = '0 FPS';
            app.Label_9 = uilabel(app.ImagePreviewPanel);
            app.Label_9.Text = '';
            app.FilenameEditFieldLabel = uilabel(app.AcquisitionTab);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FileName = uieditfield(app.AcquisitionTab, 'text');
            app.filename_ext = uilabel(app.AcquisitionTab);
            app.filename_ext.Text = '_exposure-time_magnification';
            app.Camera_view = uibutton(app.AcquisitionTab, 'push');
            app.Camera_view.ButtonPushedFcn = createCallbackFcn(app, @Camera_viewPushed, true);
            app.Camera_view.Text = 'Camera View';
            app.TabGroup2 = uitabgroup(app.AcquisitionTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.CameraTab = uitab(app.TabGroup2);
            app.CameraTab.Title = 'Camera';
            app.CameraPropertiesPanel = uipanel(app.CameraTab);
            app.CameraPropertiesPanel.Title = 'Camera Properties:';
            app.WidthpxEditFieldLabel = uilabel(app.CameraPropertiesPanel);
            app.WidthpxEditFieldLabel.Text = 'Width (px)';
            app.WidthEditField = uieditfield(app.CameraPropertiesPanel, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthpxEditFieldValueChanged, true);
            app.WidthEditField.Value = 3200;
            app.HeightpxEditFieldLabel = uilabel(app.CameraPropertiesPanel);
            app.HeightpxEditFieldLabel.Text = 'Height (px)';
            app.HeightEditField = uieditfield(app.CameraPropertiesPanel, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @WidthpxEditFieldValueChanged, true);
            app.HeightEditField.Value = 2200;
            app.AutoContrastButton = uibutton(app.CameraPropertiesPanel, 'state');
            app.AutoContrastButton.Text = 'Auto Contrast';
            app.CameraBinningDropDownLabel = uilabel(app.CameraPropertiesPanel);
            app.CameraBinningDropDownLabel.Text = 'Camera Binning';
            app.CameraBinningDropDown = uidropdown(app.CameraPropertiesPanel);
            app.CameraBinningDropDown.Items = {'1x1', '2x2'};
            app.CameraBinningDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraBinningDropDownValueChanged, true);
            app.CameraBinningDropDown.Value = '1x1';
            app.ROIPositionpxLabel = uilabel(app.CameraPropertiesPanel);
            app.ROIPositionpxLabel.Text = 'ROI Position (px):';
            app.X0Y0Label = uilabel(app.CameraPropertiesPanel);
            app.X0Y0Label.Text = 'X: 0 Y: 0 ';
            app.ResolutionLabel = uilabel(app.CameraPropertiesPanel);
            app.ResolutionLabel.Text = 'Resolution: 3200x2200';
            app.XOffsetEditFieldLabel = uilabel(app.CameraPropertiesPanel);
            app.XOffsetEditFieldLabel.Text = 'X-Offset';
            app.XOffsetEditField = uieditfield(app.CameraPropertiesPanel, 'numeric');
            app.XOffsetEditField.Limits = [0 3200];
            app.XOffsetEditField.Editable = 'off';
            app.YOffsetEditFieldLabel = uilabel(app.CameraPropertiesPanel);
            app.YOffsetEditFieldLabel.Text = 'Y-Offset';
            app.YOffsetEditField = uieditfield(app.CameraPropertiesPanel, 'numeric');
            app.YOffsetEditField.Limits = [0 2200];
            app.YOffsetEditField.Editable = 'off';
            app.Image = uiimage(app.CameraPropertiesPanel);
            app.ResetCameraButton = uibutton(app.CameraPropertiesPanel, 'push');
            app.ResetCameraButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCameraButtonPushed, true);
            app.ResetCameraButton.Text = 'Reset Camera';
            app.MotorTab = uitab(app.TabGroup2);
            app.MotorTab.Title = 'Motor ';
            app.StageControlPanel = uipanel(app.MotorTab);
            app.StageControlPanel.Title = 'Stage Control';
            app.x_coord_entry = uieditfield(app.StageControlPanel, 'text');
            app.x_coord_entry.Value = '55';
            app.y_coord_entry = uieditfield(app.StageControlPanel, 'text');
            app.y_coord_entry.Value = '37.5';
            app.text54 = uilabel(app.StageControlPanel);
            app.text54.Text = 'X-Pos';
            app.text54_2 = uilabel(app.StageControlPanel);
            app.text54_2.Text = 'Y-Pos';
            app.MoveXYmotorButton = uibutton(app.StageControlPanel, 'push');
            app.MoveXYmotorButton.ButtonPushedFcn = createCallbackFcn(app, @MoveXYmotorButtonPushed, true);
            app.MoveXYmotorButton.Text = 'Move XY-motor';
            app.text54_3 = uilabel(app.StageControlPanel);
            app.text54_3.Text = 'Move to';
            app.text54_4 = uilabel(app.StageControlPanel);
            app.text54_4.Text = {'Current '; 'position'};
            app.x_coord_text = uilabel(app.StageControlPanel);
            app.x_coord_text.Text = 'NaN';
            app.y_coord_text = uilabel(app.StageControlPanel);
            app.y_coord_text.Text = 'NaN';
            app.AutoFocusButton = uibutton(app.StageControlPanel, 'push');
            app.AutoFocusButton.ButtonPushedFcn = createCallbackFcn(app, @AutoFocusButtonPushed, true);
            app.AutoFocusButton.Text = 'AutoFocus';
            app.LinearPolarizersPanel = uipanel(app.MotorTab);
            app.LinearPolarizersPanel.Title = 'Linear Polarizers:';
            app.Det_polarizer = uilabel(app.LinearPolarizersPanel);
            app.Det_polarizer.Text = '14.8 °';
            app.ill_polarizer = uilabel(app.LinearPolarizersPanel);
            app.ill_polarizer.Text = '120.03 ° ';
            app.CurrentposLabel = uilabel(app.LinearPolarizersPanel);
            app.CurrentposLabel.Text = '(Current pos )';
            app.Rotate_Detection = uibutton(app.LinearPolarizersPanel, 'push');
            app.Rotate_Detection.ButtonPushedFcn = createCallbackFcn(app, @Rotate_DetectionButtonPushed, true);
            app.Rotate_Detection.Text = 'Rotate';
            app.Label_6 = uilabel(app.LinearPolarizersPanel);
            app.Label_6.Text = '°';
            app.Label_7 = uilabel(app.LinearPolarizersPanel);
            app.Label_7.Text = '°';
            app.DetectionEditFieldLabel = uilabel(app.LinearPolarizersPanel);
            app.DetectionEditFieldLabel.Text = 'Detection';
            app.DetectionEditField = uieditfield(app.LinearPolarizersPanel, 'numeric');
            app.DetectionEditField.Limits = [0 180];
            app.DetectionEditField.Value = 14.8;
            app.IlluminationEditFieldLabel = uilabel(app.LinearPolarizersPanel);
            app.IlluminationEditFieldLabel.Text = 'Illumination';
            app.IlluminationEditField = uieditfield(app.LinearPolarizersPanel, 'numeric');
            app.IlluminationEditField.Limits = [0 180];
            app.IlluminationEditField.Value = 120.03;
            app.Rotate_Detection_2 = uibutton(app.LinearPolarizersPanel, 'push');
            app.Rotate_Detection_2.ButtonPushedFcn = createCallbackFcn(app, @Rotate_Detection_2ButtonPushed, true);
            app.Rotate_Detection_2.Text = 'Rotate';
            app.HomemotorsButton = uibutton(app.LinearPolarizersPanel, 'push');
            app.HomemotorsButton.ButtonPushedFcn = createCallbackFcn(app, @HomemotorsButtonPushed, true);
            app.HomemotorsButton.Text = 'Home motors';
            app.SavedLocationsListBoxLabel = uilabel(app.MotorTab);
            app.SavedLocationsListBoxLabel.Text = 'Saved Locations';
            app.SavedLocationsListBox = uilistbox(app.MotorTab);
            app.SavedLocationsListBox.Items = {'Home - (55,37.5)'};
            app.SavedLocationsListBox.ItemsData = 1;
            app.SavedLocationsListBox.ValueChangedFcn = createCallbackFcn(app, @SavedLocationsListBoxValueChanged, true);
            app.SavedLocationsListBox.Value = 1;
            app.SaveLocationButton = uibutton(app.MotorTab, 'push');
            app.SaveLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLocationButtonPushed, true);
            app.SaveLocationButton.Text = 'Save Location';
            app.LocationLabelEditFieldLabel = uilabel(app.MotorTab);
            app.LocationLabelEditFieldLabel.Text = 'Location Label';
            app.LocationLabelEditField = uieditfield(app.MotorTab, 'text');
            app.ClearAllButton = uibutton(app.MotorTab, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.UnselectButton = uibutton(app.MotorTab, 'push');
            app.UnselectButton.ButtonPushedFcn = createCallbackFcn(app, @UnselectButtonPushed, true);
            app.UnselectButton.Text = 'Unselect';
            app.SampleTab = uitab(app.TabGroup2);
            app.SampleTab.Title = 'Sample';
            app.SampleParametersPanel = uipanel(app.SampleTab);
            app.SampleParametersPanel.Title = 'Sample Parameters';
            app.SampleThicknessEditFieldLabel = uilabel(app.SampleParametersPanel);
            app.SampleThicknessEditFieldLabel.Text = 'Sample Thickness';
            app.SampleThickness = uieditfield(app.SampleParametersPanel, 'numeric');
            app.SampleThickness.Limits = [-10 Inf];
            app.SampleThickness.Value = 30;
            app.mLabel_3 = uilabel(app.SampleParametersPanel);
            app.mLabel_3.Text = 'µm';
            app.mLabel_4 = uilabel(app.SampleParametersPanel);
            app.mLabel_4.Text = 'µm';
            app.SamplethicknessOffsetLabel = uilabel(app.SampleParametersPanel);
            app.SamplethicknessOffsetLabel.Text = '*Sample thickness ± Offset  ';
            app.mLabel_5 = uilabel(app.SampleParametersPanel);
            app.mLabel_5.Text = 'µm';
            app.ZStackOffsetLabel = uilabel(app.SampleParametersPanel);
            app.ZStackOffsetLabel.Text = 'Z-Stack Offset';
            app.ZStackoffset = uieditfield(app.SampleParametersPanel, 'numeric');
            app.ZStackStepSizeLabel = uilabel(app.SampleParametersPanel);
            app.ZStackStepSizeLabel.Enable = 'off';
            app.ZStackStepSizeLabel.Text = 'Z-Stack Step Size';
            app.ZStackStepSize = uieditfield(app.SampleParametersPanel, 'numeric');
            app.ZStackStepSize.Limits = [0 10];
            app.ZStackStepSize.Value = 1;
            app.SampleNameEditFieldLabel = uilabel(app.SampleParametersPanel);
            app.SampleNameEditFieldLabel.Text = 'Sample Name ';
            app.SampleNameEditField = uieditfield(app.SampleParametersPanel, 'text');
            app.SampleNameEditField.Value = 'AM---';
            app.SampleNotestxtcreatedinFileTextAreaLabel = uilabel(app.SampleParametersPanel);
            app.SampleNotestxtcreatedinFileTextAreaLabel.Text = 'Sample Notes (.txt created in File)';
            app.SampleNotes = uitextarea(app.SampleParametersPanel);
            app.GridTab = uitab(app.TabGroup2);
            app.GridTab.Title = 'Grid';
            app.Grid_axis = uiaxes(app.GridTab);
            title(app.Grid_axis, 'Tiled Imaging Grid')
            xlabel(app.Grid_axis, 'X-Position')
            ylabel(app.Grid_axis, 'Y-Position')
            zlabel(app.Grid_axis, 'Z')
            app.Grid_axis.XTick = [];
            app.Grid_axis.YTick = [];
            app.SitchingParametersPanel = uipanel(app.GridTab);
            app.SitchingParametersPanel.Title = 'Sitching Parameters';
            app.GridSizeLabel_3 = uilabel(app.SitchingParametersPanel);
            app.GridSizeLabel_3.Text = 'Grid Size: ';
            app.Grid_xentry = uieditfield(app.SitchingParametersPanel, 'numeric');
            app.Grid_xentry.ValueChangedFcn = createCallbackFcn(app, @Grid_xentryValueChanged, true);
            app.Grid_xentry.Value = 2;
            app.xEditFieldLabel = uilabel(app.SitchingParametersPanel);
            app.xEditFieldLabel.Text = 'x';
            app.Grid_yentry = uieditfield(app.SitchingParametersPanel, 'numeric');
            app.Grid_yentry.ValueChangedFcn = createCallbackFcn(app, @Grid_yentryValueChanged, true);
            app.Grid_yentry.Value = 2;
            app.StitchingTypeDropDownLabel = uilabel(app.SitchingParametersPanel);
            app.StitchingTypeDropDownLabel.Text = 'Stitching Type';
            app.StitchingType = uidropdown(app.SitchingParametersPanel);
            app.StitchingType.Items = {'Single Plane', 'Multi-Plane'};
            app.StitchingType.Value = 'Single Plane';
            app.GridSelectionDropDownLabel = uilabel(app.SitchingParametersPanel);
            app.GridSelectionDropDownLabel.Text = 'Grid Selection';
            app.GridSelectionDropDown = uidropdown(app.SitchingParametersPanel);
            app.GridSelectionDropDown.Items = {'Single Corner', 'Two Corners'};
            app.GridSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @GridSelectionDropDownValueChanged, true);
            app.GridSelectionDropDown.Value = 'Single Corner';
            app.SetUpperLeftButton = uibutton(app.SitchingParametersPanel, 'push');
            app.SetUpperLeftButton.ButtonPushedFcn = createCallbackFcn(app, @SetUpperLeftButtonPushed, true);
            app.SetUpperLeftButton.Text = 'Set Upper Left';
            app.ULLabel = uilabel(app.SitchingParametersPanel);
            app.ULLabel.Text = 'UL:';
            app.UL_Location_lbl = uilabel(app.SitchingParametersPanel);
            app.UL_Location_lbl.Text = '(55,37.5)';
            app.SetLowerRightButton = uibutton(app.SitchingParametersPanel, 'push');
            app.SetLowerRightButton.ButtonPushedFcn = createCallbackFcn(app, @SetLowerRightButtonPushed, true);
            app.SetLowerRightButton.Enable = 'off';
            app.SetLowerRightButton.Text = 'Set Lower Right';
            app.LRLabel = uilabel(app.SitchingParametersPanel);
            app.LRLabel.Text = 'LR:';
            app.LR_Location = uilabel(app.SitchingParametersPanel);
            app.LR_Location.Text = 'NA';
            app.ExposureTime1Label = uilabel(app.SitchingParametersPanel);
            app.ExposureTime1Label.Text = 'Exposure Time (1):';
            app.ExposureTime1 = uieditfield(app.SitchingParametersPanel, 'numeric');
            app.ExposureTime1.Limits = [0 5000];
            app.ExposureTime1.Value = 5;
            app.ExposureTime2EditFieldLabel = uilabel(app.SitchingParametersPanel);
            app.ExposureTime2EditFieldLabel.Text = 'Exposure Time (2):';
            app.ExposureTime2 = uieditfield(app.SitchingParametersPanel, 'numeric');
            app.ExposureTime2.Limits = [0 1500];
            app.msLabel_4 = uilabel(app.SitchingParametersPanel);
            app.msLabel_4.Text = 'ms';
            app.msLabel_5 = uilabel(app.SitchingParametersPanel);
            app.msLabel_5.Text = 'ms';
            app.UnSelectPositionButton = uibutton(app.SitchingParametersPanel, 'push');
            app.UnSelectPositionButton.ButtonPushedFcn = createCallbackFcn(app, @UnSelectPositionButtonPushed, true);
            app.UnSelectPositionButton.Text = 'Un-Select Position';
            app.SelectPositionButton = uibutton(app.SitchingParametersPanel, 'push');
            app.SelectPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPositionButtonPushed, true);
            app.SelectPositionButton.Text = 'Select Position';
            app.SetGlobalPlaneButton = uibutton(app.SitchingParametersPanel, 'push');
            app.SetGlobalPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @SetGlobalPlaneButtonPushed, true);
            app.SetGlobalPlaneButton.Text = 'Set Global Plane';
            app.TileOverlapEditFieldLabel = uilabel(app.SitchingParametersPanel);
            app.TileOverlapEditFieldLabel.Text = 'Tile Overlap';
            app.TileOverlapEditField = uieditfield(app.SitchingParametersPanel, 'numeric');
            app.TileOverlapEditField.ValueChangedFcn = createCallbackFcn(app, @TileOverlapEditFieldValueChanged, true);
            app.TileOverlapEditField.Value = 10;
            app.ROITab = uitab(app.TabGroup2);
            app.ROITab.Title = 'ROI ';
            app.ROI_axis = uiaxes(app.ROITab);
            title(app.ROI_axis, 'ROI Imaging Grid')
            xlabel(app.ROI_axis, 'X-Position')
            ylabel(app.ROI_axis, 'Y-Position')
            zlabel(app.ROI_axis, 'Z')
            app.ROI_axis.XTick = [];
            app.ROI_axis.YTick = [];
            app.ROISelection = uilistbox(app.ROITab);
            app.ROISelection.Items = {};
            app.ROISelection.ValueChangedFcn = createCallbackFcn(app, @ROISelectionValueChanged, true);
            app.ROISelection.Enable = 'off';
            app.ROISelection.Value = {};
            app.ROIExposureEditField = uieditfield(app.ROITab, 'numeric');
            app.ROIExposureEditField.ValueChangedFcn = createCallbackFcn(app, @ROIExposureEditFieldValueChanged, true);
            app.ROIExposureEditField.Value = 150;
            app.ROIExposureEditFieldLabel = uilabel(app.ROITab);
            app.ROIExposureEditFieldLabel.Text = 'ROI Exposure';
            app.ResetROIsButton = uibutton(app.ROITab, 'push');
            app.ResetROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetROIsButtonPushed, true);
            app.ResetROIsButton.Text = 'Reset ROIs';
            app.UnSelectPositionButton_2 = uibutton(app.ROITab, 'push');
            app.UnSelectPositionButton_2.Text = 'Un-Select Position';
            app.SelectPositionButton_2 = uibutton(app.ROITab, 'push');
            app.SelectPositionButton_2.Text = 'Select Position';
            app.StitchingTypeDropDownLabel_2 = uilabel(app.ROITab);
            app.StitchingTypeDropDownLabel_2.Text = 'Stitching Type';
            app.StitchingType_2 = uidropdown(app.ROITab);
            app.StitchingType_2.Items = {'Single Plane', 'Multi-Plane'};
            app.StitchingType_2.Value = 'Single Plane';
            app.ZpositionTab = uitab(app.TabGroup2);
            app.ZpositionTab.Title = 'Z-position';
            app.ReadzpositionButton = uibutton(app.ZpositionTab, 'push');
            app.ReadzpositionButton.ButtonPushedFcn = createCallbackFcn(app, @ReadzpositionButtonPushed, true);
            app.ReadzpositionButton.Text = 'Read z-position';
            app.zPositionLabel = uilabel(app.ZpositionTab);
            app.zPositionLabel.Text = '';
            app.ExpTimemsLabel = uilabel(app.AcquisitionTab);
            app.ExpTimemsLabel.Text = 'Exposure Time ';
            app.Exposure_time_2 = uieditfield(app.AcquisitionTab, 'numeric');
            app.Exposure_time_2.Limits = [0 5000];
            app.Exposure_time_2.ValueChangedFcn = createCallbackFcn(app, @Exposure_time_2ValueChanged, true);
            app.Exposure_time_2.Value = 5;
            app.BrowseButton = uibutton(app.AcquisitionTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.SaveDirLabel = uilabel(app.AcquisitionTab);
            app.SaveDirLabel.Text = '';
            app.SaveDirectoryLabel = uilabel(app.AcquisitionTab);
            app.SaveDirectoryLabel.Text = 'Save Directory:     ';
            app.SaveDirectory = uieditfield(app.AcquisitionTab, 'text');
            app.SaveDirectory.ValueChangedFcn = createCallbackFcn(app, @SaveDirectoryValueChanged, true);
            app.AcquireImagesButton = uibutton(app.AcquisitionTab, 'push');
            app.AcquireImagesButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireImagesButtonPushed, true);
            app.AcquireImagesButton.Text = 'Acquire Image(s)';
            app.Exposure_Units = uidropdown(app.AcquisitionTab);
            app.Exposure_Units.Items = {'ms', 'us'};
            app.Exposure_Units.ValueChangedFcn = createCallbackFcn(app, @Exposure_UnitsValueChanged, true);
            app.Exposure_Units.Value = 'ms';
            app.CancelAcquisitionButton = uibutton(app.AcquisitionTab, 'push');
            app.CancelAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @CancelAcquisitionButtonPushed, true);
            app.CancelAcquisitionButton.Enable = 'off';
            app.CancelAcquisitionButton.Text = 'Cancel Acquisition ';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.UIAxes = uiaxes(app.SettingsTab);
            title(app.UIAxes, 'Grid Selection')
            xlabel(app.UIAxes, 'X Position')
            ylabel(app.UIAxes, 'Y Position')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Histogram_axis2 = uiaxes(app.SettingsTab);
            app.Histogram_axis2.XLim = [0 256];
            app.Histogram_axis2.XTick = [];
            app.Histogram_axis2.YTick = [];
            app.GridLayoutPanel = uipanel(app.SettingsTab);
            app.GridLayoutPanel.Title = 'Grid Layout';
            app.Image_Axis_2 = uiaxes(app.GridLayoutPanel);
            app.Image_Axis_2.XTick = [];
            app.Image_Axis_2.YTick = [];
            app.CurrentFocusLabel = uilabel(app.GridLayoutPanel);
            app.CurrentFocusLabel.Text = 'Current Focus:';
            app.FocusPos2 = uilabel(app.GridLayoutPanel);
            app.FocusPos2.Text = 'NaN';
            app.GridStatusLampLabel = uilabel(app.GridLayoutPanel);
            app.GridStatusLampLabel.Text = 'Grid Status';
            app.GridStatusLamp = uilamp(app.GridLayoutPanel);
            app.randomized_image_lbl = uilabel(app.GridLayoutPanel);
            app.randomized_image_lbl.Text = 'Image #: 1/1';
            app.SetFocusButton = uibutton(app.GridLayoutPanel, 'push');
            app.SetFocusButton.ButtonPushedFcn = createCallbackFcn(app, @SetFocusButtonPushed, true);
            app.SetFocusButton.Text = 'Set Focus';
            app.FocusLevel = uilabel(app.GridLayoutPanel);
            app.FocusLevel.Text = 'Focus Level (a.u.) = NaN';
            app.RandomLamp = uilamp(app.GridLayoutPanel);
            app.NextButton = uibutton(app.GridLayoutPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PrevButton = uibutton(app.GridLayoutPanel, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev.';
            app.ExposureTimeEditFieldLabel = uilabel(app.GridLayoutPanel);
            app.ExposureTimeEditFieldLabel.Text = 'Exposure Time';
            app.Random_ExposureTime = uieditfield(app.GridLayoutPanel, 'numeric');
            app.Random_ExposureTime.ValueChangedFcn = createCallbackFcn(app, @Random_ExposureTimeValueChanged, true);
            app.Random_ExposureTime.Value = 15;
            app.AutofocusButton = uibutton(app.GridLayoutPanel, 'push');
            app.AutofocusButton.ButtonPushedFcn = createCallbackFcn(app, @AutofocusButtonPushed, true);
            app.AutofocusButton.Text = 'Autofocus';
            app.ImageSettingsPanel = uipanel(app.SettingsTab);
            app.ImageSettingsPanel.Title = 'Image Settings';
            app.AcquisitionPanel = uipanel(app.SettingsTab);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.ExposureTimeLabel = uilabel(app.AcquisitionPanel);
            app.ExposureTimeLabel.Text = 'Exposure Time:';
            app.Exposure_time = uieditfield(app.AcquisitionPanel, 'numeric');
            app.Exposure_time.Limits = [0 5000];
            app.Exposure_time.ValueChangedFcn = createCallbackFcn(app, @Exposure_timeValueChanged, true);
            app.Exposure_time.Value = 5;
            app.AutoFocus = uicheckbox(app.AcquisitionPanel);
            app.AutoFocus.Text = 'Auto Focus';
            app.GirdSelectionPanel = uipanel(app.SettingsTab);
            app.GirdSelectionPanel.Title = 'Gird Selection';
            app.ROISelectionListBoxLabel = uilabel(app.GirdSelectionPanel);
            app.ROISelectionListBoxLabel.Enable = 'off';
            app.ROISelectionListBoxLabel.Visible = 'off';
            app.ROISelectionListBoxLabel.Text = 'ROI Selection';
            app.DownButton = uibutton(app.GirdSelectionPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.RightButton = uibutton(app.GirdSelectionPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.UpButton = uibutton(app.GirdSelectionPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.LeftButton = uibutton(app.GirdSelectionPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BRM_MicroscopeV2_new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end