classdef BiRscope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AcquisitonTab                   matlab.ui.container.Tab
        ChangeObjectiveButton           matlab.ui.control.Button
        AutosolveqBRMCheckBox           matlab.ui.control.CheckBox
        ClibrationOffsetButton          matlab.ui.control.Button
        CalibratethePolarizationOpticsButton  matlab.ui.control.Button
        SaveDirectory                   matlab.ui.control.EditField
        SaveDirectoryLabel              matlab.ui.control.Label
        SaveDirLabel                    matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        LiveViewTab                     matlab.ui.container.Tab
        CameraPreviewCheckBox           matlab.ui.control.CheckBox
        DistanceLabel                   matlab.ui.control.Label
        STDLabel                        matlab.ui.control.Label
        StandardDeviationButton         matlab.ui.control.Button
        FPSLabel                        matlab.ui.control.Label
        Histogram_axis                  matlab.ui.control.UIAxes
        Image_axis                      matlab.ui.control.UIAxes
        GridROIImagingTab               matlab.ui.container.Tab
        ExlcudeImagesoutsideROICheckBox  matlab.ui.control.CheckBox
        SetUpperLeftButton_2            matlab.ui.control.Button
        ROISelectionListBox             matlab.ui.control.ListBox
        ROISelectionListBoxLabel        matlab.ui.control.Label
        ROIImagingButton                matlab.ui.control.Button
        DrawTissueROIButton             matlab.ui.control.Button
        LoadWidefieldImageButton        matlab.ui.control.Button
        Grid_axis_2                     matlab.ui.control.UIAxes
        Image_axis_3                    matlab.ui.control.UIAxes
        Image_axis_2                    matlab.ui.control.UIAxes
        SystemStatusPanel               matlab.ui.container.Panel
        FrameAveragingEditField         matlab.ui.control.NumericEditField
        FrameAveragingEditFieldLabel    matlab.ui.control.Label
        AutoStitchCheckBox              matlab.ui.control.CheckBox
        ZeroZStageButton                matlab.ui.control.Button
        FFFramesEditField               matlab.ui.control.NumericEditField
        FFFramesEditFieldLabel          matlab.ui.control.Label
        Saveas8bit                      matlab.ui.control.CheckBox
        FF_correction                   matlab.ui.control.CheckBox
        image_progresslbl               matlab.ui.control.Label
        CancelAcquisitonButton          matlab.ui.control.Button
        Exposure_units                  matlab.ui.control.DropDown
        ExposureTime                    matlab.ui.control.NumericEditField
        ExpTimemsLabel                  matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        FilenameLabel                   matlab.ui.control.Label
        AcquireImagesButton             matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        ZPositionLabel                  matlab.ui.control.Label
        Focus_MotorPosition             matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        StagePositionLabel              matlab.ui.control.Label
        MagnificationDropDown           matlab.ui.control.DropDown
        MagnificationDropDownLabel      matlab.ui.control.Label
        Stage_pos                       matlab.ui.control.Label
        AcquisitionType                 matlab.ui.control.DropDown
        AcquisitionTypeLabel            matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        MicroscopeSetup                 matlab.ui.control.DropDown
        MicroscopeSetupLabel            matlab.ui.control.Label
        CurrentOperationLabel           matlab.ui.control.Label
        System_status                   matlab.ui.control.Label
        Grid_size_status                matlab.ui.control.Label
        GridSizeLabel_2                 matlab.ui.control.Label
        Progress_Bar                    matlab.ui.control.UIAxes
        TabGroup2                       matlab.ui.container.TabGroup
        CameraTab                       matlab.ui.container.Tab
        CameraPropertiesPanel           matlab.ui.container.Panel
        TurnOffPreviewAfterAcqusitionCheckBox  matlab.ui.control.CheckBox
        PreviewDownsamplingEditField    matlab.ui.control.NumericEditField
        PreviewDownsamplingEditFieldLabel  matlab.ui.control.Label
        AutoContrastDropDown            matlab.ui.control.DropDown
        AutoContrastDropDownLabel       matlab.ui.control.Label
        FOVmmLabel                      matlab.ui.control.Label
        ResetCameraButton               matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        YOffsetEditField                matlab.ui.control.NumericEditField
        YOffsetEditFieldLabel           matlab.ui.control.Label
        XOffsetEditField                matlab.ui.control.NumericEditField
        XOffsetEditFieldLabel           matlab.ui.control.Label
        ResolutionLabel                 matlab.ui.control.Label
        X0Y0Label                       matlab.ui.control.Label
        ROIPositionpxLabel              matlab.ui.control.Label
        CameraBinningDropDown           matlab.ui.control.DropDown
        CameraBinningDropDownLabel      matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        HeightpxEditFieldLabel          matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        WidthpxEditFieldLabel           matlab.ui.control.Label
        MotorTab                        matlab.ui.container.Tab
        UnselectButton                  matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        LocationLabelEditField          matlab.ui.control.EditField
        LocationLabelEditFieldLabel     matlab.ui.control.Label
        SaveLocationButton              matlab.ui.control.Button
        SavedLocationsListBox           matlab.ui.control.ListBox
        SavedLocationsListBoxLabel      matlab.ui.control.Label
        LinearPolarizersPanel           matlab.ui.container.Panel
        DetectionPolarizerEditField     matlab.ui.control.NumericEditField
        DetectionPolarizerEditFieldLabel  matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        det_polarizer_lbl               matlab.ui.control.Label
        DetectionWaveplateEditField     matlab.ui.control.NumericEditField
        DetectionWaveplateEditFieldLabel  matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        det_waveplate_lbl               matlab.ui.control.Label
        Home_Rot_Motors                 matlab.ui.control.Button
        Rotate_ill_pol                  matlab.ui.control.Button
        IlluminationPolarizerEditField  matlab.ui.control.NumericEditField
        IlluminationPolarizerEditFieldLabel  matlab.ui.control.Label
        IlluminationWaveplateEditField  matlab.ui.control.NumericEditField
        IlluminationWaveplateEditFieldLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CurrentposLabel                 matlab.ui.control.Label
        ill_polarizer_lbl               matlab.ui.control.Label
        ill_waveplate_lbl               matlab.ui.control.Label
        StageControlPanel               matlab.ui.container.Panel
        HomeXYStageButton               matlab.ui.control.Button
        MoveZstage12ThicknessButton     matlab.ui.control.Button
        ResetXYStageButton              matlab.ui.control.Button
        MoveXYmotorButton               matlab.ui.control.Button
        y_coord_text                    matlab.ui.control.Label
        x_coord_text                    matlab.ui.control.Label
        text54_4                        matlab.ui.control.Label
        text54_3                        matlab.ui.control.Label
        text54_2                        matlab.ui.control.Label
        text54                          matlab.ui.control.Label
        y_coord_entry                   matlab.ui.control.EditField
        x_coord_entry                   matlab.ui.control.EditField
        SampleTab                       matlab.ui.container.Tab
        SampleParametersPanel           matlab.ui.container.Panel
        ZStackSlowCheckBox              matlab.ui.control.CheckBox
        qBRM_angles_text                matlab.ui.control.TextArea
        qBRMAngleValuesseperatedbyLabel  matlab.ui.control.Label
        SampleNotes                     matlab.ui.control.TextArea
        SampleNotestxtcreatedinFileTextAreaLabel  matlab.ui.control.Label
        SampleNameEditField             matlab.ui.control.EditField
        SampleNameEditFieldLabel        matlab.ui.control.Label
        ZStackStepSize                  matlab.ui.control.NumericEditField
        ZStackStepSizeLabel             matlab.ui.control.Label
        ZStackoffset                    matlab.ui.control.NumericEditField
        ZStackOffsetLabel               matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        SamplethicknessOffsetLabel      matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        SampleThickness                 matlab.ui.control.NumericEditField
        SampleThicknessEditFieldLabel   matlab.ui.control.Label
        GridTab                         matlab.ui.container.Tab
        SitchingParametersPanel         matlab.ui.container.Panel
        ROIImagingCheckBox              matlab.ui.control.CheckBox
        AddROIButton                    matlab.ui.control.Button
        create_img_sequence_folders     matlab.ui.control.CheckBox
        LoadGridButton                  matlab.ui.control.Button
        LoadAcquisitionButton           matlab.ui.control.Button
        TileOverlapEditField            matlab.ui.control.NumericEditField
        TileOverlapLabel                matlab.ui.control.Label
        ResetGridButton                 matlab.ui.control.Button
        SelectPositionButton            matlab.ui.control.Button
        UnSelectPositionButton          matlab.ui.control.Button
        msLabel_2                       matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        ExposureTime2                   matlab.ui.control.NumericEditField
        ExposureTime2EditFieldLabel     matlab.ui.control.Label
        ExposureTime1                   matlab.ui.control.NumericEditField
        ExposureTime1Label              matlab.ui.control.Label
        LR_Location                     matlab.ui.control.Label
        LRLabel                         matlab.ui.control.Label
        UL_Location_lbl                 matlab.ui.control.Label
        ULLabel                         matlab.ui.control.Label
        SetUpperLeftButton              matlab.ui.control.Button
        GridSelectionDropDown           matlab.ui.control.DropDown
        GridSelectionDropDownLabel      matlab.ui.control.Label
        StitchingType                   matlab.ui.control.DropDown
        StitchingTypeDropDownLabel      matlab.ui.control.Label
        Grid_yentry                     matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        Grid_xentry                     matlab.ui.control.NumericEditField
        GridSizeLabel                   matlab.ui.control.Label
        Grid_axis                       matlab.ui.control.UIAxes
        ROITab                          matlab.ui.container.Tab
        UnselectButton_3                matlab.ui.control.Button
        RemoveROIButton                 matlab.ui.control.Button
        SelectedROILabel                matlab.ui.control.Label
        SampleNotes_2                   matlab.ui.control.TextArea
        ROIInformationLabel             matlab.ui.control.Label
        ResetROIsButton                 matlab.ui.control.Button
        ROISelection                    matlab.ui.control.ListBox
        TimelapseTab                    matlab.ui.container.Tab
        Exposure_units_3                matlab.ui.control.DropDown
        Exposure_units_2                matlab.ui.control.DropDown
        qBRMStackExposureEditField      matlab.ui.control.NumericEditField
        qBRMStackExposureEditFieldLabel  matlab.ui.control.Label
        CCPStackExposureEditField       matlab.ui.control.NumericEditField
        CCPStackExposureEditFieldLabel  matlab.ui.control.Label
        NumberofFramestoAcquireEditField  matlab.ui.control.NumericEditField
        NumberofFramestoAcquireEditFieldLabel  matlab.ui.control.Label
        AcquireTimeLapseButton          matlab.ui.control.Button
        TimeLapseFrquencytimesperminEditField  matlab.ui.control.NumericEditField
        TimeLapseFrquencytimesperminEditFieldLabel  matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        SaveCalibrationOffsetButton     matlab.ui.control.Button
        CalibrationOffsetpolarizersEditField  matlab.ui.control.NumericEditField
        CalibrationOffsetpolarizersEditFieldLabel  matlab.ui.control.Label
        MotorInformationTab             matlab.ui.container.Tab
        RotationMotorsPanel_2           matlab.ui.container.Panel
        RotationMotorsPanel             matlab.ui.container.Panel
        MotorInfoListBox                matlab.ui.control.ListBox
        Motor_info_lbl                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        %%%%%%%%% Save location and Directory information %%%%%%%%%%%%%%%%%%
        save_folder_name = 'C:\Users\BMOadmin2\Documents\Images\Default';
        experimental_folder_main; % wherever yoi want to save the images to
       
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%% Stages %%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%% Rotational Stages %%%%%%%%%%%%%
        motor_properties; % Properties to store motor information in seperate file
        motor_struct = struct([]); % struct to store all rotational motor handles
        illumination_pol;
        illumination_waveplate;
        detection_waveplate;
        detection_pol;

        %%%%%%%%%%% XY Stage %%%%%%%%%%%%%%%
        motor_x; % handle for channel X on motor
        motor_y; % handle for channel Y on motor 
        warning_soundXYflag; % Flag for when teh stage goes to a No No area
        debug = 0; % debug for now only means dont home the XY stage (its already super accurate)

        % coordinates for the XY stage
        x_coord_motor;
        y_coord_motor;
        saved_locations;

        %%%%%%%%%%% Focusing Stage %%%%%%%%%%%%
        focus_stage; % Object for Focus Stage
        timer_handle_focus_stage; %%% Object for handling timer for stage position
        

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%% Acquisition Parameters %%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        grid_size_x = 2; % X Grid size for stitched Imaging
        grid_size_y = 2; % X Grid size for stitched Imaging
        x_grid_mesh; % Mesh used for creating the grid in the "grid_axis" in X
        y_grid_mesh; % Mesh Used for creating the grid for "grid_axis" in Y
        x_img_mesh; % Mesh used for holding the actual grid positions for an stitched image (X,Y)
        y_img_mesh; % Mesh used for holding the actual grid positions for an stitched image (X,Y)
        z_img_mesh; % Mesh Used for storing the Z values for a tiled acquisition
        
        % Metrics used to create mesh for stitched imaging
        FOV_microns_y; % FOV of the camera in microns (=pixel_size*num_pixels)
        FOV_microns_x; % FOV of the camera in microns (=pixel_size*num_pixels)
        FOV_microns_stitching_y; % FOV of the camera in microns (=pixel_size*num_pixels)
        FOV_microns_stitching_x; % the FOV minus the overlap used for stitching (tells you how far you should be moving for stitched images)
        
        % Parameters for setting a tiled acquisition
        current_grid_selection; % the current tile that is selected for teh grid axis
        gridtile_array; % handle for the grid array rectangle objects
        rect_handle; % handle all of the rectangles objects for the tile/grid plotting
        
        ULx = 55; % upper left x used for creating tiled acquisition (default 
        ULy = 37.5; % upper left y used for creating tiled acquisition
        mag_index = 1; % Index used to store what objective is currently being used (according to software)
        pixels_per_100_microns; % how many pixels are in 100 microns used to create tiling based on objective
        overlap_for_stitching = 0.1; % percent overlap for stitching (default = 10%)

        current_selected_point; % current grid position selected (used for setting tiled acquisition)
        grid_idx_selected = [0,0]; % the index of the position selected 
        grid_plane; % store all of the selected (X,Y,Z) coordinates for tiling. This will be used later to calculate the Z plane for the whole tiling grid
        grid_exists; % flag if we have created a grid to use for tiling
        acquisition_flag = false; % Flag for when we want to cancel an acquisiton

        % qBRM parameters
        qBRM_angles = [0 60 120]; % Angles for qBRM imaging (this makes it so you can get the 
        main_save_folder;

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%% Camera Parameters %%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        camera_preview; % object for camera preview to allow fro stopping it
        hImage; % handle to hold the image for teh live view (much faster than imshow)
        src; % handle the source and camera parameters 
        vid; %handle the video object
        cam_info; % handle all the camera info for GUI use
        image_size; % size of teh image (could change based on the ROI selected)
        camera_pixels = [2960,2960]; % number of pixels in teh camera
        pixel_size = 4.25;
        FOV_pixels; % Number of pixels inthe camera [y x]
        widefield_loaded; % check if a widefield image has been loaded (porbably can delete this when new code is written )
        camera_loaded; % flag to make sure camera is loaded
        histo; % Handle for the histogram for image viewing
        preview_downsample_val = 4;
        image_figure;
        Image_window;
        histoflag = 1; % flag that allows us to only update the histogram every two frames
        imaging_flag = false; % a flag to say when we are capturing data so we can stop the graphics from running
        timelapse_counter = 0; % counter that allows for taking time lapse images
        timelapse_handle;

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% Zoom functionality
        %%% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        zoom_mode; % are we in zoom mode? this happens when someone holds down teh ctrl button
        button_pressed; % when button is pressed we should draw the rectangle ROI
        original_position; % track the position of cursor when zooming on image
        zoom_patch_handle; % handle for the plotting rectangle for zoom mode 

        distance_measure_mode; % flag for measuring distance mode
        button_pressed_measure; % when button is pressed we should draw the rectangle ROI
        original_position_measure; %track position for measuring distance
        measure_patch_handle; % handle for the plotting rectangle for zoom mode 
        distance_measured; %keep track of what we just measured
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% Grid Acqusition
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        widefield_img; % image used to overaly grid or to do ROI imaging. 
        hImage2; %himage object for hold the widefield image for 
        grid_overlay_coordinates; % coordinates for the widefield grid imaging
        widefield_UL = []; % coordinates ffor the top left of image (x)
        selecting_grid_location_flag; % flag for whether we are selecting grid locations or not
        ROI_handle = {}; % cell array to handle all the ROI strcuturs into a cell
        ROI_counter = 1; % ROI counter 
        hImage3;
        widefield_patch_handle;
        tissue_ROI;
        grid_image_px_locationsx;
        grid_image_px_locationsy;
        marker_handle;
        tissue_polygon_mask;
        tissue_polygon_mask2;
        ROI_handler;

        %%% Handle ROI Imaging to store multiple ROIs at any resolution. 
        ROI_imaging_handle; % cell array that stores all relavent information for multiple ROIs 


        % parameters to store the patch coordinates for the ROI imaging
        grid_patchx;
        grid_patchy; 


        % add functionality for arduino control of peripheral devices
        arduinoSerial; % object for arduino serial Port


    end
    
    methods (Access = private)
        %% Status Updates
        function system_status_update(app, label)
            app.System_status.Text = label;
            drawnow;
        end
        
        function update_progressbar(app, value)
            rectangle(app.Progress_Bar,"FaceColor",'green','EdgeColor','green', 'Position',[0 0 value/100, 1]);
            app.Progress_Bar.Title.String = sprintf('%.0f%%',round(value));
            drawnow;
            if value ==0
                cla(app.Progress_Bar);
                app.Progress_Bar.Title.String = ' ';
            end
        end
        %% Save directory functions 
        function create_save_directory(app)
            filename = sprintf('%s_exp%d%s_mag%s',app.FilenameEditField.Value,app.ExposureTime.Value,app.Exposure_units.Value,app.MagnificationDropDown.Items{app.MagnificationDropDown.Value});
            app.experimental_folder_main = [app.save_folder_name, '/',filename];
            if ~exist([app.save_folder_name, '/',filename] ,'dir')
                mkdir([app.save_folder_name, '/',filename]);
            end
            cd([app.save_folder_name,'/', filename]);
            write_sample_description(app);
        end

        function write_sample_description(app)
            if ~isempty(app.SampleNotes.Value)
                fprintf(' Microscope Value %d',app.MicroscopeSetup.Value)
                fprintf(' type Value %d',app.AcquisitionType.Value)
                message = app.SampleNotes.Value;
                fileID = fopen('Sample_Notes.txt', 'w');
                fprintf(fileID, sprintf('Sample description: %s;  \nSample Name: %s; \nSample Thickness: %d; \n',message{1,1}, app.SampleNameEditField.Value, app.SampleThickness.Value));
                fprintf(fileID, sprintf(['Magnification: %s; \nExposure Time(s): %d (%d); \nExposure Units: %s; \nAcquisition Type: %s; \nMicroscopy Setup: %s; \n' ...
                    'Grid Size: %dx%d; \nOverlap: %5.2f; \nZ-Stack Step Size (microns): %d; \nPolarizer Settings (Ill Polarizer/Wavelplate)/(Det. Polarizer/Waveplate) (degrees): (%5.2f/%5.2f)/(%5.2f/%5.2f); \nCamera Width: (%dx%d)'], ...
                    app.MagnificationDropDown.Items{app.MagnificationDropDown.Value}, app.ExposureTime1.Value, app.ExposureTime2.Value, app.Exposure_units.Value, ...
                    app.AcquisitionType.Items{str2num(app.AcquisitionType.Value)}, app.MicroscopeSetup.Items{str2num(app.MicroscopeSetup.Value)}, app.Grid_xentry.Value, app.Grid_yentry.Value, app.TileOverlapEditField.Value, ...
                    app.ZStackStepSize.Value, app.IlluminationPolarizerEditField.Value, app.IlluminationWaveplateEditField.Value,app.DetectionPolarizerEditField.Value, ...
                    app.DetectionWaveplateEditField.Value, app.WidthEditField.Value, app.HeightEditField.Value));
                fclose(fileID);
            else
                disp('No sample Notes found')
            end
        end

        %% Motor Connecting 
        function update_motor_info(app, motor_number)
            values = app.motor_struct(motor_number).motor_handler.DEVICE_SPECS;
            motor_label = app.motor_struct(motor_number).motor_handler.motor_label;
            app.Motor_info_lbl.Text = sprintf(['Motor Label: %s \nDevice Address: %s \nDevice Type: %s \nSerial Num: %s \nMfg. Year: %s' ...
                ' \nFirmware: %s \nTravel (mm): %5.1f \nPulses/M.U.: %5.1f \nPulse/Deg: %5.1f \n'],motor_label, values.FULL_ADDRESS, values.devicetype, ...
                values.SerialNum,values.year, values.firmware, values.travel, values.pulsepermeasurementUnit, values.pulsesperDegree);

            update_motor_positions(app);
        end

        function update_motor_positions(app)
            %%% update all motor positions
            app.ill_polarizer_lbl.Text = sprintf('%5.4f°', get_rotational_position(app,app.illumination_pol));
            app.ill_waveplate_lbl.Text = sprintf('%5.4f°', get_rotational_position(app,app.illumination_waveplate));
            app.det_waveplate_lbl.Text = sprintf('%5.4f°', get_rotational_position(app,app.detection_waveplate));
            app.det_polarizer_lbl.Text = sprintf('%5.4f°', get_rotational_position(app,app.detection_pol));

            app.IlluminationPolarizerEditField.Value = get_rotational_position(app,app.illumination_pol);
            app.IlluminationWaveplateEditField.Value = get_rotational_position(app,app.illumination_waveplate);
            app.DetectionPolarizerEditField.Value = get_rotational_position(app,app.detection_pol);
            app.DetectionWaveplateEditField.Value = get_rotational_position(app,app.detection_waveplate);
        end

        function pos = get_rotational_position(app,motor_handle)
            pos = motor_handle.addressed_device.Position.ToDouble(motor_handle.addressed_device.Position)/motor_handle.DEVICE_SPECS.pulsesperDegree;
        end
        
        function focus_stage_obj = Focus_stage_init(app, COM_port)
            system_status_update(app,'Initializing Focusing Stage')
            update_progressbar(app,15);
            focus_stage_obj = MCM3001_motor(COM_port,0);
            
            %app.timer_handle_focus_stage = timer('Period',app.time_lapse_freq_min*60,'ExecutionMode','fixedSpacing','TasksToExecute',app.preview_counter,'BusyMode','drop');
            %app.timer_handle_focus_stage.TimerFcn = @app.timelapse_imaging;

            %app.timer_handle_focus_stage.TimerFcn = @app.get_MCM_position;
            update_progressbar(app,65);
            focus_stage_obj.query_position(0)
            update_progressbar(app,0);
            system_status_update(app,'Standby')

        end

        function connect_rotation_motors(app)
            try
                system_status_update(app, 'Loading Rotational Motors...');
                update_progressbar(app,10);
                address = rotation_motor.list_devices("COM4", '0','4');
                if length(address)<4
                    error('Error! Only %d Motors are connected. Make sure to check connections and Address of each motor',length(address))
                end
                for i = 1:length(address)
                    update_progressbar(app,i*25);
                    system_status_update(app, sprintf('Loading Rotational Motor #%d',i));
                    app.motor_struct(i).motor_handler = rotation_motor("COM4");
                    app.motor_struct(i).motor_handler.Connect(address{1,i});

                    label = app.motor_properties.motor_label{find(app.motor_properties.motor{1} == i)};
                    
                    app.motor_struct(i).motor_handler.motor_label = label;
                end
                    
                %%% need to add functionality to find label of motor and
                %%% selec that motor as the correct property
                app.illumination_pol = app.motor_struct(1).motor_handler;
                app.illumination_waveplate = app.motor_struct(2).motor_handler;
                app.detection_waveplate = app.motor_struct(3).motor_handler;
                app.detection_pol = app.motor_struct(4).motor_handler;

                Home_rotational_stages(app);
                
            catch ME
                errordlg(ME.message, 'ERROR','error')
            end
            system_status_update(app, 'StandBy');
            update_progressbar(app,0);
        end

        %% Motor Functionality 
        function get_MCM_position(app, ~, ~)
            [pos, danger] = check_obj_pos(app);
            app.Focus_MotorPosition.Text = sprintf('%5.2f um',pos);
            [x_coord, y_coord] = read_motor_position(app);
            check_stage_location(app,x_coord,y_coord, danger);
            app.x_coord_motor = x_coord;
            app.y_coord_motor = y_coord;
            app.Stage_pos.Text = sprintf('X: %5.2f Y: %5.2f',x_coord, y_coord);
        end
        
        function [x_coord, y_coord] = read_motor_position(app)
            % read position of the motor
            x_coord = System.Decimal.ToDouble(app.motor_x.Position);
            y_coord = System.Decimal.ToDouble(app.motor_y.Position);
        end

        function check_stage_location(app, x_coord,y_coord, danger)
            if (x_coord >110) || (x_coord < 1) || (y_coord < 10) || (y_coord > 67)
                if ~app.warning_soundXYflag
                    app.warning_soundXYflag = 1;
                    if danger
                        warning_sound(app);
                    end
                end
            else
                app.warning_soundXYflag = 0;
            end
            drawnow;
        end

        function [pos, danger] = check_obj_pos(app)
            pos = app.focus_stage.query_position(0);
            danger = 0;
%             if pos > 24500
%                 warning_sound(app);
%                 pause(0.5)
%                 %app.focus_stage.set_position(0,17000) % set stage to a safe location of 1000 microns above the 0 point
%                 %msgbox('Getting very high with that objective.... It will be repositioned to be safe')
%                 danger = 1;
%             elseif pos > 19000
%                 danger = 1;
%                 app.Lamp.Color = 'red';
%                 %uiwait(msgbox('Getting very high with that objective'))
%             elseif pos > 17000 && pos < 19000
%                 app.Lamp.Color = 'yellow';
%             elseif pos < 17000
%                 app.Lamp.Color = 'green';
%             end
%             drawnow;
        end
        
        function Home_rotational_stages(app)
            app.illumination_pol.Home();
            app.detection_pol.Home();
            app.illumination_waveplate.Home();
            app.detection_waveplate.Home();
            
            pause(1)
            Rotational_stages_positions(app, app.motor_properties.CCP_positions); % set the rotational stages for CCP when you home them
        end
        
        function Rotational_stages_positions(app, positions)
            app.illumination_pol.MoveAbsolute(positions(1));
            app.illumination_waveplate.MoveAbsolute(positions(2));
            app.detection_waveplate.MoveAbsolute(positions(3));
            app.detection_pol.MoveAbsolute(positions(4));

            update_motor_positions(app);
        end
        
        function moveXY(app, x_coord, y_coord)
            % moves XY stage to preset position
            moveto(app, app.motor_x, x_coord);
            moveto(app, app.motor_y, y_coord);
            % update motor position text
            [x_coord, y_coord] = read_motor_position(app);
            app.x_coord_text.Text = num2str(x_coord);
            app.y_coord_text.Text = num2str(y_coord);
        end

        function moveto(app, motor, position)
            try
                workDone=motor.InitializeWaitHandler(); % Initialise Waithandler for timeout
                motor.MoveTo(position, workDone);       % Move devce to position via .NET interface
                motor.Wait(100000);              % Wait for move to finish
            catch
                disp('Unable to Move Device. Device will be homed');
                motor.Home(100000);
            end
        end

        function Home_XY_Stage(app)
            system_status_update(app,'Homing XY Stage');
            current = app.focus_stage.query_position(0);
            app.focus_stage.set_position(0,current - 1500) % set stage to a safe location of 1000 microns above the 0 point
            app.motor_x.Home(60000);
            app.motor_y.Home(60000);
            pause(4)
            app.focus_stage.set_position(0,current - 1500) % set stage to a safe location of 1000 microns above the 0 point
            system_status_update(app,'StandBy');
        end

        function return_home_zposition(app, home_z)
            system_status_update(app,'Returning Home');
            app.focus_stage.set_position(0,home_z);
            pause(1)
            system_status_update(app,'StandBy');
        end

        function disconnect_XY_motor(app)
            try
                % Make sure to disconnect all of the motors (mainly the XY
                % stage)
                app.motor_x.StopPolling();
                app.motor_x.Disconnect();

                app.motor_y.StopPolling();
                app.motor_y.Disconnect();
            catch ME
                disp(ME.message)
            end
        end
        %% Imaging Parameter Functions
        function update_grid(app)
            cla(app.Grid_axis)
            app.rect_handle = gobjects(3,1);
            for i = 1:length(app.grid_idx_selected)-1
                app.rect_handle(i,1) = rectangle('Position', [(app.grid_idx_selected(i+1,1)-0.75), (app.grid_idx_selected(i+1,2)-0.75), 0.5, 0.5],...
                    'Parent', app.Grid_axis, ...
                    'EdgeColor', 'red', ...
                    'FaceColor', [0, 1, 0], ...
                    'Curvature',[0.5 0.5]);
            end
            
            app.current_selected_point = plot(app.Grid_axis,app.current_grid_selection(1)-0.5,app.current_grid_selection(2)-0.5,'r.', 'MarkerSize',16);
        end

        function reset_grid(app)
            x = app.Grid_xentry.Value;
            y = app.Grid_yentry.Value;

            app.Grid_axis.XTick = 0:1:x;
            app.Grid_axis.YTick = 0:1:y;

            app.Grid_axis.XLim = [0 x];
            app.Grid_axis.YLim = [0 y];
            app.current_grid_selection = [1,1];
            app.grid_idx_selected = [0,0];
            app.grid_plane = [];
            calc_tile_grid(app);
            app.SelectPositionButton.Enable = 'on';
            app.UnSelectPositionButton.Enable = 'off';
            
            cla(app.Grid_axis); % clear the grid axis figure
            app.grid_exists = 0; % we dont have a grid anymore

%             if app.widefield_loaded
%                 disp('here')
%                 overlay_grid_ROI(app);
%             end
        end

        % calc the tile grid for teh X,Y grid for tiled axquisitons and
        % such
        function calc_tile_grid(app)
            Generatearray = @(A,S,N) A+S*(0:N-1); % function used to create x and y vectors
            
            x = app.Grid_xentry.Value;
            y = app.Grid_yentry.Value;

            % create x and y vectors with the Upper left x and y values and
            % the step size (which is based on objective)
            x_max = app.ULx; % the actual position of the ROI in the Img space
            x_step = app.FOV_microns_stitching_x(app.MagnificationDropDown.Value)/1000;
            center_posx = Generatearray(x_max,-x_step,x);

            y_max = app.ULy;  % its actually the min value but its the top left of our matrix
            y_step = app.FOV_microns_stitching_y(app.MagnificationDropDown.Value)/1000;
            center_posy = Generatearray(y_max,y_step,y);

            % update the x and y mesh for imaging (in stage space)
            [app.x_img_mesh, app.y_img_mesh] = meshgrid(center_posx, center_posy);
            

        end

        % calculate a Z plane from a series of 3 (X Y and Z) points 
        function z_plane = calc_z_plane(app, x_mesh,y_mesh,grid_plane)
            P1 = grid_plane(:,:,2); % extract data points to define plane
            P2 = grid_plane(:,:,3);
            P3 = grid_plane(:,:,4);
            N = cross(P1-P2,P1-P3); % find normal vector
            N = N./norm(N); % normalaze the value
            A = N(1); B=N(2); C=N(3); % define coordinates of normal vector
            x0 = P1(1); y0 = P1(2); z0 = P1(3); % define coordinated of the origin
            z_plane = (-A.*(x_mesh-x0)-B.*(y_mesh-y0)+C.*z0)./C; % find z values with plane equation
            app.grid_exists = 1; % we have created a z plane mesh for tiling
            if (anynan(z_plane)) || any(isinf(z_plane), 'all')
                app.grid_exists = 0;
                z_plane = [];
            end
                
        end

        function update_FOV(app)
            app.pixels_per_100_microns = ((100.*app.motor_properties.Objectives)./app.motor_properties.pixel_size)';
            app.FOV_microns_y = 100*app.camera_pixels(2)./app.pixels_per_100_microns; % First column --> y
            app.FOV_microns_x = 100*app.camera_pixels(1)./app.pixels_per_100_microns; % Second column --> x
            app.FOV_microns_stitching_y = app.FOV_microns_y.*(1 - app.overlap_for_stitching);
            app.FOV_microns_stitching_x = app.FOV_microns_x.*(1 - app.overlap_for_stitching);
        end

%% Imaging Functions
        % Single Images
        function images = qBRM_single_image(app, filename, FF, varargin)
            
            %qBRM_point_degree = 0:app.qBRM_step.value
            images = zeros(app.camera_pixels(1),app.camera_pixels(2),length(app.qBRM_angles));
            
            orig_pos_pol = get_rotational_position(app,app.illumination_pol);
            orig_pos_wave = get_rotational_position(app,app.illumination_waveplate);
            %orig_det_pos_pol = get_rotational_position(app,app.detection_pol);
            %orig_det_pos_wave = get_rotational_position(app,app.detection_waveplate);
            for step = 1:length(app.qBRM_angles)
                step_size_pol = orig_pos_pol + app.qBRM_angles(step);
                step_size_wave = orig_pos_wave + app.qBRM_angles(step);
                if step_size_wave >=360
                    step_size_wave = step_size_wave - 360;
                end
                if step_size_pol >=360
                    step_size_pol = step_size_pol - 360;
                end
                app.illumination_pol.MoveAbsolute(step_size_pol);
                app.illumination_waveplate.MoveAbsolute(step_size_wave);
                %app.detection_pol.MoveAbsolute(orig_det_pos_pol + app.qBRM_angles(step));
                %app.detection_waveplate.MoveAbsolute(orig_det_pos_wave +  app.qBRM_angles(step));
                pause(0.35)
                images(:,:,step) = image_capture(app);
                update_progressbar(app, 100*(step/length(app.qBRM_angles)));
            end
            %reset polarizers to qBRM positions
            Rotational_stages_positions(app, app.motor_properties.qBRM_positions)
            update_progressbar(app, 0);

            if app.FF_correction.Value
                if FF
                    [~] = acquire_FF_img(app,1);
                end
            end
        end
        
        function save_qBRM(app, images, filename)
            % only save if filename is provided
            if ~isempty(filename)
                if size(images,3) ~=3
                    images = uint16(images);
                    angles = app.qBRM_angles;
                    system_status_update(app,'Saving Z stack as .mat file (not three points)');
                    save([filename,'.mat'], "images", "angles", '-v7.3')

                else
                    rgb_img = images;
                    if app.Saveas8bit.Value
                        rgb_img = rgb_img/256; %conver tot 8 bit scale
                        imwrite(uint8(rgb_img),[filename, '.tif'])
                    else
                        imwrite(uint16(rgb_img),[filename, '.tif'])
                    end
                end
            end
        end

        function img = single_image(app, filename)
            update_progressbar(app, 10);
            app.System_status.Text = 'Acquiring Image';
            update_progressbar(app, 50);
            img = image_capture(app);
            if app.Saveas8bit.Value
                img = img/256; %conver tot 8 bit scale
                imwrite(uint8(img),[filename, '.tif'])
            else
                imwrite(uint16(img),[filename, '.tif'])
            end
            update_progressbar(app, 0);
            app.System_status.Text = 'StandBy';
        end

        function [CCP, qBRM] = single_CCP_qBRM(app, filename)
            % acquire CCP
            CCP = single_image(app, filename);
            
            % acquire qBRM
            qBRM = qBRM_single_image(app, filename, app.FF_correction.Value);
        end

        function zstack_CCP_qBRM(app,starting_position, num_planes, step_size,filename)
            % Get current position in Z then do steps of certain size
            current = app.focus_stage.query_position(0);
            qBRM_point_degree = [0, 30, 90];
            img = zeros(app.camera_pixels(1),app.camera_pixels(2),num_planes,length(app.qBRM_angles)); % initialize a img var to hold images for z stack
            orig_pos_pol = get_rotational_position(app,app.illumination_pol);
            orig_pos_wave = get_rotational_position(app,app.illumination_waveplate);
            for step = 1:length(app.qBRM_angles)+1
                if step ==1 % first step make sure to take a CCP z stack
                    Rotational_stages_positions(app, app.motor_properties.CCP_positions); % set the rotational stages for CCP when you home them
                    pause(0.75)
                    type = 'CCP';
                else
                    app.illumination_pol.MoveAbsolute(orig_pos_pol + app.qBRM_angles(step-1));
                    app.illumination_waveplate.MoveAbsolute(orig_pos_wave + app.qBRM_angles(step-1));
                    type = sprintf('qBRM (%d°)', app.qBRM_angles(step-1),step);
                    pause(0.75)
                end

                for i = 1:num_planes
                    system_status_update(app,'Acquring %s  Z stack (%d/%d)',type,i,num_planes);
                    app.focus_stage.set_position(0,starting_position+i*step_size);
                    pause(0.1);
                    img(:,:,i,step) = image_capture(app);  % save it as a 4D matrix then we can just save it as a RGB z stack
                end
                update_progressbar(app, 100*(step/length(app.qBRM_angles)));
            end
            system_status_update(app,'Saving CCP Z-stack');

            % save CCP as the first position acquired
            %CCP_img = reshape(img(:,:,:,1), [size(img,1),size(img,1),size(img,3)]);
            write_zstack(app,num_planes,img(:,:,:,1),[filename, '.tif'])

            % save Z-stack 
            system_status_update(app,'Saving qBRM Z-stack');
            if size(img(:,:,:,2:end),4) ~=3 
                img = uint16(img);
                angles = app.qBRM_angles;
                system_status_update(app,'Saving Z stack as .mat file (not three points)');
                save([filename,'.mat'], "img", "angles", '-v7.3')
            else
                % save the z stack (as long as we have three points)
                for i = 1:num_planes
                    rgb_img = cat(3,img(:,:,i,1),img(:,:,i,2),img(:,:,i,3));
                    imwrite(uint16(rgb_img),[filename,'.tif'],'tif','WriteMode','append')
                end
            end

            % return to starting position 
            return_home_zposition(app, current);
        end
        
        function stitched_CCP_qBRM(app, x_mesh,y_mesh,z_planes)
            y = 0;
            counter = 1;
            
            for y_idx = 1:size(x_mesh,1)
                y = y + 1;
                for x_idx = 1:size(x_mesh,2)
                
                    if app.acquisition_flag
                        cancel_acquisition(app);
                        return
                    end
                    magnification = app.MagnificationDropDown.Value;
                    save('grid_coordinates.mat','x_mesh','y_mesh','z_planes','counter')
                    if(mod(y,2) == 1)
                        % For odd number rows, moving left to right
                        x = x_idx;
                    else
                        % For even number rows, moving right to left
                        x = size(x_mesh,2) - x_idx + 1;
                    end

                    %now actually move the motor and then
                    %take a z stack (wait a sec)
                    moveXY(app,x_mesh(y,x), y_mesh(y,x));
                    pause(0.25)

                    filename = sprintf('img_%03d.tif',counter);
                    counter = counter +1;
                    if app.StitchingType.Value ==1
                        [~] = single_image(app,filename);
                    else
                        CCP_zstack(app, z_planes(y,x), app.SampleThickness.Value/app.ZStackStepSize.Value, app.ZStackStepSize.Value, filename);
                    end                   
                end
            end
        end

        % Z-stack Imaging Functions
        function qBRM_zstack(app,starting_position, num_planes, step_size,filename, ff_correction)
            % Get current position in Z then do steps of certain size
            Rotational_stages_positions(app, app.motor_properties.qBRM_positions); % set the rotational stages for qBRM when you home them
            starting_position = starting_position - num_planes*step_size/2;
            current = app.focus_stage.query_position(0);
            img = zeros(app.camera_pixels(1),app.camera_pixels(2),length(app.qBRM_angles),num_planes, 'uint16'); % initialize a img var to hold images for z stack
            orig_pos_pol = get_rotational_position(app,app.illumination_pol);
            orig_pos_wave = get_rotational_position(app,app.illumination_waveplate);
            

            %%% Change code to handle Slow z-stacking for more accuracy
            if app.ZStackSlowCheckBox.Value
                
                for i = 1:num_planes
                    if app.acquisition_flag
                        cancel_acquisition(app);
                        break
                    end
                    app.focus_stage.set_position(0,starting_position+i*step_size);
                    pause(0.4);
                    for step = 1:length(app.qBRM_angles)
                        % handle situtation where motor tries to go above 360
                        step_size_pol = orig_pos_pol + app.qBRM_angles(step);
                        step_size_wave = orig_pos_wave + app.qBRM_angles(step);
                        if step_size_wave >360
                            step_size_wave = step_size_wave - 360;
                        end
                        if step_size_pol >360
                            step_size_pol = step_size_pol - 360;
                        end

                        app.illumination_pol.MoveAbsolute(step_size_pol);
                        app.illumination_waveplate.MoveAbsolute(step_size_wave);
                        
                        pause(1)
                    img(:,:,step,i) = image_capture(app);  % save it as a 4D matrix then we can just save it as a RGB z stack
                    system_status_update(app,sprintf('Acquiring Z stack %d/%d',i,num_planes));
                    end

                update_progressbar(app, 100*(step/length(app.qBRM_angles)));
                end

            else

                for step = 1:length(app.qBRM_angles)
                    % handle situtation where motor tries to go above 360
                    step_size_pol = orig_pos_pol + app.qBRM_angles(step);
                    step_size_wave = orig_pos_wave + app.qBRM_angles(step);
                    if step_size_wave >360
                        step_size_wave = step_size_wave - 360;
                    end
                    if step_size_pol >360
                        step_size_pol = step_size_pol - 360;
                    end

                    app.illumination_pol.MoveAbsolute(step_size_pol);
                    app.illumination_waveplate.MoveAbsolute(step_size_wave);
                    app.focus_stage.set_position(0,starting_position);
                    pause(1)

                    for i = 1:num_planes
                        if app.acquisition_flag
                            cancel_acquisition(app);
                            break
                        end
                        app.focus_stage.set_position(0,starting_position+i*step_size);
                        pause(0.1);
                        img(:,:,step,i) = image_capture(app);  % save it as a 4D matrix then we can just save it as a RGB z stack
                        system_status_update(app,sprintf('Acquiring Z stack %d/%d',i,num_planes));
                    end

                    app.focus_stage.set_position(0,starting_position); % return to starting position
                    update_progressbar(app, 100*(step/length(app.qBRM_angles)));
                end

            end


            %%%%%% Changed this code to handle slow Z-stack qBRM for more
            %%%%%% accuracye measurements
            
            app.focus_stage.set_position(0,starting_position);
            
            system_status_update(app,'Saving qBRM Z-stack');
            if size(img,3) ~=3 
                images = uint16(img);
                angles = app.qBRM_angles;
                system_status_update(app,'Saving Z stack as .mat file (not three points)');
                save([filename,'.mat'], "images", "angles")
            else
                if app.Saveas8bit.Value
                    img = img/256; %converty 16 bit to 8 bit scale
                    img = uint8(img);
                else
                    img = uint16(img);
                end
                % save the z stack (as long as we have three points)
                write_RGB_stack(img, [filename,'.tif'])
%                 for i = 1:num_planes
%                     rgb_img = cat(3,img(:,:,i,1),img(:,:,i,2),img(:,:,i,3));
%                     
%                     imwrite(uint16(rgb_img),[filename,'.tif'],'WriteMode','append', 'ColorSpace','rgb')
%                 end
            end
            Rotational_stages_positions(app, app.motor_properties.qBRM_positions); % set the rotational stages for CCP when you home them
            return_home_zposition(app,current);


             if ff_correction
                [~] = acquire_FF_img(app,1);
            end
            
        end

        function CCP_zstack(app, starting_position, num_planes, step_size,filename)
            orig_position = starting_position;
            starting_position = starting_position - num_planes*step_size/2;
            app.focus_stage.set_position(0,starting_position);
            img = zeros(app.camera_pixels(1),app.camera_pixels(2),num_planes); % initialize a img var to hold images for z stack
            app.focus_stage.set_position(0,starting_position);
            pause(0.75)
            % Get current position in Z then do steps of certain size
            for i = 1:num_planes
                if app.acquisition_flag
                    cancel_acquisition(app);
                    break
                end
                app.focus_stage.set_position(0,starting_position+i*step_size);
                pause(0.055)
                img(:,:,i) = image_capture(app);
                system_status_update(app,sprintf('Acquiring Z stack %d/%d',i,num_planes));
            end

            app.focus_stage.set_position(0,orig_position);
            write_zstack(app, num_planes,img,[filename, '.tif'])
        end

        function write_zstack(app, num_planes,img,filename)
            disp(filename)
            if app.Saveas8bit.Value
                img = img/256; %converty 16 bit to 8 bit scale
                saveastiff(uint8(img), filename);
            else
                saveastiff(uint16(img), filename);
            end
            
%             for i = 1:num_planes
%                 imwrite(uint16(img(:,:,i)),filename,'WriteMode','append')
%             end
        end

        % Stitched acquisition 
        function stitched_CCP(app, x_mesh,y_mesh,z_planes)
            y = 0;
            counter = 1;
            
            for y_idx = 1:size(x_mesh,1)
                y = y + 1;
                for x_idx = 1:size(x_mesh,2)
                
                    if app.acquisition_flag
                        cancel_acquisition(app);
                        break
                    end
                    
                    save('grid_coordinates.mat','x_mesh','y_mesh','z_planes','counter')
                    try
                        grid_patch_x = app.grid_patchx;
                        grid_patch_y = app.grid_patchy;
                        save('patch_coordinates.mat', 'grid_patch_x', 'grid_patch_y')
                    catch
                        disp('couldnt save the patch coordiantes')
                    end

                    if(mod(y,2) == 1)
                        % For odd number rows, moving left to right
                        x = x_idx;
                    else
                        % For even number rows, moving right to left
                        x = size(x_mesh,2) - x_idx + 1;
                    end
                    
                    dont_take = 0;
                    try 
                        if (app.tissue_polygon_mask(y,x)) && (~isempty(app.tissue_polygon_mask)) && (app.ExlcudeImagesoutsideROICheckBox.Value)
                            fprintf('Take images at index (%d,%d) AKA Coutner: %d\n', y,x, counter)
                            
                        else
                            dont_take = 1;
                            fprintf('Dont Take images at index (%d,%d) AKA Coutner: %d\n', y,x, counter)
                        end
                    catch
                        disp('didnt work')
                    end
                    
                    %now actually move the motor and then
                    %take a z stack (wait a sec)
                    moveXY(app,x_mesh(y,x), y_mesh(y,x));
                    pause(0.5)

                    filename = sprintf('img_%03d',counter);
                    counter = counter +1;
                    if app.StitchingType.Value ==1
                        app.focus_stage.set_position(0,z_planes(y,x));
                        pause(0.1)
                        %pause(2)
                        [~] = single_image(app,filename);
                    else
                        if ~dont_take
                            CCP_zstack(app, z_planes(y,x), app.SampleThickness.Value/app.ZStackStepSize.Value, app.ZStackStepSize.Value, filename);
                        end
                    end
                    update_progressbar(app, (counter/(size(x_mesh,1)*size(x_mesh,2))));
                    app.image_progresslbl.Text = sprintf('Image: %d/%d',counter,size(x_mesh,1)*size(x_mesh,2));
                end
            end
        end
        
        function stitched_qBRM(app, x_mesh,y_mesh,z_planes)
            run_10X_fast = 0;
            angular_val = 1; % 1 --> normal qBRM, rotating 3 angles per tile
            if (app.MagnificationDropDown.Value ==1) || (run_10X_fast)
                angular_val = 3; % 3 --> fast qBRM, acquiring all tiles at 1 angle (x3)
                widefield_imgset = zeros([app.camera_pixels(1),app.camera_pixels(1),3,size(x_mesh,1)*size(x_mesh,2)], 'uint16');
% widefield_imgset = zeros([app.camera_pixels(1),app.camera_pixels(1),3,size(x_mesh,1)*size(x_mesh,2)], 'uint8'); % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~ Added by Nate March 26th for one acquisition 
            end


            Rotational_stages_positions(app, app.motor_properties.qBRM_positions);
            pause(0.25)
            orig_pos_pol = get_rotational_position(app,app.illumination_pol);
            orig_pos_wave = get_rotational_position(app,app.illumination_waveplate);
            
            reference_img = take_notissue_reference(app);
            contrast = fmeasure(reference_img, 'BREN');

            for i = 1:angular_val
                
                % if 4X image make sure to do each 
                if (app.MagnificationDropDown.Value ==1) || (run_10X_fast)
                    app.illumination_pol.MoveAbsolute(orig_pos_pol + app.qBRM_angles(i));
                    app.illumination_waveplate.MoveAbsolute(orig_pos_wave + app.qBRM_angles(i));
                end
                
                try
                    grid_patch_x = app.grid_patchx;
                    grid_patch_y = app.grid_patchy;
                    save('patch_coordinates.mat', 'grid_patch_x', 'grid_patch_y')
                catch
                    disp('couldnt save the patch coordiantes')
                end
                
                % Do the XY scanning 
                y = 0;
                counter = 1;
                tic; % Start timing the entire loop
                for y_idx = 1:size(x_mesh,1)
                    y = y + 1;
                    for x_idx = 1:size(x_mesh,2)
                        if counter == 1
                            starttime = tic;
                        end

                        if app.acquisition_flag
                            cancel_acquisition(app);
                            break
                        end
                        save('grid_coordinates.mat','x_mesh','y_mesh','z_planes','counter')

                        if(mod(y,2) == 1)
                            % For odd number rows, moving left to right
                            x = x_idx;
                        else
                            % For even number rows, moving right to left
                            x = size(x_mesh,2) - x_idx + 1;
                        end
                        
                        dont_take = 0;
                        list_to_save = zeros(size(app.tissue_polygon_mask));
                        try
                            if (app.tissue_polygon_mask(y,x)) && (~isempty(app.tissue_polygon_mask)) && (app.ExlcudeImagesoutsideROICheckBox.Value)
                                fprintf('Take images at index (%d,%d) AKA Coutner: %d\n', y,x, counter)
                                list_to_save(y,x) = counter;
                            else
                                dont_take = 1;
                                fprintf('Dont Take images at index (%d,%d) AKA Coutner: %d\n', y,x, counter)
                            end
                        catch
                            disp('didnt work')
                        end

                        %now actually move the motor and then
                        %take a z stack
                        moveXY(app,x_mesh(y,x), y_mesh(y,x));
                        
%                         test_img = uint16(image_capture(app));
%                         contrast1 = fmeasure(test_img, 'BREN');
%                         percent_diff = abs(contrast1-contrast)/contrast; % x2 and x1 are your input variables. x1 is reference and x2 the value to compare
%                         ssim_diff = ssim(reference_img,test_img);
%                         pnsr_diff = psnr(reference_img,test_img);
%                         immse_diff = immse(reference_img,test_img);
%                         fprintf('Percent Difference (%5.2f-%5.2f): %5.2f \n', contrast, contrast1, percent_diff)
%                         fprintf('Percent pnsr Difference: %5.2f \n', pnsr_diff)
%                         fprintf('Percent immse Difference: %5.2f \n', immse_diff)
%                         fprintf('Percent immse Difference: %5.2f \n', ssim_diff)

                        filename = sprintf('img_%03d',counter);
                        app.focus_stage.set_position(0,z_planes(y,x));
                        pause(0.5)

                        
                        if (app.MagnificationDropDown.Value ==1) || (run_10X_fast) % this comment will get rid of the 10X "Fast" imaging
                            widefield_imgset(:,:,i,counter) = uint16(image_capture(app));
                            %pause(1)
%                             img_temp = uint16(image_capture(app));                                             % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~ Added by Nate March 26th for one acquisition 
%                             widefield_imgset(:,:,i,counter) = uint8(img_temp./(2^8));                          % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~ Added by Nate March 26th for one acquisition 
                            
                            %img = qBRM_single_image(app, filename,0,1);
                            %save_qBRM(app, img, sprintf('img_%03d',counter));
                        else
                            %widefield_imgset(:,:,i,counter) = [];
                            if app.StitchingType.Value ==1
                                img = qBRM_single_image(app, filename, 0);
                                
                                save_qBRM(app, img, filename);
                                

                            elseif app.StitchingType.Value ~=1
                                if ~dont_take
                                    qBRM_zstack(app, z_planes(y,x), app.SampleThickness.Value/app.ZStackStepSize.Value, app.ZStackStepSize.Value, filename,0);
                                end
                            end
                        end
                        
                         % Timing logic
                            if counter == 1
                                timePerIteration = toc; % Time for the first iteration
                            else
                                timePerIteration = toc / counter; % Average time per iteration
                            end
                            estimatedTimeRemaining = timePerIteration * (size(x_mesh,1)*size(x_mesh,2)- counter);
                        
                            % Display progress and estimated time remaining
                            fprintf('Iteration %d/%d. Estimated time remaining: %.2f seconds.\n', ...
                                counter, size(x_mesh,1)*size(x_mesh,2), estimatedTimeRemaining);

                        counter = counter +1;
                        
                        % set our polarizers to the correct positions before
                        % our next tile
                        if app.StitchingType.Value ~=1
                            Rotational_stages_positions(app, app.motor_properties.qBRM_positions);
                        end
                        app.image_progresslbl.Text = sprintf('Image: %d/%d',counter,size(x_mesh,1)*size(x_mesh,2));
                        update_progressbar(app, (counter/(size(x_mesh,1)*size(x_mesh,2))));
                    end
                end
            end
            save('grid_coordinates_images_taken_idx.mat','list_to_save')

            if app.FF_correction.Value
                Rotational_stages_positions(app, app.motor_properties.qBRM_positions);
                FF = acquire_FF_img(app,1);
            end

            % get back into experiment folder
            cd(app.experimental_folder_main)
            

            %%%%%%%% Perform qBRM right away if we click the check box %%%%%%%
            if app.AutosolveqBRMCheckBox.Value
                main_folder = pwd;
                % if we did widefield save it a particular way
                if (app.MagnificationDropDown.Value ==1) || (app.MagnificationDropDown.Value ==2)
                    mkdir('qBRM')
                    cd('qBRM')
                    mkdir('Ret')
                    mkdir('RGB_norm')
                    mkdir('phi')
                    mkdir('trans')
                    % write Widefield Images
                    for idx =1:size(x_mesh,1)*size(x_mesh,2)
                        cd(main_folder)
                        current_img = widefield_imgset(:,:,:,idx);
                        imwrite(current_img, sprintf('img_%03d.tif', idx));
                        ff_corr = double(FF) - mean(FF,3);
                        corrected_imgset = double(current_img)-ff_corr;
                        cd('qBRM')
                        % perform mini batches to solve for the qBRM images with GPU (not enouhg memory to do them all at once
                        % but we dont save I0. This could change
                        [I0, phi, A, RGB_norm] = analytical_qBRM_gpu_all(double(corrected_imgset));
                        cd('RGB_norm')
                        imwrite(uint8(255*RGB_norm),sprintf('RGB_norm_%03d.tif',idx));
                        cd(main_folder)
                        cd('qBRM')
                        cd('phi')
                        %imwrite(single(phi),sprintf('phi/phi_%03d.tif',i));
                        saveastiff(single(phi),sprintf('phi_%03d.tif',idx));
                        cd(main_folder)
                        cd('qBRM')
                        cd('Ret')
                        saveastiff(single(A),sprintf('ret_%03d.tif',idx));
                        cd(main_folder)
                        cd('qBRM')
                        cd('trans')
                        imwrite(uint16(((2^16)-1)*mat2gray(I0)),sprintf('transmittance_%03d.tif',idx));
                    end
                end
            
            %%% Comment out if doesnt work %%%%
            else
                if (app.MagnificationDropDown.Value ==1) || (run_10X_fast)
                    main_folder = pwd;
                    % if we did widefield save it a particular way
                    for idx =1:size(x_mesh,1)*size(x_mesh,2)
                        cd(main_folder)
                        current_img = widefield_imgset(:,:,:,idx);
                        imwrite(current_img, sprintf('img_%03d.tif', idx));
                    end
                end
            %%% Comment out if doesnt work %%%%
            
            end


%                 %perform flatfield correction and then solve and save ret, phi
%                 %etc.
%                 system_status_update(app, 'Solving qBRM data')
%                 ff_corr = double(FF) - mean(FF,3);
%                 corrected_imgset = double(widefield_imgset)-ff_corr;
% 
%                 % perform mini batches to solve for the qBRM images with GPU (not enouhg memory to do them all at once
%                 % but we dont save I0. This could change
%                 [~, ret, phi, RGB_norm] = qBRM_solve_minibatch_gpu(10, double(widefield_imgset),ff_corr); 
%                 
%                 mkdir('qBRM')
%                 cd('qBRM\')
%                 mkdir('Ret')
%                 mkdir('RGB_norm')
%                 mkdir('phi')
%                 fprintf(1,'Saving qBRM Progress: %3d%%\n',0);
%                 for idx = 1:size(corrected_imgset,4)
%                     cd(app.experimental_folder_main)
%                     cd('qBRM\')
%                     imwrite(RGB_norm(:,:,:,idx),sprintf('RGB_norm/RGB_norm_%03d.tif',idx));
%                     saveastiff(single(phi(:,:,:,idx)),sprintf('phi/phi_%03d.tif',idx));
%                     cd('Ret')
%                     saveastiff(single(ret(:,:,:,idx)),sprintf('ret_%03d.tif',idx));
%                     prog = ( 100*(idx/size(corrected_imgset,4)));
%                     fprintf(1,'\b\b\b\b%3.0f%%',prog);
%                 end
        end
        %% Miscellaneous Functions
        function warning_sound(app)
            WarnWave = [sin(1:.6:1000), sin(1:.7:1000), sin(1:.4:1000)];
            %sound(WarnWave,22050);
        end

        function disable_GUI(app)
            disp('need to disable all the buttons and stuff (except the cancel button)')
        end

        function enable_GUI(app)
            disp('need to re-enable all the buttons')
        end
        %% Camera Functions
        function initialize_camera(app)
            system_status_update(app,'Initializing Camera');
            % load camera and motor last
            imaqreset;
            %imaqmex('feature', '-slowpreview', true); % supposedly makes the preview faster
            app.cam_info = imaqhwinfo;
            
            app.vid = videoinput('pmimaq_2022b', 1, '0-CMOS, 0-500MHz, 1-HDR, 16-Bit');
            app.vid.FramesPerTrigger = 1;
            load_camera_default(app);
        end
        
        function load_camera_default(app)
            try
                %%%% can use manual trigger mode to greatly increase frame
                %%% rate for the capture of single images
                img = image_capture(app); % take an image to get the size of the sensor
                app.image_size = app.vid.ROIPosition/2;
                app.camera_pixels = size(img);
                %app.camera_pixels = [2960, 2960];

                app.hImage = image(app.Image_axis, zeros(round(app.camera_pixels(1)/app.preview_downsample_val),round(app.camera_pixels(2)/app.preview_downsample_val),1), 'HitTest','off');
                pbaspect(app.Image_axis, [round(app.camera_pixels(1)/app.preview_downsample_val),round(app.camera_pixels(2)/app.preview_downsample_val),1])
                app.Image_axis.XLim = [0,round(app.camera_pixels(1)/app.preview_downsample_val)];
                app.Image_axis.YLim = [0,round(app.camera_pixels(2)/app.preview_downsample_val)];
                app.Image_axis.XTick = [];
                app.Image_axis.YTick = [];
                app.vid.FrameGrabInterval = 1;
                app.src = getselectedsource(app.vid);
                app.src.AutoContrastMax = 65535;
                app.src.AutoContrastMin = 0;
                app.src.AutoContrast = 'OFF';
                app.src.Exposure = app.ExposureTime.Value;
                

                % also create hImage for the zoomed in preview
                app.hImage3 = image(app.Image_axis_3, zeros(round(app.camera_pixels(1)/app.preview_downsample_val),round(app.camera_pixels(2)/app.preview_downsample_val),1), 'HitTest','off');
                pbaspect(app.Image_axis_3, [round(app.camera_pixels(1)/app.preview_downsample_val),round(app.camera_pixels(2)/app.preview_downsample_val),1])
                app.Image_axis_3.XLim = [0,round(app.camera_pixels(1)/app.preview_downsample_val)];
                app.Image_axis_3.YLim = [0,round(app.camera_pixels(2)/app.preview_downsample_val)];
                app.Image_axis_3.XTick = [];
                app.Image_axis_3.YTick = [];

                %Preview the images
                Camera_preview(app, app.hImage);
            catch ME
                disconnect_XY_motor(app);
                errorMessage = sprintf('Error in function %s() at line %d.\n\nError Message:\n%s', ...
                ME.stack(1).name, ME.stack(1).line, ME.message);
                uiwait(warndlg(errorMessage));
            end
        end
        
        function Camera_preview(app, hImage)
            app.ResolutionLabel.Text = sprintf('Resolution: %dx%d',app.camera_pixels(1),app.camera_pixels(2));
            app.histo = histogram(randn(0,1000)*256,0:2:255,'Parent', app.Histogram_axis, 'EdgeColor', 'black', 'FaceColor', 'black', 'Normalization','probability');
            
            app.camera_preview = preview(app.vid, hImage);
            setappdata(hImage,'UpdatePreviewWindowFcn',@app.mypreview_fcn);
            app.widefield_loaded = 0;
            app.camera_loaded = 1;
        end

        function mypreview_fcn(app,~,event,himage)
            % Set the value of the text label.
            app.FPSLabel.Text = event.FrameRate;
            % Display the current image frame.
            
            if ~app.imaging_flag % make sure we arent capturing data
                set(himage, 'CData', flipud(event.Data(1:app.preview_downsample_val:end,1:app.preview_downsample_val:end)));
                app.histoflag = app.histoflag + 1;
                
                if app.histoflag > 3
                    app.histo.Data = event.Data(1:app.preview_downsample_val:end,1:app.preview_downsample_val:end);
                    app.histoflag = 1;
                    get_MCM_position(app);
                end
            end

        end      
        
        function cancel_acquisition(app)
            app.acquisition_flag = false;
            update_progressbar(app,0);
            system_status_update(app,'StandBy')
            uiwait(msgbox('Acqusition Canceled', 'Operation Message', "warn"), 5)
        end

        
        function basic_image_correction(app, image_dir)
            system_status_update(app,'BaSiC Correction...');
            BaSIC_correction(image_dir,0)
        end
        
        function img = image_capture(app, varargin)
            number_frames = 1;
            number_frames = app.FrameAveragingEditField.Value; % comment out if this is giving an error
            if nargin >2
                number_frames = varargin{1};
                if varargin{1} >10
                    number_frames = 10;
                end
            end
            
            img_tmp = zeros(2960,2960,number_frames);
            for i =1:number_frames
                img_tmp(:,:,i) = getsnapshot(app.vid);  
            end

            img_tmp = mean(img_tmp,3);
            %take the average of all teh frames
            
            img = flipud(img_tmp);
            img = uint16(img);
            % select whether you want a 8 bit or 16 bit image
            % need to add code for this
        end
       
        
        function rgb_img = acquire_FF_img(app, sample_img)
            system_status_update(app,'Acquiring qBRM Flatfield (No Sample)');
            [current_x, current_y] = read_motor_position(app);
            start = app.focus_stage.query_position(0);
            app.focus_stage.set_position(0,start - 1500);
            pause(1)
            moveXY(app, 55,27.5);
            x = sample_img;
            app.focus_stage.set_position(0,start);
            
            % actaully acquire the FF image
            %qBRM_point_degree = 0:app.qBRM_step.value
            images = zeros(app.camera_pixels(1),app.camera_pixels(2),length(app.qBRM_angles));
            
            orig_pos_pol = get_rotational_position(app,app.illumination_pol);
            orig_pos_wave = get_rotational_position(app,app.illumination_waveplate);

            for step = 1:length(app.qBRM_angles)
                step_size_pol = orig_pos_pol + app.qBRM_angles(step);
                step_size_wave = orig_pos_wave + app.qBRM_angles(step);
                if step_size_wave >=360
                    step_size_wave = step_size_wave - 360;
                end
                if step_size_pol >=360
                    step_size_pol = step_size_pol - 360;
                end
                app.illumination_pol.MoveAbsolute(step_size_pol);
                app.illumination_waveplate.MoveAbsolute(step_size_wave);
                pause(0.35)
                images(:,:,step) = image_capture(app,app.FFFramesEditField.Value);
                update_progressbar(app, 100*(step/length(app.qBRM_angles)));
            end
            filename = 'Flatfield';

            %reset polarizers to qBRM positions
            Rotational_stages_positions(app, app.motor_properties.qBRM_positions)
            update_progressbar(app, 0);

            app.focus_stage.set_position(0,start - 1500);
            moveXY(app,current_x,current_y);
            app.focus_stage.set_position(0,start);

            % save our flatfield in a new folder
            mkdir('Flatfield')
            cd('Flatfield')
            % only save if filename is provided
            if ~isempty(filename) 
                if size(images,3) ~=3
                    images = uint16(images);
                    angles = app.qBRM_angles;
                    system_status_update(app,'Saving Z stack as .mat file (not three points)');
                    save([filename,'.mat'], "images", "angles", '-v7.3')
                    rgb_img = [];

                else
                    rgb_img = cat(3,images(:,:,1),images(:,:,2),images(:,:,3));
                    if app.Saveas8bit.Value
                        rgb_img = rgb_img/256; %conver tot 8 bit scale
                        imwrite(uint8(rgb_img),[filename, '.tif'])
                    else
                        imwrite(uint16(rgb_img),[filename, '.tif'])
                    end
                end
            end
        end
        
        function timelapse_imaging(app,~,~)
            app.timelapse_counter = app.timelapse_counter +1; 
            disp(app.timelapse_counter)
            disp('Acquiring CCP Z stack')

            %folder to save time lapse
            %cd("E:\Aging_study\Sample_prep_paper\TDE\Timelapse")
            cd("C:\Users\BMOadmin2\Documents\Images\Rosene\Sample_Prep_paper\Timelapse_Folder")
            %CCP_zstack(app, app.focus_stage.query_position(0), app.SampleThickness.Value/app.ZStackStepSize.Value, app.ZStackStepSize.Value, sprintf('Timelapse_%03d',app.timelapse_counter));

            disp('Acquiring qBRM Z stack')
            qBRM_zstack(app,app.focus_stage.query_position(0), app.SampleThickness.Value/app.ZStackStepSize.Value, app.ZStackStepSize.Value, sprintf('Timelapse_%03d.tif',app.timelapse_counter));
        end
        
        function ROI_moving_events(app, src, event, ROI_counter)

            top_leftx = app.widefield_UL(1) - event.CurrentPosition(1)/app.pixels_per_100_microns(1)*0.1 - (0.5*app.camera_pixels(1)/app.pixels_per_100_microns(app.MagnificationDropDown.Value)*0.1);
            top_lefty = app.widefield_UL(2) + event.CurrentPosition(2)/app.pixels_per_100_microns(1)*0.1 + (0.5*app.camera_pixels(2)/app.pixels_per_100_microns(app.MagnificationDropDown.Value)*0.1);
            width = event.CurrentPosition(3)/app.pixels_per_100_microns(1)*0.1;
            height = event.CurrentPosition(4)/app.pixels_per_100_microns(1)*0.1;

            grid_sizex = 2;
            grid_sizey = 2;
            
        end
        
        function reference_img = take_notissue_reference(app)
            [current_x, current_y] = read_motor_position(app);
            start = app.focus_stage.query_position(0);
            app.focus_stage.set_position(0,start - 1500);
            pause(1)
            moveXY(app, 55,27.5);
            app.focus_stage.set_position(0,start);

            reference_img = image_capture(app,app.FFFramesEditField.Value);
        end
        
        function overlay_grid_ROI(app)
            % turn our imaging grid (in stage space coordinates) to a row
            % vector for calcuating overlay coordinates (pixel space)
            objective_list = [4,10,20,40,60];
            delete(app.widefield_patch_handle)
            delete(app.marker_handle)
            
            % offset for non perfect alighnment of objectives
            offset = [0,0; %4X --> 10X
                        0.0383, -0.1863; % 4X --> 10X
                    0.086, 0.5; % 4X --> 20X
                0.1, 0.34; % 4X --> 40X
                0.1,0.35]; % 4X --> 60X
            %x_sel = app.widefield_UL(1) - app.Image_axis_2.CurrentPoint(1,1)/app.pixels_per_100_microns(1)*0.1 + offset(app.MagnificationDropDown.Value,1);
            %y_sel = app.widefield_UL(2) + app.Image_axis_2.CurrentPoint(1,2)/app.pixels_per_100_microns(1)*0.1  + offset(app.MagnificationDropDown.Value, 2); % convert pixel position on image to the actual stage position


            %calculate the position of the TL of the grid on widefield image based on the stage coord of TL
            %of the widefield image and the stage coord TL of the grid
            overlay_UL = [(app.widefield_UL(1)-app.x_img_mesh(1,1)+ offset(app.MagnificationDropDown.Value,1))*(1000*4/app.pixel_size)-0.5*app.camera_pixels(1)*4/objective_list(app.MagnificationDropDown.Value), ...
                (app.y_img_mesh(1,1)- app.widefield_UL(2)- offset(app.MagnificationDropDown.Value,2))*(1000*4/app.pixel_size)-0.5*app.camera_pixels(2)*4/objective_list(app.MagnificationDropDown.Value)];  %difference between TL of widefield img and TL of current grid (in microns)


            %calc (x,y) coordinates/positions for overlay patch in image
            y = repmat(1:app.Grid_yentry.Value, [1,app.Grid_xentry.Value]); % create a matrix for the x positions  alcaulatins based on grid size 
            x = sort(repmat(1:app.Grid_xentry.Value, [1,app.Grid_yentry.Value])); % create a matrix for the y positions calculations based on grid size 

            %calc x positions
            TLx = overlay_UL(1) + (x-1)*(1-app.overlap_for_stitching)*(app.camera_pixels(1)*4/objective_list(app.MagnificationDropDown.Value));
            TRx = TLx+(app.camera_pixels(1)*4/objective_list(app.MagnificationDropDown.Value));
            BRx = TLx+(app.camera_pixels(1)*4/objective_list(app.MagnificationDropDown.Value));
            BLx = overlay_UL(1) + (x-1)*(1-app.overlap_for_stitching)*(app.camera_pixels(1)*4/objective_list(app.MagnificationDropDown.Value));

            % calc y positions
            TLy = overlay_UL(2) + (y-1)*(1-app.overlap_for_stitching)*(app.camera_pixels(2)*4/objective_list(app.MagnificationDropDown.Value));
            TRy = overlay_UL(2) + (y-1)*(1-app.overlap_for_stitching)*(app.camera_pixels(2)*4/objective_list(app.MagnificationDropDown.Value));
            BRy = TLy+(app.camera_pixels(1)*4/objective_list(app.MagnificationDropDown.Value));
            BLy = TLy+(app.camera_pixels(1)*4/objective_list(app.MagnificationDropDown.Value));
            
            grid_patch_x = [TLx; TRx; BRx; BLx];
            grid_patch_y = [TLy; TRy; BRy; BLy];
            
            app.grid_patchx = grid_patch_x;
            app.grid_patchy = grid_patch_y;
            app.widefield_patch_handle = patch(grid_patch_x,grid_patch_y,'red', 'EdgeColor', 'red','FaceColor', 'none', 'Parent', app.Image_axis_2);

            app.grid_image_px_locationsx = (app.widefield_UL(1)-app.x_img_mesh)*(1000*4/app.pixel_size);
            app.grid_image_px_locationsy = (app.y_img_mesh- app.widefield_UL(2))*(1000*4/app.pixel_size);
            tmp_grid_image_px_locationsx = (app.widefield_UL(1)-app.x_img_mesh(:)'+ offset(app.MagnificationDropDown.Value,1))*(1000*4/app.pixel_size);
            tmp_grid_image_px_locationsy = (app.y_img_mesh(:)'- app.widefield_UL(2)-offset(app.MagnificationDropDown.Value,2))*(1000*4/app.pixel_size);
            hold(app.Image_axis_2,'on')
            app.marker_handle = plot(tmp_grid_image_px_locationsx,tmp_grid_image_px_locationsy, 'r*', 'Parent',app.Image_axis_2, 'MarkerSize', 3);       
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                %pp.image_figure = figure(1);
                %app.Image_window = axes('Parent',app.image_figure);
                
                %get all the libraries we need
                addpath("C:\Users\BMOadmin2\Documents\Control_GUI\BRMscope\Functions")
                addpath("C:\Users\BMOadmin2\Documents\Control_GUI\BRMscope\Functions\qBRM")
                addpath("C:\Users\BMOadmin2\Documents\Control_GUI\BRMscope\Properties\")


                app.motor_properties = load("properties.mat").properties;  %% load in the properties file so we know which motor is which and some attributes that we want to be saveable
                app.motor_properties.CCP_positions = app.motor_properties.CCP_positions - app.CalibrationOffsetpolarizersEditField.Value;
                app.motor_properties.qBRM_positions = app.motor_properties.qBRM_positions - app.CalibrationOffsetpolarizersEditField.Value;
                app.motor_properties.brightfield_positions = app.motor_properties.brightfield_positions - app.CalibrationOffsetpolarizersEditField.Value;
                app.CalibrationOffsetpolarizersEditField.Value = app.motor_properties.Calibration_offset;

                drawnow;
                connect_rotation_motors(app);
                system_status_update(app,'Setting rotational Stages for CCP Imaging')
                update_motor_info(app,1)

                % Initialize the Focusing Stage
                system_status_update(app,'Initializing Focusing Stage')
                app.focus_stage = Focus_stage_init(app,"COM5");
                %app.focus_stage.set_position(0,10000) % set stage to a safe location of 1000 microns above the 0 point

                % Initialize the XY stage
                system_status_update(app,'Initializing XY Stage')
                [app.motor_x, app.motor_y] = motor_init(app.motor_properties.XYMotorSN);
                system_status_update(app,'Homing XY Stage')
                
                if app.debug
                    app.motor_x.Home(60000);
                    app.motor_y.Home(60000);
                end
                
                % Initialize timers for the background for stage position
                %start(app.timer_handle_focus_stage)

                % get the camera going
                initialize_camera(app);

                % Initialize the grid an all other acquisition parameters
                update_FOV(app);
                calc_tile_grid(app);

                system_status_update(app,'StandBy');

            catch ME
                disconnect_XY_motor(app);
                errorMessage = sprintf('Error in function %s() at line %d.\n\nError Message:\n%s', ...
                ME.stack(1).name, ME.stack(1).line, ME.message);
                uiwait(warndlg(errorMessage));
            end
            
            disp('Getting random flatfield to test to not take images where there is no tissue')
            

            % connect arduino for peripheral control
            %app.arduinoSerial = serialport("COM6",9600);
            

        end

        % Button pushed function: AcquireImagesButton
        function AcquireImagesButtonPushed(app, event)
            create_save_directory(app);
            disable_GUI(app);
            app.imaging_flag = true; % make umaging a lot faster by keeping preview running but dont update graphics
            % this helps us acquire data much much faster
            %stoppreview(app.vid);

            %create_save_directory(app, app.AcquisitionType.Value);
            switch str2double(app.AcquisitionType.Value)
                case 1 % Single Image
                    switch str2double(app.MicroscopeSetup.Value)
                        case 1 % CCP Imaging
                            [~] = single_image(app, app.FilenameEditField.Value);
                        case 2 % qBRM Imaging
                            img = qBRM_single_image(app, app.FilenameEditField.Value, app.FF_correction.Value);
                            save_qBRM(app, img, app.FilenameEditField.Value);
                        case 3 % Brightfield Imaging
                            [~] = single_image(app, app.FilenameEditField.Value);
                    end

                case 2 % Single Z Stack
                    switch str2double(app.MicroscopeSetup.Value)
                        case 1 % CCP Imaging
                            CCP_zstack(app, app.focus_stage.query_position(0), app.SampleThickness.Value/app.ZStackStepSize.Value, app.ZStackStepSize.Value, app.FilenameEditField.Value);
                        case 2 % qBRM Imaging
                            qBRM_zstack(app,app.focus_stage.query_position(0), app.SampleThickness.Value/app.ZStackStepSize.Value, app.ZStackStepSize.Value, app.FilenameEditField.Value, app.FF_correction.Value);
                        case 3 % Brightfield Imaging
                            CCP_zstack(app, app.focus_stage.query_position(0), app.SampleThickness.Value/app.ZStackStepSize.Value, app.ZStackStepSize.Value, app.FilenameEditField.Value);
                        case 4 % CCP + qBRM
                            zstack_CCP_qBRM(app,app.focus_stage.query_position(0), app.SampleThickness.Value/app.ZStackStepSize.Value, app.ZStackStepSize.Value, app.FilenameEditField.Value)
                    end
                case 3 % Widefield/Stitching
                    switch str2double(app.MicroscopeSetup.Value)
                        case 1 % CCP Imaging
                            %check if we have a grid if not just use teh
                            %current z position
                            if app.grid_exists
                                z_planes = app.z_img_mesh;
                            else
                                uiwait(msgbox('No Grid Found for tiling. The current Z position will be used for the Tiling grid'),3)
                                z_planes = ones(size(app.x_img_mesh))*app.focus_stage.query_position(0);
                            end
                                
                            stitched_CCP(app,app.x_img_mesh,app.y_img_mesh,z_planes);
                            
                            % if we took a 4X image do flatfield correciton
                            if app.MagnificationDropDown.Value == 1
                                
                                %disp('Dont do basic correction anymore')
                                basic_image_correction(app, pwd);
                                % actual folder location [app.save_folder_name,'/', filename]
                                % stitch the image based on stage
                                % coordinates (no fiji stitching)
                                app.widefield_img = stitch_inplace(pwd, app.Grid_xentry.Value, app.Grid_yentry.Value, 100*app.overlap_for_stitching);
                                app.widefield_loaded = 1;
                                imwrite(app.widefield_img, 'Fused_CCP.tif');
                            
                                %fiji_stitching(app.Grid_xentry.Value, app.Grid_yentry.Value, 100*app.overlap_for_stitching, pwd, 'img_basic{iii}.tif', 'Fused_CCP.tif');
                            end

                            if app.create_img_sequence_folders.Value % create image sequences from teh Z stacks that we create
                                write_image_Seq(pwd);
                            end
                            
                        case 2 % qBRM Imaging
                            %check if we have a grid if not just use teh
                            %current z position
                            if app.grid_exists
                                z_planes = app.z_img_mesh;
                            else
                                uiwait(msgbox('No Grid Found for tiling. The current Z position will be used for the Tiling grid'),3)
                                z_planes = ones(size(app.x_img_mesh))*app.focus_stage.query_position(0);
                            end

                            stitched_qBRM(app,app.x_img_mesh,app.y_img_mesh,z_planes);
                            
                        case 3 % Brightfield Imaging
                            %check if we have a grid if not just use teh
                            %current z position
                            if app.grid_exists
                                z_planes = app.z_img_mesh;
                            else
                                uiwait(msgbox('No Grid Found for tiling. The current Z position will be used for the Tiling grid'),3)
                                z_planes = ones(size(app.x_img_mesh))*app.focus_stage.query_position(0);
                            end
                            stitched_CCP(app,app.x_img_mesh,app.y_img_mesh,z_planes);
                        case 4 % ROI Imaging (CCP and qBRM included)
                            prompt = {'Please Enter Foldername for All ROIs'};
                            dlgtitle = 'Input';
                            fieldsize = [1 45];
                            definput = {'Default_Folder'};
                            answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
                            answer = answer{1};
                            if ~exist([app.save_folder_name, '/',answer] ,'dir')
                                mkdir([app.save_folder_name, '/',answer]);
                            end
                            cd([app.save_folder_name,'/', answer]);
                           
                            for i =1:length(app.ROI_imaging_handle)
                                system_status_update(app,sprintf('Running ROI Imaging (ROI: %d/%d',i, length(app.ROI_imaging_handle)))
                                cd([app.save_folder_name,'/', answer]);

                                % update all acquisition Settings for the
                                % current ROI
                                value = i;
                                app.FilenameEditField.Value = app.ROI_imaging_handle{value}{1,1};
                                app.ExposureTime.Value = app.ROI_imaging_handle{value}{1,2};
                                app.Exposure_units.Value = app.ROI_imaging_handle{value}{1,3};
                                app.src.ExposureResolution = app.ROI_imaging_handle{value}{1,3};
                                app.src.Exposure = app.ROI_imaging_handle{value}{1,2};
                                app.MagnificationDropDown.Value = app.ROI_imaging_handle{value}{1,4};
                                app.x_img_mesh = app.ROI_imaging_handle{value}{1,5};
                                app.y_img_mesh = app.ROI_imaging_handle{value}{1,6};
                                app.z_img_mesh = app.ROI_imaging_handle{value}{1,7};
                                app.Grid_yentry.Value = size(app.x_img_mesh, 1);
                                app.Grid_xentry.Value = size(app.x_img_mesh, 2);
                                app.Saveas8bit.Value = app.ROI_imaging_handle{value}{1,8};
                                app.FF_correction.Value = app.ROI_imaging_handle{value}{1,9};
                                app.SampleThickness.Value = app.ROI_imaging_handle{value}{1,10};
                                app.ZStackStepSize.Value = app.ROI_imaging_handle{value}{1,11};
                                app.StitchingType.Value = app.ROI_imaging_handle{value}{1,12};
                                app.MicroscopeSetup.Value = app.ROI_imaging_handle{value}{1,13};
                                app.AcquisitionType.Value = app.ROI_imaging_handle{value}{1,14};
                                drawnow; 

                                filename = sprintf('%s_exp%d%s_mag%s',app.FilenameEditField.Value,app.ExposureTime.Value,app.Exposure_units.Value,app.MagnificationDropDown.Items{app.MagnificationDropDown.Value});
                                app.experimental_folder_main = [app.save_folder_name,'/', answer, '/', filename];
                                if ~exist([app.save_folder_name,'/', answer, '/', filename],'dir')
                                    mkdir([app.save_folder_name,'/', answer, '/', filename]);
                                end
                                cd([app.save_folder_name,'/', answer, '/', filename]);
                                write_sample_description(app);

                                switch str2double(app.MicroscopeSetup.Value) %  run CCP-BRM
                                    case 1
                                        stitched_CCP(app,app.x_img_mesh,app.y_img_mesh,app.z_img_mesh);
                                    case 2
                                        stitched_qBRM(app,app.x_img_mesh,app.y_img_mesh,app.z_img_mesh);
                                    otherwise
                                        disp('Error We dont do that here... Only select CCP-BRM and qBRM stitching')
                                end 
                            end
                    end
            end

            % if thsi box is checked then we will leave the preview off 
            if ~app.TurnOffPreviewAfterAcqusitionCheckBox.Value
                app.imaging_flag = false; % make umaging a lot faster by keeping preview running but dont update graphics
            end
            
            preview(app.vid,app.hImage);
            system_status_update(app,'StandBy');
            update_progressbar(app,0);
            enable_GUI(app);
        end

        % Value changed function: MotorInfoListBox
        function MotorInfoListBoxValueChanged(app, event)
            update_motor_info(app,app.MotorInfoListBox.Value);
        end

        % Button pushed function: Home_Rot_Motors
        function Home_Rot_MotorsPushed(app, event)
            system_status_update(app, 'Homing Rotational Motors');
            Home_rotational_stages(app);
            system_status_update(app,'StandBy');
        end

        % Button pushed function: ChangeObjectiveButton
        function ChangeObjectiveButtonPushed(app, event)
            current = app.focus_stage.query_position(0);
            app.focus_stage.set_position(0,1000) % set stage to a safe location of 1000 microns above the 0 point
            pause(3)
            uiwait(msgbox('Press Enter Once you have switched objectives and it will return to previous position'))
            app.focus_stage.set_position(0, current)
        end

        % Button pushed function: Rotate_ill_pol
        function Rotate_ill_polButtonPushed(app, event)
            app.illumination_pol.MoveAbsolute(app.IlluminationPolarizerEditField.Value);
            app.illumination_waveplate.MoveAbsolute(app.IlluminationWaveplateEditField.Value);
            app.detection_pol.MoveAbsolute(app.DetectionPolarizerEditField.Value);
            app.detection_waveplate.MoveAbsolute(app.DetectionWaveplateEditField.Value);

            update_motor_positions(app);
        end

        % Button pushed function: MoveXYmotorButton
        function MoveXYmotorButtonPushed(app, event)
            app.x_coord_motor = str2double(app.x_coord_entry.Value);
            app.y_coord_motor = str2double(app.y_coord_entry.Value);
            moveXY(app,app.x_coord_motor, app.y_coord_motor);
        end

        % Button pushed function: HomeXYStageButton
        function HomeXYStageButtonPushed(app, event)
            Home_XY_Stage(app);
        end

        % Button pushed function: SaveLocationButton
        function SaveLocationButtonPushed(app, event)
            [x_coord, y_coord] = read_motor_position(app);
            app.saved_locations = [app.saved_locations, [x_coord;y_coord]];
            app.SavedLocationsListBox.ItemsData = [app.SavedLocationsListBox.ItemsData,length(app.saved_locations)];
            app.SavedLocationsListBox.Items = [app.SavedLocationsListBox.Items, sprintf('%s-(%5.1f,%5.1f)', app.LocationLabelEditField.Value,x_coord, y_coord)]; % append the new string to the ROI_selection label
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.saved_locations = [55;37.5];
            app.SavedLocationsListBox.ItemsData = [1];
            app.SavedLocationsListBox.Items = {'Home (55,37.5)'};
        end

        % Button pushed function: UnselectButton
        function UnselectButtonPushed(app, event)
             app.SavedLocationsListBox.Value={};
        end

        % Value changed function: SavedLocationsListBox
        function SavedLocationsListBoxValueChanged(app, event)
            value = app.SavedLocationsListBox.Value;
            moveXY(app, app.saved_locations(value,2),app.saved_locations(value,1))
        end

        % Value changed function: MicroscopeSetup
        function MicroscopeSetupValueChanged(app, event)
            value = str2double(app.MicroscopeSetup.Value);

            switch value
                case 1  % CCP imaging optics positions
                    Rotational_stages_positions(app, app.motor_properties.CCP_positions)
                case 2  % qBRM imaging optics positions
                    Rotational_stages_positions(app, app.motor_properties.qBRM_positions)
                case 3  % Pseudo Brightfield imaging optics positions
                    Rotational_stages_positions(app, app.motor_properties.brightfield_positions)
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            selpath = uigetdir("C:\Users\BMOadmin2\Documents\",'Get Save Directory');
            if selpath
                C = strsplit(selpath,'\');
                path = C{:,end};
                selpath_dir = erase(selpath, path);

                if ~isequal(selpath_dir,0) % if a directory is selected
                    app.save_folder_name = selpath;
                    app.SaveDirectory.Value = path;
                    app.SaveDirLabel.Text = app.save_folder_name;
                end
            end
        end

        % Value changed function: Exposure_units
        function Exposure_unitsValueChanged(app, event)
            app.src.ExposureResolution = app.Exposure_units.Value;
            if strcmp(app.AutoContrastDropDown.Value,'us')
                value = 500;
            else
                value = 5;
            end
            app.ExposureTime.Value = value;
            app.ExposureTime1.Value = value;
            app.src.Exposure = app.ExposureTime1.Value;
            app.msLabel.Text = app.Exposure_units.Value;
            app.msLabel_2.Text = app.Exposure_units.Value;
        end

        % Value changed function: ExposureTime
        function ExposureTimeValueChanged(app, event)
            value = app.ExposureTime.Value;
            app.ExposureTime2.Value = value;
            app.ExposureTime1.Value = value;
            app.src.Exposure = value;
        end

        % Button down function: Grid_axis
        function Grid_axisButtonDown(app, event)
            delete(app.current_selected_point)
            X = floor(app.Grid_axis.CurrentPoint(1,1)) + 1;
            Y = floor(app.Grid_axis.CurrentPoint(1,2)) + 1;
            app.current_selected_point = plot(app.Grid_axis,X-0.5,Y-0.5,'r.','MarkerSize',16);
            hold(app.Grid_axis,'on');
            app.current_grid_selection = [X,Y];
            
            %move to the new selected tile
            moveXY(app,app.x_img_mesh(Y,X),app.y_img_mesh(Y,X))
        end

        % Button pushed function: SetUpperLeftButton
        function SetUpperLeftButtonPushed(app, event)
            % read position of the motor
            [app.ULx, app.ULy] = read_motor_position(app);
            app.UL_Location_lbl.Text = sprintf('(%5.2f,%5.2f)',app.ULx,app.ULy);
            app.SelectPositionButton.Enable = 'on';
            reset_grid(app);
        end

        % Button pushed function: SetUpperLeftButton_2
        function SetUpperLeftButton_2Pushed(app, event)
            [app.ULx, app.ULy] = read_motor_position(app);
            app.UL_Location_lbl.Text = sprintf('(%5.2f,%5.2f)',app.ULx,app.ULy);
            app.SelectPositionButton.Enable = 'on';
            reset_grid(app);
            overlay_grid_ROI(app);
        end

        % Callback function
        function SetLowerRightButtonPushed(app, event)
            [app.LRx, app.LRy] = read_motor_position(app);
            app.SelectPositionButton.Enable = 'on';
            app.LR_Location.Text = sprintf('(%.2f,%.2f)', app.LRx, app.LRy);
            app.grid_plane = [];
            %reset_grid_parameters(app);
            %calc grid size then just do normal update
            x_min = app.LRx;
            x_max = app.ULx;
            x_step = app.FOV_microns_stitching_x(app.mag_index)/1000; % convert FOV in microns to mm
            x_max = x_max + x_step/2; % increase to include the point itself
            x_img = x_min:x_step:x_max;
            x_plot_grid = (x_min-x_step/2):x_step:(x_max+x_step/2); % Step is divided by 2 to center frame over boundary
            app.Grid_xentry.Value = length(x_plot_grid);

            y_min = app.ULy;
            y_max = app.LRy;
            y_step = app.FOV_microns_stitching_y(app.mag_index)/1000; % convert FOV in microns to mm
            y_max = y_max + y_step/2;  % increase to include the point itself
            y_img = y_min:y_step:y_max;
            y_plot_grid = (y_min-y_step/2):y_step:(y_max+y_step/2); % Step is divided by 2 to center frame over boundary
            app.Grid_yentry.Value = length(y_plot_grid);

            update_grid(app)
        end

        % Callback function: Grid_xentry, Grid_yentry, 
        % ...and 2 other components
        function Grid_xentryValueChanged(app, event)
            reset_grid(app);
            x_fov = app.camera_pixels(1)*app.motor_properties.pixel_size/app.motor_properties.Objectives(app.MagnificationDropDown.Value);
            y_fov = app.camera_pixels(2)*app.motor_properties.pixel_size/app.motor_properties.Objectives(app.MagnificationDropDown.Value);
            app.FOVmmLabel.Text = sprintf('FOV: (%5.2fx%5.2f) um',x_fov,y_fov);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            disp(key)
            switch key
                case 'tab' % hit tab to switch objectives
                    current = app.focus_stage.query_position(0);
                    app.focus_stage.set_position(0,current - 10000) % set stage to a safe location of 1000 microns above the 0 point
                    pause(3)
                    uiwait(msgbox('Press Enter Once you have switched objectives and it will return to previous position'))
                    app.focus_stage.set_position(0, current)
                case 'space'
                    delete(app.zoom_patch_handle)
                    pbaspect(app.Image_axis, [app.camera_pixels(1),app.camera_pixels(2),1])
                    app.Image_axis.XLim = [0,app.camera_pixels(1)/app.PreviewDownsamplingEditField.Value];
                    app.Image_axis.YLim = [0,app.camera_pixels(2)/app.PreviewDownsamplingEditField.Value];
                case 'control'
                    app.zoom_mode = 1;
                case 'shift'
                    app.distance_measure_mode = 1;
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            app.distance_measure_mode = 0;
            app.zoom_mode = 0;
        end

        % Button down function: Image_axis
        function Image_axisButtonDown(app, event)
            %find the distance from teh center pixel and convert to stage
            %space (mm)

            if app.zoom_mode
                app.button_pressed_measure = 0;
                app.distance_measure_mode = 0;
                app.button_pressed = 1;
                app.original_position = [app.Image_axis.CurrentPoint(1,1),app.Image_axis.CurrentPoint(1,2)];

            elseif app.distance_measure_mode
                app.zoom_mode = 0;
                app.button_pressed_measure = 1;
                app.button_pressed = 0;
                app.original_position_measure = [app.Image_axis.CurrentPoint(1,1),app.Image_axis.CurrentPoint(1,2)];
                
            else
                x = (app.Image_axis.CurrentPoint(1,1)-(round(app.camera_pixels(1)/app.preview_downsample_val)/2))/app.pixels_per_100_microns(app.MagnificationDropDown.Value)*0.1*app.preview_downsample_val;
                y = -(app.Image_axis.CurrentPoint(1,2)-(round(app.camera_pixels(2)/app.preview_downsample_val)/2))/app.pixels_per_100_microns(app.MagnificationDropDown.Value)*0.1*app.preview_downsample_val; % this value is negative becuase the camera is flipped
                
                % find new location based on current location nad move
                moveXY(app,app.x_coord_motor - x,app.y_coord_motor - y)
            end
        end

        % Button down function: Image_axis_2
        function Image_axis_2ButtonDown(app, event)
            % offset for not perfect alignment of the 4X and 40X objective [10X; 20X; 40X; 60X]
            offset = [0,0; %4X --> 10X
                        0.0383, -0.1863; % 4X --> 10X
                    0.086, 0.5; % 4X --> 20X
                0.1, 0.34; % 4X --> 40X
                0.1,0.35]; % 4X --> 60X
            x_sel = app.widefield_UL(1) - app.Image_axis_2.CurrentPoint(1,1)/app.pixels_per_100_microns(1)*0.1 + offset(app.MagnificationDropDown.Value,1);
            y_sel = app.widefield_UL(2) + app.Image_axis_2.CurrentPoint(1,2)/app.pixels_per_100_microns(1)*0.1  + offset(app.MagnificationDropDown.Value, 2); % convert pixel position on image to the actual stage position
            fprintf('X: %3.2f, Y: %3.2f', x_sel, y_sel)
            moveXY(app,x_sel, y_sel);

        end

        % Button pushed function: LoadWidefieldImageButton
        function LoadWidefieldImageButtonPushed(app, event)

            selpath = uigetdir('C:\Users\BMOadmin2\Documents\','Get Save Directory');
             if selpath
                try
                    if ~app.widefield_loaded
                        app.widefield_img = imread([selpath, '\Fused_CCP.tif']);
                        app.widefield_loaded = 1;
                    end
                    disp('try to get both an image and a widefield')

                    %reset polygon mask
                    app.tissue_polygon_mask = [];

                    app.widefield_loaded = 1;
                    app.grid_overlay_coordinates = load([selpath, '\grid_coordinates.mat']);
                    %app.widefield_img = imread([selpath, '\Fused_CCP.tif']);
                    app.hImage2 = image(app.Image_axis_2, zeros(size(app.widefield_img,2),size(app.widefield_img,1),1), 'HitTest','off');
                    pbaspect(app.Image_axis_2, [size(app.widefield_img,2),size(app.widefield_img,1),1])
                    %imshow(app.widefield_img, 'Parent',app.Image_axis_2)
                    app.Image_axis_2.XLim = [0,size(app.widefield_img,2)];
                    app.Image_axis_2.YLim = [0,size(app.widefield_img,1)];
                    app.Image_axis_2.XTick = [];
                    app.Image_axis_2.YTick = [];
                    app.widefield_img = imadjust(app.widefield_img);
                    set(app.hImage2, 'CData', app.widefield_img);

                    % offset for not perfect alignment of the 4X and 40X objective [10X; 20X; 40X; 60X]
                     offset = [0,0; %4X --> 10X
                        0.0383, -0.1863; % 4X --> 10X
                    -0.1, 0.4; % 4X --> 20X
                0.1, 0.34; % 4X --> 40X
                0.1,0.35]; % 4X --> 60X

                    app.widefield_UL(1) = app.grid_overlay_coordinates.x_mesh(1,1)+ (0.5*app.camera_pixels(1)/app.pixels_per_100_microns(1)*0.1);
                    app.widefield_UL(2) = app.grid_overlay_coordinates.y_mesh(1,1)- (0.5*app.camera_pixels(2)/app.pixels_per_100_microns(1)*0.1);
                    app.widefield_UL
                    overlay_grid_ROI(app);
                catch ME
                    errorMessage = sprintf('Error in function %s() at line %d.\n\nError Message:\n%s', ...
                        ME.stack(1).name, ME.stack(1).line, ME.message);
                    uiwait(warndlg(errorMessage));
                end
             end
        end

        % Button pushed function: ROIImagingButton
        function ROIImagingButtonPushed(app, event)
            disp('Add ROI to image')
            app.ROI_handle{app.ROI_counter} = drawrectangle('Label',sprintf('ROI #%d (%s)',app.ROI_counter, app.MagnificationDropDown.Items{app.MagnificationDropDown.Value}),'Parent',app.Image_axis_2);
            addlistener(app.ROI_handle{app.ROI_counter},'ROIMoved',@(src,event)app.ROI_moving_events(src,event,app.ROI_counter));

            if ~isempty(app.ROI_handle{app.ROI_counter}.Position)
                app.ROI_counter = app.ROI_counter + 1;

            end
        end

        % Button pushed function: DrawTissueROIButton
        function DrawTissueROIButtonPushed(app, event)
            tmp_polygon = drawpolygon(app.Image_axis_2, 'LineWidth',3,'Color','cyan');
            disp(app.grid_image_px_locationsx)
            app.tissue_ROI = tmp_polygon.Position;

            app.tissue_polygon_mask = inpolygon(app.grid_image_px_locationsx,app.grid_image_px_locationsy,app.tissue_ROI(:,1)',app.tissue_ROI(:,2)');
            fprintf('Number of images: %d \n', nnz(app.tissue_polygon_mask))
            app.marker_handle = plot(app.grid_image_px_locationsx(app.tissue_polygon_mask), app.grid_image_px_locationsy(app.tissue_polygon_mask), 'g*', 'Parent',app.Image_axis_2, 'MarkerSize', 3);   
            % create the actual mask to be used during stitched image
            % acquisiton (to say where to not take images
            %app.tissue_polygon_mask = reshape(app.tissue_polygon_mask, [app.Grid_xentry.Value,app.Grid_yentry.Value]);
            
            delete(tmp_polygon)
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if app.button_pressed
                app.zoom_mode = 0;
                app.button_pressed = 0;
                delete(app.zoom_patch_handle)
                %%% pseudo zoom into the image
                w = abs(app.original_position(1) - app.Image_axis.CurrentPoint(1,1));
                h = abs(app.original_position(2) - app.Image_axis.CurrentPoint(1,2));
                app.Image_axis.XLim = [app.original_position(1) app.Image_axis.CurrentPoint(1,1)];
                app.Image_axis.YLim = [app.original_position(2) app.Image_axis.CurrentPoint(1,2)];
                pbaspect(app.Image_axis, [w,h,1])
            end

            if app.button_pressed_measure
                app.button_pressed_measure = 0;
                app.distance_measure_mode = 0;
                delete(app.measure_patch_handle)
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.button_pressed
                delete(app.zoom_patch_handle)
                height = app.Image_axis.CurrentPoint(1,2) - app.original_position(2);
                width =  app.Image_axis.CurrentPoint(1,1) - app.original_position(1);
                coordinates = [app.original_position(1), app.original_position(2), width, height];
                %%%% handle all types of dragging of the rectangle
                %%%% to make sure it ends up the same shape drawn
                %%%% (not negative)
                if height < 0 && width < 0
                    coordinates = [app.Image_axis.CurrentPoint(1,1), app.Image_axis.CurrentPoint(1,2), abs(width), abs(height)];
                elseif height < 0 && width > 0
                    coordinates = [app.original_position(1), app.Image_axis.CurrentPoint(1,2), abs(width), abs(height)];
                elseif height > 0 && width < 0
                    coordinates = [app.Image_axis.CurrentPoint(1,1), app.original_position(2), abs(width), abs(height)];
                end
                patchx = [coordinates(1); coordinates(1) + coordinates(3); coordinates(1)+ coordinates(3); coordinates(1)];
                patchy = [coordinates(2) + coordinates(4); coordinates(2)+ coordinates(4); coordinates(2); coordinates(2)];
                app.zoom_patch_handle = patch(patchx, patchy, 'red', 'FaceColor', 'none', 'EdgeColor', 'red', 'Parent', app.Image_axis);

            elseif app.button_pressed_measure
                delete(app.measure_patch_handle)

                patchx = [app.original_position_measure(1), app.Image_axis.CurrentPoint(1,1)];
                patchy = [app.original_position_measure(2), app.Image_axis.CurrentPoint(1,2)];
                y = [app.Image_axis.CurrentPoint(1,1), app.Image_axis.CurrentPoint(1,2)];
                
                % update distance measure based on magnification
                app.distance_measured  = app.preview_downsample_val*100*norm(app.original_position_measure-y)/app.pixels_per_100_microns(app.MagnificationDropDown.Value);

                app.DistanceLabel.Text = sprintf('Distance: %5.3f µm', app.distance_measured);
                app.measure_patch_handle = patch(patchx, patchy, 'red', 'FaceColor', 'none', 'EdgeColor', 'red', 'Parent', app.Image_axis);

            end
        end

        % Value changed function: TileOverlapEditField
        function TileOverlapEditFieldValueChanged(app, event)
            app.overlap_for_stitching = app.TileOverlapEditField.Value/100;
            update_FOV(app);
            calc_tile_grid(app);
            reset_grid(app);
        end

        % Button pushed function: UnSelectPositionButton
        function UnSelectPositionButtonPushed(app, event)
            [grid_already_selected, idx] = ismember(app.current_grid_selection, app.grid_idx_selected, 'rows');
            if grid_already_selected
                app.grid_idx_selected(idx,:) = [];
                app.SelectPositionButton.Enable = 'on';
            else
                message = 'Location has not been selected yet';
                uiwait(warndlg(message),4);
            end
        end

        % Button pushed function: SelectPositionButton
        function SelectPositionButtonPushed(app, event)
            [grid_already_selected, ~] = ismember(app.current_grid_selection, app.grid_idx_selected, 'rows');
            if grid_already_selected
                message = 'Location already Selected for Stitching';
                uiwait(warndlg(message),4);
            else
                app.grid_idx_selected = [app.grid_idx_selected; app.current_grid_selection];
                % get all positions and save to a global Plane var to calculate
                % plane later on
                z_focus = app.focus_stage.query_position(0);
                [x_pos, y_pos] = read_motor_position(app);
                app.grid_plane(:,:, end+1) = [x_pos, y_pos, z_focus];

                app.UnSelectPositionButton.Enable = 'on';

                if size(app.grid_idx_selected,1) >3
                    app.SelectPositionButton.Enable = 'off';
                    z_plane = calc_z_plane(app, app.x_img_mesh,app.y_img_mesh,app.grid_plane);
                    app.z_img_mesh = z_plane; % set the Z img mesh as teh z plane
                end
                update_grid(app);
            end
        end

        % Value changed function: CameraBinningDropDown
        function CameraBinningDropDownValueChanged(app, event)
            disp('need to write code to change binning')
        end

        % Callback function
        function AutoContrastButtonValueChanged(app, event)
            value = app.AutoContrastButton.Value;
            disp('need to write code to change contrast')
        end

        % Button pushed function: ResetCameraButton
        function ResetCameraButtonPushed(app, event)
            imaqreset;
            % Re-Initialize the camera
            initialize_camera(app);
        end

        % Button pushed function: LoadAcquisitionButton
        function LoadAcquisitionButtonPushed(app, event)
                try
                    [file,path] = uigetfile('*.mat');
                    if file
                        coordinates = load([path, file]);
                        app.x_img_mesh = coordinates.x_mesh;
                        app.y_img_mesh = coordinates.y_mesh;
                        app.z_img_mesh = coordinates.z_planes;

                        app.ULx = app.x_img_mesh(1,1);
                        app.ULy = app.y_img_mesh(1,1);
                        app.UL_Location_lbl.Text = sprintf('(%5.2f,%5.2f)',app.ULx,app.ULy);
                        app.Grid_yentry.Value = size(app.x_img_mesh,1);
                        app.Grid_xentry.Value = size(app.x_img_mesh,2);
                        
                        x = app.Grid_xentry.Value;
                        y = app.Grid_yentry.Value;
            
                        app.Grid_axis.XTick = 0:1:x;
                        app.Grid_axis.YTick = 0:1:y;
            
                        app.Grid_axis.XLim = [0 x];
                        app.Grid_axis.YLim = [0 y];
                        app.current_grid_selection = [1,1];
                        app.grid_idx_selected = [0,0];
                        app.grid_plane = [];

                        app.UnSelectPositionButton.Enable = 'on';
                    
                        app.SelectPositionButton.Enable = 'off';
                        app.grid_exists = 1;

                    end 
                catch ME
                    errorMessage = sprintf('Error in function %s() at line %d.\n\nError Message:\n%s', ...
                        ME.stack(1).name, ME.stack(1).line, ME.message);
                    uiwait(warndlg(errorMessage));
                end
        end

        % Button pushed function: CancelAcquisitonButton
        function CancelAcquisitonButtonPushed(app, event)
            disp('need code to cancel acqusiition')
        end

        % Value changed function: AutoContrastDropDown
        function AutoContrastDropDownValueChanged(app, event)
            auto_contrast = ['OFF,ON-MANUAL','ON'];
            app.src.AutoContrast = auto_contrast(app.AutoContrastDropDown.Value);
        end

        % Button pushed function: ClibrationOffsetButton
        function ClibrationOffsetButtonPushed(app, event)
            message = 'Place Calibration Slide to calibrate onto the stage and move to the 0° Polarizer. Press ENTER to start calibration. (Make sure Angles are set to 0:30:150)';
            uiwait(msgbox(message));

%             solver = load('Symbolic_solution_0_60_120.mat').solve_qBRM_symbolic;
% 
%             img = qBRM_single_image(app, 'Calibration_test.tif');
% 
%             img = double(img);
            
        end

        % Button pushed function: CalibratethePolarizationOpticsButton
        function CalibratethePolarizationOpticsButtonPushed(app, event)
            disp(['need to add a function that walks the user through calibrationg the polarization optics. Use the procedure from thorlabs. ' ...
                'First have the polarizers in unknown position then find minimum transmission when your signal is the lowest (crossed polarizers). ' ...
                'Then add the detction waveplate and find where it is maximum signal (this is 45 degrees and creates circularly polarized light' ...
                'Then need to add the last waveplate then find the overall minimum. '])


            message = 'remove all polarizing optics except for the linear polarizers. When you only have the linear polarizers click ENTER.';
            uiwait(msgbox(message));

            % while loop to find where olarizers are crossed
            counter = 1;
            calibrating = 1;
            app.detection_pol.Home();
            app.illumination_pol.Home();

            step = 15;
            current = get_rotational_position(app,app.detection_pol);
            direction = 1;
            img = uint16(image_capture(app));
            low_val = mean(img,'all');

            for i = 1:40
                app.detection_pol.MoveAbsolute(current + step*direction)
                pause(1)
                
                previous = current;
                current = get_rotational_position(app,app.detection_pol);
                
                img = uint16(image_capture(app));
                val = mean(img,'all');

                if val < low_val
                    low_val = val;
                    step = 0.75*step;
                else
                    current = previous; % if we go higher than we want to go back to original position
                    direction = -direction;
                    step = 0.9*step;
                end

                fprintf('Position: %5.2f Value: %5.2f Low Value: %5.2f Step: %5.2f direction: %5.2f \n',current, val, low_val, step, direction)
                
            end
            
            optimal = current;
            if optimal < 0
                optimal = optimal + 180;
            end

            message = sprintf('optimal Polarizer Position is %5.3f. Place the Illumination waveplate into the optical path and Press ENTER.', optimal);
            uiwait(msgbox(message));

            app.illumination_pol.Home();
            app.detection_pol.MoveAbsolute(optimal)
            app.illumination_waveplate.Home();

            % while loop to find where olarizers are crossed
            counter = 1;
            calibrating = 1;
            
            step = 15;
            current = get_rotational_position(app,app.illumination_waveplate);
            direction = 1;
            img = uint16(image_capture(app));
            low_val = mean(img,'all');

            for i = 1:40
                app.illumination_waveplate.MoveAbsolute(current + step*direction)
                pause(1)
                
                previous = current;
                current = get_rotational_position(app,app.illumination_waveplate);
                
                img = uint16(image_capture(app));
                val = mean(img,'all');

                if val > low_val
                    low_val = val;
                    step = 0.75*step;
                else
                    current = previous; % if we go higher than we want to go back to original position
                    direction = -direction;
                    step = 0.9*step;
                end

                fprintf('Position: %5.2f Value: %5.2f Low Value: %5.2f Step: %5.2f direction: %5.2f \n',current, val, low_val, step, direction)
                
            end

            optimal_waveplate = current;
            if optimal_waveplate < 0
                optimal_waveplate = optimal_waveplate + 180;
            end

            message = sprintf('optimal Polarizer Position is %5.3f. Place the detection waveplate into the optical path and Press ENTER.', optimal_waveplate);
            uiwait(msgbox(message));

            % while loop to find where olarizers are crossed
            counter = 1;
            calibrating = 1;
            app.illumination_pol.Home();
            app.detection_pol.MoveAbsolute(optimal)
            app.illumination_waveplate.MoveAbsolute(optimal_waveplate);
            app.detection_waveplate.Home();

            step = 15;
            current = get_rotational_position(app,app.detection_waveplate);
            direction = 1;
            img = uint16(image_capture(app));
            low_val = mean(img,'all');

            for i = 1:40
                app.detection_waveplate.MoveAbsolute(current + step*direction)
                pause(1)
                
                previous = current;
                current = get_rotational_position(app,app.detection_waveplate);
                
                img = uint16(image_capture(app));
                val = mean(img,'all');

                if val < low_val
                    low_val = val;
                    step = 0.75*step;
                else
                    current = previous; % if we go higher than we want to go back to original position
                    direction = -direction;
                    step = 0.9*step;
                end

                fprintf('Position: %5.2f Value: %5.2f Low Value: %5.2f Step: %5.2f direction: %5.2f \n',current, val, low_val, step, direction)
                
            end

            optimal_waveplate_det = current;
            if optimal_waveplate_det < 0
                optimal_waveplate_det = optimal_waveplate_det + 180;
            end

            message = sprintf('Illumination Polarizer: 0° \n Detection Polarizer: %5.3f° \n Illumination Waveplate: %5.3f° \n Detection Waveplate: %5.3f°', optimal, optimal_waveplate, optimal_waveplate_det);
            uiwait(msgbox(message));

    
        end

        % Value changed function: qBRM_angles_text
        function qBRM_angles_textValueChanged(app, event)
            str = app.qBRM_angles_text.Value{1,1};
            str_tmp = str;
            try
                str(~isstrprop(str,'digit')) = ' '; %replace non-numeric characters with empty space
                vec = str2double(strsplit(strtrim(str))); % trim white space and convert to numeric
                
                % if given in vector notation convert it
                if contains(str_tmp,':')
                    vec = vec(1):vec(2):vec(3);
                end

                app.qBRM_angles = vec;
                
                %update text box with vector
                s1 = sprintf('%.0f,' , vec);
                app.qBRM_angles_text.Value = s1(1:end-1);
                
            catch ME
                errorMessage = sprintf('Error in function %s() at line %d.\n\nError Message:\n%s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                uiwait(warndlg(errorMessage));
            end
        end

        % Button pushed function: StandardDeviationButton
        function StandardDeviationButtonPushed(app, event)
            img = image_capture(app);
            variation = std(single(img(:)));
            app.STDLabel.Text = sprintf('STD: %5.2f',variation);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            disconnect_XY_motor(app);
            start = app.focus_stage.query_position(0);
            %app.focus_stage.set_position(0,0);
            imaqreset;
            delete(app)
        end

        % Value changed function: PreviewDownsamplingEditField
        function PreviewDownsamplingEditFieldValueChanged(app, event)
            value = app.PreviewDownsamplingEditField.Value;
            app.preview_downsample_val = value;
            imaqreset;
            % Re-Initialize the camera
            initialize_camera(app);
        end

        % Value changed function: TurnOffPreviewAfterAcqusitionCheckBox
        function TurnOffPreviewAfterAcqusitionCheckBoxValueChanged(app, event)
            value = app.TurnOffPreviewAfterAcqusitionCheckBox.Value;
            if value
                
                stoppreview(app.vid);
            else
                imaqreset;
                initialize_camera(app);
            end
        end

        % Button pushed function: LoadGridButton
        function LoadGridButtonPushed(app, event)
            selpath = uigetfile("C:\Users\BMOadmin2\Documents\",'Get Save Directory');

            if selpath
                try
                    grid_parameters = load(selpath);
                    app.x_img_mesh = grid_parameters.x_mesh;
                    app.y_img_mesh = grid_parameters.y_mesh;
                    app.z_img_mesh =  grid_parameters.z_planes;

                    app.ULx = app.x_img_mesh(1,1);
                    app.ULy = app.y_img_mesh(1,1);
                    app.UL_Location_lbl.Text = sprintf('(%5.2f,%5.2f)',app.ULx,app.ULy);

                    app.Grid_xentry.Value = size(app.x_img_mesh,2);
                    app.Grid_yentry.Value = size(app.x_img_mesh,1);
                    reset_grid(app);
                    % need to add all of these values to grid coordinates
                    % mat file so we can load in acquisitions
%                     app.MagnificationDropDown.Items{app.MagnificationDropDown.Value}, app.ExposureTime1.Value, app.ExposureTime2.Value, app.Exposure_units.Value, ...
%                     app.AcquisitionType.Items{str2num(app.AcquisitionType.Value)}, app.MicroscopeSetup.Items{str2num(app.MicroscopeSetup.Value)}, app.Grid_xentry.Value, app.Grid_yentry.Value, app.TileOverlapEditField.Value, ...
%                     app.ZStackStepSize.Value, app.IlluminationPolarizerEditField.Value, app.IlluminationWaveplateEditField.Value,app.DetectionPolarizerEditField.Value,
                catch ME
                    errorMessage = sprintf('Error in function %s() at line %d.\n\nError Message:\n%s', ...
                        ME.stack(1).name, ME.stack(1).line, ME.message);
                    uiwait(warndlg(errorMessage));
                end
            end
        end

        % Value changed function: CameraPreviewCheckBox
        function CameraPreviewCheckBoxValueChanged(app, event)
            value = app.CameraPreviewCheckBox.Value;
            app.imaging_flag = ~value;
        end

        % Button pushed function: MoveZstage12ThicknessButton
        function MoveZstage12ThicknessButtonPushed(app, event)
            start = app.focus_stage.query_position(0);
            app.focus_stage.set_position(0,start - app.SampleThickness.Value/2);

        end

        % Button pushed function: AcquireTimeLapseButton
        function AcquireTimeLapseButtonPushed(app, event)
            timelapse_freq_per_min = app.TimeLapseFrquencytimesperminEditField.Value;
            timelapse_num_frames = app.NumberofFramestoAcquireEditField.Value;
            app.imaging_flag = true; % make umaging a lot faster by keeping preview running but dont update graphics
            system_status_update(app, 'DONT CLICK!!!')
            app.timelapse_counter = 0;
            
            app.timelapse_handle = timer('Period',timelapse_freq_per_min*60,'ExecutionMode','fixedRate','TasksToExecute',timelapse_num_frames);
            app.timelapse_handle.TimerFcn = @app.timelapse_imaging;
            start(app.timelapse_handle)
            
        end

        % Selection change function: TabGroup3
        function TabGroup3SelectionChanged(app, event)
            selectedTab = app.TabGroup3.SelectedTab;
            disp(selectedTab)

            if strcmp(selectedTab.Title, 'Live View')
                closepreview(app.vid)
                Camera_preview(app, app.hImage);
                
            else strcmp(selectedTab.Title, 'Grid/ROI Imaging')
                closepreview(app.vid)
                Camera_preview(app, app.hImage3);
            end
        end

        % Button pushed function: ZeroZStageButton
        function ZeroZStageButtonPushed(app, event)
            disp('Set zero position on stage')
        end

        % Value changed function: CalibrationOffsetpolarizersEditField
        function CalibrationOffsetpolarizersEditFieldValueChanged(app, event)
            app.motor_properties = load("properties.mat").properties;  %% load in the properties file so we know which motor is which and some attributes that we want to be saveable
            
            value = app.CalibrationOffsetpolarizersEditField.Value;
            app.motor_properties.CCP_positions = app.motor_properties.CCP_positions - value;
            app.motor_properties.qBRM_positions = app.motor_properties.qBRM_positions - value;
            app.motor_properties.brightfield_positions = app.motor_properties.brightfield_positions - value;
        end

        % Button pushed function: SaveCalibrationOffsetButton
        function SaveCalibrationOffsetButtonPushed(app, event)
            app.motor_properties = load("properties.mat").properties;  %% load in the properties file so we know which motor is which and some attributes that we want to be saveable
            
            value = app.CalibrationOffsetpolarizersEditField.Value;
            app.motor_properties.Calibration_offset = value;
            save("C:\Users\BMOadmin2\Documents\Control_GUI\BRMscope\Properties\properties.mat");
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            disp('Adding ROI to ROISelection and saving all relevant information')
            
            if app.grid_exists
                z_planes = app.z_img_mesh;
            else
                uiwait(msgbox('No Grid Found for tiling. The current Z position will be used for the Tiling grid'),3)
                z_planes = ones(size(app.x_img_mesh))*app.focus_stage.query_position(0);
            end
            filename = sprintf('%s_exp%d%s_mag%s',app.FilenameEditField.Value,app.ExposureTime.Value,app.Exposure_units.Value,app.MagnificationDropDown.Items{app.MagnificationDropDown.Value});
            
            % save all relevant information for each ROI so that we can
            % save that later
            app.ROI_imaging_handle{app.ROI_counter} = {app.FilenameEditField.Value,app.ExposureTime.Value,app.Exposure_units.Value, app.MagnificationDropDown.Value,...
                app.x_img_mesh, app.y_img_mesh, z_planes, app.Saveas8bit.Value, app.FF_correction.Value, app.SampleThickness.Value, app.ZStackStepSize.Value, app.StitchingType.Value,...
                app.MicroscopeSetup.Value, app.AcquisitionType.Value};
            app.ROISelection.ItemsData = [app.ROISelection.ItemsData,length(app.ROI_imaging_handle)];
            app.ROISelection.Items = [app.ROISelection.Items, app.FilenameEditField.Value]; % append the new string to the ROI_selection label
            
            tmp_ROI_handle = app.ROI_imaging_handle;
            save(['C:\Users\BMOadmin2\Documents\Images\Default\', app.FilenameEditField.Value, '.mat'], 'tmp_ROI_handle')
            app.ROI_counter = app.ROI_counter + 1;
%             code needed to run ROI imaging 
%             1. update all values for the current ROI, Grid size, grid locations, filename, folder, etc.
%             2. loop through all ROIs in the cell array to actually take the images
%             

        end

        % Button pushed function: ResetROIsButton
        function ResetROIsButtonPushed(app, event)
            app.ROISelection.ItemsData = [];
            app.ROISelection.Items = {};
            app.ROI_imaging_handle = [];
            app.ROI_counter = 1;
        end

        % Button pushed function: UnselectButton_3
        function UnselectButton_3Pushed(app, event)
            app.ROISelection.Value = {};
        end

        % Button pushed function: RemoveROIButton
        function RemoveROIButtonPushed(app, event)
            disp('Need to remove current selection')
        end

        % Value changed function: ROISelection
        function ROISelectionValueChanged(app, event)
            value = app.ROISelection.Value;
            disp('When we select this ROI make sure to update all information')
                
            app.FilenameEditField.Value = app.ROI_imaging_handle{value}{1,1};
            app.ExposureTime.Value = app.ROI_imaging_handle{value}{1,2};
            app.Exposure_units.Value = app.ROI_imaging_handle{value}{1,3};
            app.MagnificationDropDown.Value = app.ROI_imaging_handle{value}{1,4};
            app.x_img_mesh = app.ROI_imaging_handle{value}{1,5};
            app.y_img_mesh = app.ROI_imaging_handle{value}{1,6};
            app.z_img_mesh = app.ROI_imaging_handle{value}{1,7};
            app.Grid_yentry.Value = size(app.x_img_mesh, 1);
            app.Grid_xentry.Value = size(app.x_img_mesh, 2);
            app.Saveas8bit.Value = app.ROI_imaging_handle{value}{1,8};
            app.FF_correction.Value = app.ROI_imaging_handle{value}{1,9};
            app.SampleThickness.Value = app.ROI_imaging_handle{value}{1,10};
            app.ZStackStepSize.Value = app.ROI_imaging_handle{value}{1,11};
            app.StitchingType.Value = app.ROI_imaging_handle{value}{1,12};
            app.MicroscopeSetup.Value = app.ROI_imaging_handle{value}{1,13};
            app.AcquisitionType.Value = app.ROI_imaging_handle{value}{1,14};

        end

        % Value changing function: SaveDirectory
        function SaveDirectoryValueChanging(app, event)
            changingValue = event.Value;
            app.main_save_folder;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisitonTab = uitab(app.TabGroup);
            app.AcquisitonTab.Title = 'Acquisiton ';
            app.TabGroup2 = uitabgroup(app.AcquisitonTab);
            app.CameraTab = uitab(app.TabGroup2);
            app.CameraTab.Title = 'Camera';
            app.CameraPropertiesPanel = uipanel(app.CameraTab);
            app.CameraPropertiesPanel.Title = 'Camera Properties:';
            app.WidthpxEditFieldLabel = uilabel(app.CameraPropertiesPanel);
            app.WidthpxEditFieldLabel.Text = 'Width (px)';
            app.WidthEditField = uieditfield(app.CameraPropertiesPanel, 'numeric');
            app.HeightpxEditFieldLabel = uilabel(app.CameraPropertiesPanel);
            app.HeightpxEditFieldLabel.Text = 'Height (px)';
            app.HeightEditField = uieditfield(app.CameraPropertiesPanel, 'numeric');
            app.CameraBinningDropDownLabel = uilabel(app.CameraPropertiesPanel);
            app.CameraBinningDropDownLabel.Text = 'Camera Binning';
            app.CameraBinningDropDown = uidropdown(app.CameraPropertiesPanel);
            app.CameraBinningDropDown.Items = {'1x1', '2x2'};
            app.CameraBinningDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraBinningDropDownValueChanged, true);
            app.CameraBinningDropDown.Value = '1x1';
            app.ROIPositionpxLabel = uilabel(app.CameraPropertiesPanel);
            app.ROIPositionpxLabel.Text = 'ROI Position (px):';
            app.X0Y0Label = uilabel(app.CameraPropertiesPanel);
            app.X0Y0Label.Text = 'X: 0 Y: 0 ';
            app.ResolutionLabel = uilabel(app.CameraPropertiesPanel);
            app.ResolutionLabel.Text = 'Resolution: 3200x2200';
            app.XOffsetEditFieldLabel = uilabel(app.CameraPropertiesPanel);
            app.XOffsetEditFieldLabel.Text = 'X-Offset';
            app.XOffsetEditField = uieditfield(app.CameraPropertiesPanel, 'numeric');
            app.XOffsetEditField.Limits = [0 3200];
            app.XOffsetEditField.Editable = 'off';
            app.YOffsetEditFieldLabel = uilabel(app.CameraPropertiesPanel);
            app.YOffsetEditFieldLabel.Text = 'Y-Offset';
            app.YOffsetEditField = uieditfield(app.CameraPropertiesPanel, 'numeric');
            app.YOffsetEditField.Limits = [0 2200];
            app.YOffsetEditField.Editable = 'off';
            app.Image = uiimage(app.CameraPropertiesPanel);
            app.ResetCameraButton = uibutton(app.CameraPropertiesPanel, 'push');
            app.ResetCameraButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCameraButtonPushed, true);
            app.ResetCameraButton.Text = 'Reset Camera';
            app.FOVmmLabel = uilabel(app.CameraPropertiesPanel);
            app.FOVmmLabel.Text = 'FOV (mm): (-,-)';
            app.AutoContrastDropDownLabel = uilabel(app.CameraPropertiesPanel);
            app.AutoContrastDropDownLabel.Text = 'Auto-Contrast';
            app.AutoContrastDropDown = uidropdown(app.CameraPropertiesPanel);
            app.AutoContrastDropDown.Items = {'OFF', 'ON-MANUAL', 'ON'};
            app.AutoContrastDropDown.ItemsData = [1 2 3];
            app.AutoContrastDropDown.ValueChangedFcn = createCallbackFcn(app, @AutoContrastDropDownValueChanged, true);
            app.AutoContrastDropDown.Value = 1;
            app.PreviewDownsamplingEditFieldLabel = uilabel(app.CameraPropertiesPanel);
            app.PreviewDownsamplingEditFieldLabel.Text = 'Preview Downsampling';
            app.PreviewDownsamplingEditField = uieditfield(app.CameraPropertiesPanel, 'numeric');
            app.PreviewDownsamplingEditField.ValueChangedFcn = createCallbackFcn(app, @PreviewDownsamplingEditFieldValueChanged, true);
            app.PreviewDownsamplingEditField.Value = 4;
            app.TurnOffPreviewAfterAcqusitionCheckBox = uicheckbox(app.CameraPropertiesPanel);
            app.TurnOffPreviewAfterAcqusitionCheckBox.ValueChangedFcn = createCallbackFcn(app, @TurnOffPreviewAfterAcqusitionCheckBoxValueChanged, true);
            app.TurnOffPreviewAfterAcqusitionCheckBox.Text = 'Turn Off Preview After Acqusition ';
            app.MotorTab = uitab(app.TabGroup2);
            app.MotorTab.Title = 'Motor ';
            app.StageControlPanel = uipanel(app.MotorTab);
            app.StageControlPanel.Title = 'Stage Control';
            app.x_coord_entry = uieditfield(app.StageControlPanel, 'text');
            app.x_coord_entry.Value = '55';
            app.y_coord_entry = uieditfield(app.StageControlPanel, 'text');
            app.y_coord_entry.Value = '37.5';
            app.text54 = uilabel(app.StageControlPanel);
            app.text54.Text = 'X-Pos';
            app.text54_2 = uilabel(app.StageControlPanel);
            app.text54_2.Text = 'Y-Pos';
            app.text54_3 = uilabel(app.StageControlPanel);
            app.text54_3.Text = 'Move to';
            app.text54_4 = uilabel(app.StageControlPanel);
            app.text54_4.Text = {'Current '; 'position'};
            app.x_coord_text = uilabel(app.StageControlPanel);
            app.x_coord_text.Text = 'NaN';
            app.y_coord_text = uilabel(app.StageControlPanel);
            app.y_coord_text.Text = 'NaN';
            app.MoveXYmotorButton = uibutton(app.StageControlPanel, 'push');
            app.MoveXYmotorButton.ButtonPushedFcn = createCallbackFcn(app, @MoveXYmotorButtonPushed, true);
            app.MoveXYmotorButton.Text = 'Move XY-motor';
            app.ResetXYStageButton = uibutton(app.StageControlPanel, 'push');
            app.ResetXYStageButton.Text = 'Reset XY Stage';
            app.MoveZstage12ThicknessButton = uibutton(app.StageControlPanel, 'push');
            app.MoveZstage12ThicknessButton.ButtonPushedFcn = createCallbackFcn(app, @MoveZstage12ThicknessButtonPushed, true);
            app.MoveZstage12ThicknessButton.Text = 'Move Z stage 1/2 Thickness';
            app.HomeXYStageButton = uibutton(app.StageControlPanel, 'push');
            app.HomeXYStageButton.ButtonPushedFcn = createCallbackFcn(app, @HomeXYStageButtonPushed, true);
            app.HomeXYStageButton.Text = 'Home XY Stage';
            app.LinearPolarizersPanel = uipanel(app.MotorTab);
            app.LinearPolarizersPanel.Title = 'Linear Polarizers:';
            app.ill_waveplate_lbl = uilabel(app.LinearPolarizersPanel);
            app.ill_waveplate_lbl.Text = '0°';
            app.ill_polarizer_lbl = uilabel(app.LinearPolarizersPanel);
            app.ill_polarizer_lbl.Text = '0°';
            app.CurrentposLabel = uilabel(app.LinearPolarizersPanel);
            app.CurrentposLabel.Text = '(Current pos )';
            app.Label = uilabel(app.LinearPolarizersPanel);
            app.Label.Text = '°';
            app.Label_2 = uilabel(app.LinearPolarizersPanel);
            app.Label_2.Text = '°';
            app.IlluminationWaveplateEditFieldLabel = uilabel(app.LinearPolarizersPanel);
            app.IlluminationWaveplateEditFieldLabel.Text = {'Illumination'; 'Waveplate'};
            app.IlluminationWaveplateEditField = uieditfield(app.LinearPolarizersPanel, 'numeric');
            app.IlluminationPolarizerEditFieldLabel = uilabel(app.LinearPolarizersPanel);
            app.IlluminationPolarizerEditFieldLabel.Text = {'Illumination'; 'Polarizer'};
            app.IlluminationPolarizerEditField = uieditfield(app.LinearPolarizersPanel, 'numeric');
            app.Rotate_ill_pol = uibutton(app.LinearPolarizersPanel, 'push');
            app.Rotate_ill_pol.ButtonPushedFcn = createCallbackFcn(app, @Rotate_ill_polButtonPushed, true);
            app.Rotate_ill_pol.Text = 'Rotate';
            app.Home_Rot_Motors = uibutton(app.LinearPolarizersPanel, 'push');
            app.Home_Rot_Motors.ButtonPushedFcn = createCallbackFcn(app, @Home_Rot_MotorsPushed, true);
            app.Home_Rot_Motors.Text = 'Home motors';
            app.det_waveplate_lbl = uilabel(app.LinearPolarizersPanel);
            app.det_waveplate_lbl.Text = '0°';
            app.Label_6 = uilabel(app.LinearPolarizersPanel);
            app.Label_6.Text = '°';
            app.DetectionWaveplateEditFieldLabel = uilabel(app.LinearPolarizersPanel);
            app.DetectionWaveplateEditFieldLabel.Text = {'Detection'; 'Waveplate'};
            app.DetectionWaveplateEditField = uieditfield(app.LinearPolarizersPanel, 'numeric');
            app.det_polarizer_lbl = uilabel(app.LinearPolarizersPanel);
            app.det_polarizer_lbl.Text = '0°';
            app.Label_7 = uilabel(app.LinearPolarizersPanel);
            app.Label_7.Text = '°';
            app.DetectionPolarizerEditFieldLabel = uilabel(app.LinearPolarizersPanel);
            app.DetectionPolarizerEditFieldLabel.Text = {'Detection'; 'Polarizer'};
            app.DetectionPolarizerEditField = uieditfield(app.LinearPolarizersPanel, 'numeric');
            app.SavedLocationsListBoxLabel = uilabel(app.MotorTab);
            app.SavedLocationsListBoxLabel.Text = 'Saved Locations';
            app.SavedLocationsListBox = uilistbox(app.MotorTab);
            app.SavedLocationsListBox.Items = {'Home - (55,37.5)'};
            app.SavedLocationsListBox.ItemsData = 1;
            app.SavedLocationsListBox.ValueChangedFcn = createCallbackFcn(app, @SavedLocationsListBoxValueChanged, true);
            app.SavedLocationsListBox.Value = 1;
            app.SaveLocationButton = uibutton(app.MotorTab, 'push');
            app.SaveLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLocationButtonPushed, true);
            app.SaveLocationButton.Text = 'Save Location';
            app.LocationLabelEditFieldLabel = uilabel(app.MotorTab);
            app.LocationLabelEditFieldLabel.Text = 'Location Label';
            app.LocationLabelEditField = uieditfield(app.MotorTab, 'text');
            app.ClearAllButton = uibutton(app.MotorTab, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.UnselectButton = uibutton(app.MotorTab, 'push');
            app.UnselectButton.ButtonPushedFcn = createCallbackFcn(app, @UnselectButtonPushed, true);
            app.UnselectButton.Text = 'Unselect';
            app.SampleTab = uitab(app.TabGroup2);
            app.SampleTab.Title = 'Sample';
            app.SampleParametersPanel = uipanel(app.SampleTab);
            app.SampleParametersPanel.Title = 'Sample Parameters';
            app.SampleThicknessEditFieldLabel = uilabel(app.SampleParametersPanel);
            app.SampleThicknessEditFieldLabel.Text = 'Sample Thickness';
            app.SampleThickness = uieditfield(app.SampleParametersPanel, 'numeric');
            app.SampleThickness.Limits = [-10 Inf];
            app.SampleThickness.Value = 30;
            app.mLabel = uilabel(app.SampleParametersPanel);
            app.mLabel.Text = 'µm';
            app.mLabel_2 = uilabel(app.SampleParametersPanel);
            app.mLabel_2.Text = 'µm';
            app.SamplethicknessOffsetLabel = uilabel(app.SampleParametersPanel);
            app.SamplethicknessOffsetLabel.Text = '*Sample thickness ± Offset  ';
            app.mLabel_3 = uilabel(app.SampleParametersPanel);
            app.mLabel_3.Text = 'µm';
            app.ZStackOffsetLabel = uilabel(app.SampleParametersPanel);
            app.ZStackOffsetLabel.Text = 'Z-Stack Offset';
            app.ZStackoffset = uieditfield(app.SampleParametersPanel, 'numeric');
            app.ZStackStepSizeLabel = uilabel(app.SampleParametersPanel);
            app.ZStackStepSizeLabel.Enable = 'off';
            app.ZStackStepSizeLabel.Text = 'Z-Stack Step Size';
            app.ZStackStepSize = uieditfield(app.SampleParametersPanel, 'numeric');
            app.ZStackStepSize.Limits = [0 30];
            app.ZStackStepSize.Value = 1;
            app.SampleNameEditFieldLabel = uilabel(app.SampleParametersPanel);
            app.SampleNameEditFieldLabel.Text = 'Sample Name ';
            app.SampleNameEditField = uieditfield(app.SampleParametersPanel, 'text');
            app.SampleNameEditField.Value = 'AM---';
            app.SampleNotestxtcreatedinFileTextAreaLabel = uilabel(app.SampleParametersPanel);
            app.SampleNotestxtcreatedinFileTextAreaLabel.Text = 'Sample Notes (.txt created in File)';
            app.SampleNotes = uitextarea(app.SampleParametersPanel);
            app.SampleNotes.Value = {'"No Notes"'};
            app.qBRMAngleValuesseperatedbyLabel = uilabel(app.SampleParametersPanel);
            app.qBRMAngleValuesseperatedbyLabel.Text = 'qBRM Angle Values (''0,60,120'' or ''0:60:120'')';
            app.qBRM_angles_text = uitextarea(app.SampleParametersPanel);
            app.qBRM_angles_text.ValueChangedFcn = createCallbackFcn(app, @qBRM_angles_textValueChanged, true);
            app.qBRM_angles_text.Value = {'0,60,120'};
            app.ZStackSlowCheckBox = uicheckbox(app.SampleParametersPanel);
            app.ZStackSlowCheckBox.Text = 'Z-Stack Slow';
            app.GridTab = uitab(app.TabGroup2);
            app.GridTab.Title = 'Grid';
            app.Grid_axis = uiaxes(app.GridTab);
            title(app.Grid_axis, 'Tiled Imaging Grid')
            xlabel(app.Grid_axis, 'X-Position')
            ylabel(app.Grid_axis, 'Y-Position')
            zlabel(app.Grid_axis, 'Z')
            app.Grid_axis.XLim = [0 2];
            app.Grid_axis.YLim = [0 2];
            app.Grid_axis.YDir = 'reverse';
            app.Grid_axis.XTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.Grid_axis.XTickLabel = '';
            app.Grid_axis.YTick = [0 0.5 1 1.5 2];
            app.Grid_axis.YTickLabel = '';
            app.Grid_axis.ButtonDownFcn = createCallbackFcn(app, @Grid_axisButtonDown, true);
            app.SitchingParametersPanel = uipanel(app.GridTab);
            app.SitchingParametersPanel.Title = 'Sitching Parameters';
            app.GridSizeLabel = uilabel(app.SitchingParametersPanel);
            app.GridSizeLabel.Text = 'Grid Size: ';
            app.Grid_xentry = uieditfield(app.SitchingParametersPanel, 'numeric');
            app.Grid_xentry.ValueChangedFcn = createCallbackFcn(app, @Grid_xentryValueChanged, true);
            app.Grid_xentry.Value = 2;
            app.xEditFieldLabel = uilabel(app.SitchingParametersPanel);
            app.xEditFieldLabel.Text = 'x';
            app.Grid_yentry = uieditfield(app.SitchingParametersPanel, 'numeric');
            app.Grid_yentry.ValueChangedFcn = createCallbackFcn(app, @Grid_xentryValueChanged, true);
            app.Grid_yentry.Value = 2;
            app.StitchingTypeDropDownLabel = uilabel(app.SitchingParametersPanel);
            app.StitchingTypeDropDownLabel.Text = 'Stitching Type';
            app.StitchingType = uidropdown(app.SitchingParametersPanel);
            app.StitchingType.Items = {'Single Plane', 'Multi-Plane'};
            app.StitchingType.ItemsData = [1 2];
            app.StitchingType.Value = 1;
            app.GridSelectionDropDownLabel = uilabel(app.SitchingParametersPanel);
            app.GridSelectionDropDownLabel.Text = 'Grid Selection';
            app.GridSelectionDropDown = uidropdown(app.SitchingParametersPanel);
            app.GridSelectionDropDown.Items = {'Single Corner'};
            app.GridSelectionDropDown.Value = 'Single Corner';
            app.SetUpperLeftButton = uibutton(app.SitchingParametersPanel, 'push');
            app.SetUpperLeftButton.ButtonPushedFcn = createCallbackFcn(app, @SetUpperLeftButtonPushed, true);
            app.SetUpperLeftButton.Text = 'Set Upper Left';
            app.ULLabel = uilabel(app.SitchingParametersPanel);
            app.ULLabel.Text = 'UL:';
            app.UL_Location_lbl = uilabel(app.SitchingParametersPanel);
            app.UL_Location_lbl.Text = '(55,37.5)';
            app.LRLabel = uilabel(app.SitchingParametersPanel);
            app.LRLabel.Text = 'LR:';
            app.LR_Location = uilabel(app.SitchingParametersPanel);
            app.LR_Location.Text = 'NA';
            app.ExposureTime1Label = uilabel(app.SitchingParametersPanel);
            app.ExposureTime1Label.Text = 'Exposure Time (1):';
            app.ExposureTime1 = uieditfield(app.SitchingParametersPanel, 'numeric');
            app.ExposureTime1.Limits = [0 5000];
            app.ExposureTime1.Editable = 'off';
            app.ExposureTime1.Value = 5;
            app.ExposureTime2EditFieldLabel = uilabel(app.SitchingParametersPanel);
            app.ExposureTime2EditFieldLabel.Text = 'Exposure Time (2):';
            app.ExposureTime2 = uieditfield(app.SitchingParametersPanel, 'numeric');
            app.ExposureTime2.Limits = [0 1500];
            app.msLabel = uilabel(app.SitchingParametersPanel);
            app.msLabel.Text = 'ms';
            app.msLabel_2 = uilabel(app.SitchingParametersPanel);
            app.msLabel_2.Text = 'ms';
            app.UnSelectPositionButton = uibutton(app.SitchingParametersPanel, 'push');
            app.UnSelectPositionButton.ButtonPushedFcn = createCallbackFcn(app, @UnSelectPositionButtonPushed, true);
            app.UnSelectPositionButton.Text = 'Un-Select Position';
            app.SelectPositionButton = uibutton(app.SitchingParametersPanel, 'push');
            app.SelectPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPositionButtonPushed, true);
            app.SelectPositionButton.Text = 'Select Position';
            app.ResetGridButton = uibutton(app.SitchingParametersPanel, 'push');
            app.ResetGridButton.ButtonPushedFcn = createCallbackFcn(app, @Grid_xentryValueChanged, true);
            app.ResetGridButton.Text = 'Reset Grid';
            app.TileOverlapLabel = uilabel(app.SitchingParametersPanel);
            app.TileOverlapLabel.Text = 'Tile Overlap (%)';
            app.TileOverlapEditField = uieditfield(app.SitchingParametersPanel, 'numeric');
            app.TileOverlapEditField.ValueChangedFcn = createCallbackFcn(app, @TileOverlapEditFieldValueChanged, true);
            app.TileOverlapEditField.Value = 10;
            app.LoadAcquisitionButton = uibutton(app.SitchingParametersPanel, 'push');
            app.LoadAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAcquisitionButtonPushed, true);
            app.LoadAcquisitionButton.Text = 'Load Acquisition';
            app.LoadGridButton = uibutton(app.SitchingParametersPanel, 'push');
            app.LoadGridButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGridButtonPushed, true);
            app.LoadGridButton.Text = 'Load Grid';
            app.create_img_sequence_folders = uicheckbox(app.SitchingParametersPanel);
            app.create_img_sequence_folders.Text = 'Create Img-Seq';
            app.AddROIButton = uibutton(app.SitchingParametersPanel, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Text = 'Add ROI';
            app.ROIImagingCheckBox = uicheckbox(app.SitchingParametersPanel);
            app.ROIImagingCheckBox.Text = 'ROI Imaging';
            app.ROITab = uitab(app.TabGroup2);
            app.ROITab.Title = 'ROI ';
            app.ROISelection = uilistbox(app.ROITab);
            app.ROISelection.Items = {};
            app.ROISelection.ValueChangedFcn = createCallbackFcn(app, @ROISelectionValueChanged, true);
            app.ROISelection.Value = {};
            app.ResetROIsButton = uibutton(app.ROITab, 'push');
            app.ResetROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetROIsButtonPushed, true);
            app.ResetROIsButton.Text = 'Reset ROIs';
            app.ROIInformationLabel = uilabel(app.ROITab);
            app.ROIInformationLabel.Text = 'ROI Information';
            app.SampleNotes_2 = uitextarea(app.ROITab);
            app.SampleNotes_2.Value = {'Need to add info about size and location etc. '};
            app.SelectedROILabel = uilabel(app.ROITab);
            app.SelectedROILabel.Text = 'Selected ROI';
            app.RemoveROIButton = uibutton(app.ROITab, 'push');
            app.RemoveROIButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveROIButtonPushed, true);
            app.RemoveROIButton.Text = 'Remove ROI';
            app.UnselectButton_3 = uibutton(app.ROITab, 'push');
            app.UnselectButton_3.ButtonPushedFcn = createCallbackFcn(app, @UnselectButton_3Pushed, true);
            app.UnselectButton_3.Text = 'Unselect';
            app.TimelapseTab = uitab(app.TabGroup2);
            app.TimelapseTab.Title = 'Timelapse';
            app.TimeLapseFrquencytimesperminEditFieldLabel = uilabel(app.TimelapseTab);
            app.TimeLapseFrquencytimesperminEditFieldLabel.Text = 'Time Lapse Frquency (times per min)';
            app.TimeLapseFrquencytimesperminEditField = uieditfield(app.TimelapseTab, 'numeric');
            app.TimeLapseFrquencytimesperminEditField.Value = 1;
            app.AcquireTimeLapseButton = uibutton(app.TimelapseTab, 'push');
            app.AcquireTimeLapseButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireTimeLapseButtonPushed, true);
            app.AcquireTimeLapseButton.Text = 'Acquire Time Lapse';
            app.NumberofFramestoAcquireEditFieldLabel = uilabel(app.TimelapseTab);
            app.NumberofFramestoAcquireEditFieldLabel.Text = 'Number of Frames to Acquire';
            app.NumberofFramestoAcquireEditField = uieditfield(app.TimelapseTab, 'numeric');
            app.NumberofFramestoAcquireEditField.Value = 30;
            app.CCPStackExposureEditFieldLabel = uilabel(app.TimelapseTab);
            app.CCPStackExposureEditFieldLabel.Text = 'CCP Stack Exposure';
            app.CCPStackExposureEditField = uieditfield(app.TimelapseTab, 'numeric');
            app.CCPStackExposureEditField.Value = 1;
            app.qBRMStackExposureEditFieldLabel = uilabel(app.TimelapseTab);
            app.qBRMStackExposureEditFieldLabel.Text = 'qBRM Stack Exposure';
            app.qBRMStackExposureEditField = uieditfield(app.TimelapseTab, 'numeric');
            app.qBRMStackExposureEditField.Value = 1;
            app.Exposure_units_2 = uidropdown(app.TimelapseTab);
            app.Exposure_units_2.Items = {'ms', 'us'};
            app.Exposure_units_2.Value = 'ms';
            app.Exposure_units_3 = uidropdown(app.TimelapseTab);
            app.Exposure_units_3.Items = {'ms', 'us'};
            app.Exposure_units_3.Value = 'ms';
            app.SystemStatusPanel = uipanel(app.AcquisitonTab);
            app.SystemStatusPanel.Title = 'System Status:';
            app.Progress_Bar = uiaxes(app.SystemStatusPanel);
            title(app.Progress_Bar, '0%')
            app.Progress_Bar.XLim = [0 1];
            app.Progress_Bar.YLim = [0 1];
            app.Progress_Bar.XTick = [];
            app.Progress_Bar.XTickLabel = '';
            app.Progress_Bar.YTick = [];
            app.Progress_Bar.YTickLabel = '';
            app.GridSizeLabel_2 = uilabel(app.SystemStatusPanel);
            app.GridSizeLabel_2.Text = 'Grid Size: ';
            app.Grid_size_status = uilabel(app.SystemStatusPanel);
            app.Grid_size_status.Text = '2x2';
            app.System_status = uilabel(app.SystemStatusPanel);
            app.System_status.Text = 'Standby';
            app.CurrentOperationLabel = uilabel(app.SystemStatusPanel);
            app.CurrentOperationLabel.Text = 'Current Operation:';
            app.MicroscopeSetupLabel = uilabel(app.SystemStatusPanel);
            app.MicroscopeSetupLabel.Text = 'Microscope Setup:';
            app.MicroscopeSetup = uidropdown(app.SystemStatusPanel);
            app.MicroscopeSetup.Items = {'CCP-BRM', 'qBRM', 'Brightfield Microscopy', 'ROI Imaging'};
            app.MicroscopeSetup.ItemsData = {'1', '2', '3', '4'};
            app.MicroscopeSetup.ValueChangedFcn = createCallbackFcn(app, @MicroscopeSetupValueChanged, true);
            app.MicroscopeSetup.Value = '1';
            app.Label_3 = uilabel(app.SystemStatusPanel);
            app.Label_3.Text = '......................................................................................................................................................................................................';
            app.AcquisitionTypeLabel = uilabel(app.SystemStatusPanel);
            app.AcquisitionTypeLabel.Text = 'Acquisition Type:';
            app.AcquisitionType = uidropdown(app.SystemStatusPanel);
            app.AcquisitionType.Items = {'Single Image', 'Single Z-Stack', 'Stitching'};
            app.AcquisitionType.ItemsData = {'1', '2', '3'};
            app.AcquisitionType.Value = '1';
            app.Stage_pos = uilabel(app.SystemStatusPanel);
            app.Stage_pos.Text = 'X: 0 Y: 0';
            app.MagnificationDropDownLabel = uilabel(app.SystemStatusPanel);
            app.MagnificationDropDownLabel.Text = 'Magnification:';
            app.MagnificationDropDown = uidropdown(app.SystemStatusPanel);
            app.MagnificationDropDown.Items = {'4X', '10X', '20X', '40X', '60X'};
            app.MagnificationDropDown.ItemsData = [1 2 3 4 5];
            app.MagnificationDropDown.ValueChangedFcn = createCallbackFcn(app, @Grid_xentryValueChanged, true);
            app.MagnificationDropDown.Value = 3;
            app.StagePositionLabel = uilabel(app.SystemStatusPanel);
            app.StagePositionLabel.Text = 'Stage Position:';
            app.Label_5 = uilabel(app.SystemStatusPanel);
            app.Label_5.Text = '......................................................................................................................................................................................................';
            app.Focus_MotorPosition = uilabel(app.SystemStatusPanel);
            app.Focus_MotorPosition.Text = '0 µm';
            app.ZPositionLabel = uilabel(app.SystemStatusPanel);
            app.ZPositionLabel.Text = 'Z Position:';
            app.Lamp = uilamp(app.SystemStatusPanel);
            app.AcquireImagesButton = uibutton(app.SystemStatusPanel, 'push');
            app.AcquireImagesButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireImagesButtonPushed, true);
            app.AcquireImagesButton.Text = 'Acquire Image(s)';
            app.FilenameLabel = uilabel(app.SystemStatusPanel);
            app.FilenameLabel.Text = 'Filename:';
            app.FilenameEditField = uieditfield(app.SystemStatusPanel, 'text');
            app.ExpTimemsLabel = uilabel(app.SystemStatusPanel);
            app.ExpTimemsLabel.Text = 'Exposure Time ';
            app.ExposureTime = uieditfield(app.SystemStatusPanel, 'numeric');
            app.ExposureTime.Limits = [0 5000];
            app.ExposureTime.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeValueChanged, true);
            app.ExposureTime.Value = 5;
            app.Exposure_units = uidropdown(app.SystemStatusPanel);
            app.Exposure_units.Items = {'ms', 'us'};
            app.Exposure_units.ValueChangedFcn = createCallbackFcn(app, @Exposure_unitsValueChanged, true);
            app.Exposure_units.Value = 'ms';
            app.CancelAcquisitonButton = uibutton(app.SystemStatusPanel, 'push');
            app.CancelAcquisitonButton.ButtonPushedFcn = createCallbackFcn(app, @CancelAcquisitonButtonPushed, true);
            app.CancelAcquisitonButton.Text = 'Cancel Acquisiton';
            app.image_progresslbl = uilabel(app.SystemStatusPanel);
            app.image_progresslbl.Text = 'Image: 0/0';
            app.FF_correction = uicheckbox(app.SystemStatusPanel);
            app.FF_correction.Text = 'Flatfield Correction';
            app.Saveas8bit = uicheckbox(app.SystemStatusPanel);
            app.Saveas8bit.Text = 'Save as 8-bit';
            app.FFFramesEditFieldLabel = uilabel(app.SystemStatusPanel);
            app.FFFramesEditFieldLabel.Text = 'FF Frames';
            app.FFFramesEditField = uieditfield(app.SystemStatusPanel, 'numeric');
            app.FFFramesEditField.Value = 10;
            app.ZeroZStageButton = uibutton(app.SystemStatusPanel, 'push');
            app.ZeroZStageButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroZStageButtonPushed, true);
            app.ZeroZStageButton.Text = 'Zero Z-Stage';
            app.AutoStitchCheckBox = uicheckbox(app.SystemStatusPanel);
            app.AutoStitchCheckBox.Text = 'Auto-Stitch';
            app.FrameAveragingEditFieldLabel = uilabel(app.SystemStatusPanel);
            app.FrameAveragingEditFieldLabel.Text = 'Frame Averaging';
            app.FrameAveragingEditField = uieditfield(app.SystemStatusPanel, 'numeric');
            app.FrameAveragingEditField.Value = 1;
            app.TabGroup3 = uitabgroup(app.AcquisitonTab);
            app.TabGroup3.SelectionChangedFcn = createCallbackFcn(app, @TabGroup3SelectionChanged, true);
            app.LiveViewTab = uitab(app.TabGroup3);
            app.LiveViewTab.Title = 'Live View';
            app.Image_axis = uiaxes(app.LiveViewTab);
            zlabel(app.Image_axis, 'Z')
            app.Image_axis.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Image_axis.ButtonDownFcn = createCallbackFcn(app, @Image_axisButtonDown, true);
            app.Histogram_axis = uiaxes(app.LiveViewTab);
            zlabel(app.Histogram_axis, 'Z')
            app.Histogram_axis.XLim = [0 256];
            app.Histogram_axis.XTick = [0 127 255];
            app.Histogram_axis.XTickLabel = {'0'; '127'; '255'};
            app.Histogram_axis.YTick = [];
            app.FPSLabel = uilabel(app.LiveViewTab);
            app.FPSLabel.Text = 'FPS:';
            app.StandardDeviationButton = uibutton(app.LiveViewTab, 'push');
            app.StandardDeviationButton.ButtonPushedFcn = createCallbackFcn(app, @StandardDeviationButtonPushed, true);
            app.StandardDeviationButton.Text = 'Standard Deviation';
            app.STDLabel = uilabel(app.LiveViewTab);
            app.STDLabel.Text = 'NaN';
            app.DistanceLabel = uilabel(app.LiveViewTab);
            app.DistanceLabel.Text = 'Distance: ';
            app.CameraPreviewCheckBox = uicheckbox(app.LiveViewTab);
            app.CameraPreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @CameraPreviewCheckBoxValueChanged, true);
            app.CameraPreviewCheckBox.Text = 'Camera Preview';
            app.CameraPreviewCheckBox.Value = true;
            app.GridROIImagingTab = uitab(app.TabGroup3);
            app.GridROIImagingTab.Title = 'Grid/ROI Imaging';
            app.Image_axis_2 = uiaxes(app.GridROIImagingTab);
            zlabel(app.Image_axis_2, 'Z')
            app.Image_axis_2.XTick = [];
            app.Image_axis_2.YTick = [];
            app.Image_axis_2.ButtonDownFcn = createCallbackFcn(app, @Image_axis_2ButtonDown, true);
            app.Image_axis_3 = uiaxes(app.GridROIImagingTab);
            zlabel(app.Image_axis_3, 'Z')
            app.Image_axis_3.XTick = [];
            app.Image_axis_3.YTick = [];
            app.Grid_axis_2 = uiaxes(app.GridROIImagingTab);
            title(app.Grid_axis_2, 'Tiled Imaging Grid')
            xlabel(app.Grid_axis_2, 'X-Position')
            ylabel(app.Grid_axis_2, 'Y-Position')
            zlabel(app.Grid_axis_2, 'Z')
            app.Grid_axis_2.XLim = [0 2];
            app.Grid_axis_2.YLim = [0 2];
            app.Grid_axis_2.YDir = 'reverse';
            app.Grid_axis_2.XTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.Grid_axis_2.XTickLabel = '';
            app.Grid_axis_2.YTick = [0 0.5 1 1.5 2];
            app.Grid_axis_2.YTickLabel = '';
            app.LoadWidefieldImageButton = uibutton(app.GridROIImagingTab, 'push');
            app.LoadWidefieldImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWidefieldImageButtonPushed, true);
            app.LoadWidefieldImageButton.Text = 'Load Widefield Image';
            app.DrawTissueROIButton = uibutton(app.GridROIImagingTab, 'push');
            app.DrawTissueROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawTissueROIButtonPushed, true);
            app.DrawTissueROIButton.Text = 'Draw Tissue ROI';
            app.ROIImagingButton = uibutton(app.GridROIImagingTab, 'push');
            app.ROIImagingButton.ButtonPushedFcn = createCallbackFcn(app, @ROIImagingButtonPushed, true);
            app.ROIImagingButton.Text = 'ROI Imaging';
            app.ROISelectionListBoxLabel = uilabel(app.GridROIImagingTab);
            app.ROISelectionListBoxLabel.Text = 'ROI Selection';
            app.ROISelectionListBox = uilistbox(app.GridROIImagingTab);
            app.ROISelectionListBox.Items = {};
            app.ROISelectionListBox.Value = {};
            app.SetUpperLeftButton_2 = uibutton(app.GridROIImagingTab, 'push');
            app.SetUpperLeftButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetUpperLeftButton_2Pushed, true);
            app.SetUpperLeftButton_2.Text = 'Set Upper Left';
            app.ExlcudeImagesoutsideROICheckBox = uicheckbox(app.GridROIImagingTab);
            app.ExlcudeImagesoutsideROICheckBox.Text = 'Exlcude Images outside ROI';
            app.ExlcudeImagesoutsideROICheckBox.Value = true;
            app.BrowseButton = uibutton(app.AcquisitonTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.SaveDirLabel = uilabel(app.AcquisitonTab);
            app.SaveDirLabel.Text = 'C:\Users\BMOadmin2\Documents\Images\';
            app.SaveDirectoryLabel = uilabel(app.AcquisitonTab);
            app.SaveDirectoryLabel.Text = 'Save Directory:     ';
            app.SaveDirectory = uieditfield(app.AcquisitonTab, 'text');
            app.SaveDirectory.ValueChangingFcn = createCallbackFcn(app, @SaveDirectoryValueChanging, true);
            app.SaveDirectory.Value = 'Default';
            app.CalibratethePolarizationOpticsButton = uibutton(app.AcquisitonTab, 'push');
            app.CalibratethePolarizationOpticsButton.ButtonPushedFcn = createCallbackFcn(app, @CalibratethePolarizationOpticsButtonPushed, true);
            app.CalibratethePolarizationOpticsButton.Text = 'Calibrate the Polarization Optics';
            app.ClibrationOffsetButton = uibutton(app.AcquisitonTab, 'push');
            app.ClibrationOffsetButton.ButtonPushedFcn = createCallbackFcn(app, @ClibrationOffsetButtonPushed, true);
            app.ClibrationOffsetButton.Text = 'Clibration Offset';
            app.AutosolveqBRMCheckBox = uicheckbox(app.AcquisitonTab);
            app.AutosolveqBRMCheckBox.Text = 'Auto-solve qBRM';
            app.ChangeObjectiveButton = uibutton(app.AcquisitonTab, 'push');
            app.ChangeObjectiveButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeObjectiveButtonPushed, true);
            app.ChangeObjectiveButton.Enable = 'off';
            app.ChangeObjectiveButton.Text = 'Change Objective';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.CalibrationOffsetpolarizersEditFieldLabel = uilabel(app.SettingsTab);
            app.CalibrationOffsetpolarizersEditFieldLabel.Text = 'Calibration Offset (polarizers)';
            app.CalibrationOffsetpolarizersEditField = uieditfield(app.SettingsTab, 'numeric');
            app.CalibrationOffsetpolarizersEditField.ValueChangedFcn = createCallbackFcn(app, @CalibrationOffsetpolarizersEditFieldValueChanged, true);
            app.SaveCalibrationOffsetButton = uibutton(app.SettingsTab, 'push');
            app.SaveCalibrationOffsetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCalibrationOffsetButtonPushed, true);
            app.SaveCalibrationOffsetButton.Text = 'Save Calibration Offset';
            app.MotorInformationTab = uitab(app.TabGroup);
            app.MotorInformationTab.Title = 'Motor Information';
            app.RotationMotorsPanel = uipanel(app.MotorInformationTab);
            app.RotationMotorsPanel.Title = 'Rotation Motors';
            app.Motor_info_lbl = uilabel(app.RotationMotorsPanel);
            app.Motor_info_lbl.Text = {'Motor Label: '; 'Device Address: '; 'Device Type:  '; 'Serial Num:  '; 'Mfg. Year:                  '; 'Firmware:  '; 'Travel (mm):  '; 'Pulses/M.U.:  '; 'Pulse/Deg: '};
            app.MotorInfoListBox = uilistbox(app.RotationMotorsPanel);
            app.MotorInfoListBox.Items = {'Motor 1', 'Motor 2', 'Motor 3', 'Motor 4'};
            app.MotorInfoListBox.ItemsData = [1 2 3 4];
            app.MotorInfoListBox.ValueChangedFcn = createCallbackFcn(app, @MotorInfoListBoxValueChanged, true);
            app.MotorInfoListBox.Value = 1;
            app.RotationMotorsPanel_2 = uipanel(app.MotorInformationTab);
            app.RotationMotorsPanel_2.Title = 'Rotation Motors';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BiRscope

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end