classdef MCM3001_motor_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ChangeObjectivesButton    matlab.ui.control.Button
        ChangingObjectivesLabel   matlab.ui.control.Label
        umZstackButton            matlab.ui.control.Button
        LowerMotorButton          matlab.ui.control.Button
        SetZeroButton             matlab.ui.control.Button
        StepSizemmEditField       matlab.ui.control.NumericEditField
        StepSizemmEditFieldLabel  matlab.ui.control.Label
        MoveRelativeButton        matlab.ui.control.Button
        PositionmmEditField       matlab.ui.control.NumericEditField
        PositionmmEditFieldLabel  matlab.ui.control.Label
        MoveMotorButton           matlab.ui.control.Button
        MotorPositionum0Label     matlab.ui.control.Label
    end

    
    properties (Access = private)
        focus_stage; % Description
        timer_handle;
    end
    
    methods (Access = private)
        
        function get_MCM_position(app, obj, event)
            app.MotorPositionum0Label.Text = sprintf('Motor Position (um): %5.6f',app.focus_stage.query_position(0));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("C:\Users\BMOadmin2\Documents\Control_GUI")

            app.focus_stage = MCM3001_motor("COM5",0);
            
            app.timer_handle = timer('Period',0.075,'ExecutionMode','fixedSpacing','TasksToExecute',inf,'BusyMode','drop');
            app.timer_handle.TimerFcn = @app.get_MCM_position;
            app.focus_stage.query_position(0)
            start(app.timer_handle)

        end

        % Button pushed function: SetZeroButton
        function SetZeroButtonPushed(app, event)
            app.focus_stage.set_encoder_zero(0)
        end

        % Button pushed function: LowerMotorButton
        function LowerMotorButtonPushed(app, event)
            app.focus_stage.set_position(0,1000) % set stage to a safe location of 1000 microns above the 0 point
        end

        % Button pushed function: umZstackButton
        function umZstackButtonPushed(app, event)
            
            tic
            current = app.focus_stage.query_position(0);
            toc
            tic
            for i = 1:30
                app.focus_stage.set_position(0,current+i);
                pause(0.01)
            end
            toc
        end

        % Button pushed function: ChangeObjectivesButton
        function ChangeObjectivesButtonPushed(app, event)
            app.ChangingObjectivesLabel.Visible = 'on';
            current = app.focus_stage.query_position(0);
            
            app.focus_stage.set_position(0,1000) % set stage to a safe location of 1000 microns above the 0 point
            pause(3)
            app.ChangingObjectivesLabel.Visible = 'off';
            app.focus_stage.set_position(0, current)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.timer_handle)
            delete(app.focus_stage)
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MotorPositionum0Label = uilabel(app.UIFigure);
            app.MotorPositionum0Label.Text = 'Motor Position (um): 0';
            app.MoveMotorButton = uibutton(app.UIFigure, 'push');
            app.MoveMotorButton.Text = 'Move Motor';
            app.PositionmmEditFieldLabel = uilabel(app.UIFigure);
            app.PositionmmEditFieldLabel.Text = 'Position (mm)';
            app.PositionmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MoveRelativeButton = uibutton(app.UIFigure, 'push');
            app.MoveRelativeButton.Text = 'Move Relative ';
            app.StepSizemmEditFieldLabel = uilabel(app.UIFigure);
            app.StepSizemmEditFieldLabel.Text = 'Step Size (mm)';
            app.StepSizemmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetZeroButton = uibutton(app.UIFigure, 'push');
            app.SetZeroButton.ButtonPushedFcn = createCallbackFcn(app, @SetZeroButtonPushed, true);
            app.SetZeroButton.Text = 'Set Zero';
            app.LowerMotorButton = uibutton(app.UIFigure, 'push');
            app.LowerMotorButton.ButtonPushedFcn = createCallbackFcn(app, @LowerMotorButtonPushed, true);
            app.LowerMotorButton.Text = 'Lower Motor ';
            app.umZstackButton = uibutton(app.UIFigure, 'push');
            app.umZstackButton.ButtonPushedFcn = createCallbackFcn(app, @umZstackButtonPushed, true);
            app.umZstackButton.Text = '30 um Z stack ';
            app.ChangingObjectivesLabel = uilabel(app.UIFigure);
            app.ChangingObjectivesLabel.Visible = 'off';
            app.ChangingObjectivesLabel.Text = 'Changing Objectives...';
            app.ChangeObjectivesButton = uibutton(app.UIFigure, 'push');
            app.ChangeObjectivesButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeObjectivesButtonPushed, true);
            app.ChangeObjectivesButton.Text = 'Change Objectives ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MCM3001_motor_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end