classdef Motor_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        XEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        MovebyXdegreesButton           matlab.ui.control.Button
        MotorInfoPanel                 matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        MotorInfoListBox               matlab.ui.control.ListBox
        MotorInfoListBoxLabel          matlab.ui.control.Label
        MoveIllPolarizerby180degreesButton  matlab.ui.control.Button
        DetectionWaveplateDropDown     matlab.ui.control.DropDown
        DetectionWaveplateDropDownLabel  matlab.ui.control.Label
        DetectionPolarizerDropDown     matlab.ui.control.DropDown
        DetectionPolarizerDropDownLabel  matlab.ui.control.Label
        IlluminationWaveplateDropDown  matlab.ui.control.DropDown
        IlluminationWaveplateDropDownLabel  matlab.ui.control.Label
        IlluminationPolarizerDropDown  matlab.ui.control.DropDown
        IlluminationPolarizerDropDownLabel  matlab.ui.control.Label
        DisconnectAllMotorsButton      matlab.ui.control.Button
        HomeallmotorsButton            matlab.ui.control.Button
        CrossPolarizersButton          matlab.ui.control.Button
        ConnectMotorsButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        motor_struct = struct([]); % Description
        
        % handles for each of the rotation stages (with type of motor)
        illumination_pol;
        illumination_waveplate;
        detection_waveplate;
        detection_pol;

        motor_properties;

    end
    
    methods (Access = private)
        
        function motor_info(app, motor_number)
            values = app.motor_struct(motor_number).motor_handler.DEVICE_SPECS;
            motor_label = app.motor_struct(motor_number).motor_handler.motor_label;
            app.Label.Text = sprintf(['Motor Label: %s \nDevice Address: %s \nDevice Type: %s \nSerial Num: %s \nMfg. Year: %s' ...
                ' \nFirmware: %s \nTavel (mm): %5.1f \nPulses/M.U.: %5.1f \nPulse/Deg: %5.1f \n'],motor_label, values.FULL_ADDRESS, values.devicetype, ...
                values.SerialNum,values.year, values.firmware, values.travel, values.pulsepermeasurementUnit, values.pulsesperDegree);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("C:\Users\BMOadmin2\Documents\Control_GUI\BRMscope\Functions")
            addpath("C:\Users\BMOadmin2\Documents\Control_GUI\BRMscope\Properties\")
            app.motor_properties = load("properties.mat").properties;  %% load in the properties file so we know which motor is which and some attributes that we want to be saveable 
            disp(fieldnames(app.motor_properties))
            drawnow;
            try
                address = rotation_motor.list_devices("COM4", '0','F');
                if length(address)<4
                    error('Error! Only %d Motors are connected. Make sure to check connections and Address of each motor',length(address))
                end
                for i = 1:length(address)
                    app.motor_struct(i).motor_handler = rotation_motor("COM4");
                    app.motor_struct(i).motor_handler.Connect(address{1,i});

                    label = app.motor_properties.motor_label{find(app.motor_properties.motor{1} == i)}
                    
                    app.motor_struct(i).motor_handler.motor_label = label;
                end

                app.illumination_pol = app.motor_struct(1).motor_handler;
                app.detection_pol = app.motor_struct(2).motor_handler;
                app.illumination_waveplate = app.motor_struct(3).motor_handler;
                app.detection_waveplate = app.motor_struct(4).motor_handler;

            catch ME
                errordlg(ME.message, 'ERROR','error')
            end
            motor_info(app,1)
        end

        % Button pushed function: HomeallmotorsButton
        function HomeallmotorsButtonPushed(app, event)
            for i = 1:length(app.motor_struct)
                app.motor_struct(i).motor_handler.Home();
            end
            disp('All Motors have been Homed')
        end

        % Button pushed function: CrossPolarizersButton
        function CrossPolarizersButtonPushed(app, event)
            tic 
            for i = 0:5:360
                app.motor_struct(3).motor_handler.MoveAbsolute(i);
            end
            toc
        end

        % Button pushed function: DisconnectAllMotorsButton
        function DisconnectAllMotorsButtonPushed(app, event)
            for i = 1:length(app.motor_struct)
                app.motor_struct(i).motor_handler.Disconnect();
            end
        end

        % Value changed function: DetectionPolarizerDropDown, 
        % DetectionWaveplateDropDown, IlluminationPolarizerDropDown, 
        % IlluminationWaveplateDropDown
        function IlluminationPolarizerDropDownValueChanged(app, event)
            disp('Changed Motor')

            % need to change selected motors and then be able to move them
            app.illumination_pol = app.motor_struct(app.IlluminationPolarizerDropDown.Value).motor_handler;
            app.illumination_waveplate = app.motor_struct(app.IlluminationWaveplateDropDown.Value).motor_handler;
            app.detection_pol = app.motor_struct(app.DetectionPolarizerDropDown.Value).motor_handler;
            app.detection_waveplate = app.motor_struct(app.DetectionWaveplateDropDown.Value).motor_handler;
        end

        % Button pushed function: MoveIllPolarizerby180degreesButton
        function MoveIllPolarizerby180degreesButtonPushed(app, event)
            app.illumination_pol.MoveAbsolute(180);
        end

        % Value changed function: MotorInfoListBox
        function MotorInfoListBoxValueChanged(app, event)
            motor_info(app,app.MotorInfoListBox.Value);
            
        end

        % Button pushed function: MovebyXdegreesButton
        function MovebyXdegreesButtonPushed(app, event)
            for i = 0:5:180
                app.motor_struct(2).motor_handler.MoveAbsolute(i);
                input(sprintf('Current Posisiton %d. Press enter to continue', i));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectMotorsButton = uibutton(app.UIFigure, 'push');
            app.ConnectMotorsButton.Text = 'Connect Motors';
            app.CrossPolarizersButton = uibutton(app.UIFigure, 'push');
            app.CrossPolarizersButton.ButtonPushedFcn = createCallbackFcn(app, @CrossPolarizersButtonPushed, true);
            app.CrossPolarizersButton.Text = 'Cross Polarizers';
            app.HomeallmotorsButton = uibutton(app.UIFigure, 'push');
            app.HomeallmotorsButton.ButtonPushedFcn = createCallbackFcn(app, @HomeallmotorsButtonPushed, true);
            app.HomeallmotorsButton.Text = 'Home all motors';
            app.DisconnectAllMotorsButton = uibutton(app.UIFigure, 'push');
            app.DisconnectAllMotorsButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectAllMotorsButtonPushed, true);
            app.DisconnectAllMotorsButton.Text = 'Disconnect All Motors';
            app.IlluminationPolarizerDropDownLabel = uilabel(app.UIFigure);
            app.IlluminationPolarizerDropDownLabel.Text = 'Illumination Polarizer';
            app.IlluminationPolarizerDropDown = uidropdown(app.UIFigure);
            app.IlluminationPolarizerDropDown.Items = {'Motor 1', 'Motor 2', 'Motor 3', 'Motor 4'};
            app.IlluminationPolarizerDropDown.ItemsData = [1 2 3 4];
            app.IlluminationPolarizerDropDown.ValueChangedFcn = createCallbackFcn(app, @IlluminationPolarizerDropDownValueChanged, true);
            app.IlluminationPolarizerDropDown.Value = 1;
            app.IlluminationWaveplateDropDownLabel = uilabel(app.UIFigure);
            app.IlluminationWaveplateDropDownLabel.Text = 'Illumination Waveplate';
            app.IlluminationWaveplateDropDown = uidropdown(app.UIFigure);
            app.IlluminationWaveplateDropDown.Items = {'Motor 1', 'Motor 2', 'Motor 3', 'Motor 4'};
            app.IlluminationWaveplateDropDown.ItemsData = [1 2 3 4];
            app.IlluminationWaveplateDropDown.ValueChangedFcn = createCallbackFcn(app, @IlluminationPolarizerDropDownValueChanged, true);
            app.IlluminationWaveplateDropDown.Value = 3;
            app.DetectionPolarizerDropDownLabel = uilabel(app.UIFigure);
            app.DetectionPolarizerDropDownLabel.Text = 'Detection Polarizer';
            app.DetectionPolarizerDropDown = uidropdown(app.UIFigure);
            app.DetectionPolarizerDropDown.Items = {'Motor 1', 'Motor 2', 'Motor 3', 'Motor 4'};
            app.DetectionPolarizerDropDown.ItemsData = [1 2 3 4];
            app.DetectionPolarizerDropDown.ValueChangedFcn = createCallbackFcn(app, @IlluminationPolarizerDropDownValueChanged, true);
            app.DetectionPolarizerDropDown.Value = 2;
            app.DetectionWaveplateDropDownLabel = uilabel(app.UIFigure);
            app.DetectionWaveplateDropDownLabel.Text = 'Detection Waveplate';
            app.DetectionWaveplateDropDown = uidropdown(app.UIFigure);
            app.DetectionWaveplateDropDown.Items = {'Motor 1', 'Motor 2', 'Motor 3', 'Motor 4'};
            app.DetectionWaveplateDropDown.ItemsData = [1 2 3 4];
            app.DetectionWaveplateDropDown.ValueChangedFcn = createCallbackFcn(app, @IlluminationPolarizerDropDownValueChanged, true);
            app.DetectionWaveplateDropDown.Value = 4;
            app.MoveIllPolarizerby180degreesButton = uibutton(app.UIFigure, 'push');
            app.MoveIllPolarizerby180degreesButton.ButtonPushedFcn = createCallbackFcn(app, @MoveIllPolarizerby180degreesButtonPushed, true);
            app.MoveIllPolarizerby180degreesButton.Text = 'Move Ill Polarizer by 180 degrees';
            app.MotorInfoListBoxLabel = uilabel(app.UIFigure);
            app.MotorInfoListBoxLabel.Text = 'Motor Info';
            app.MotorInfoListBox = uilistbox(app.UIFigure);
            app.MotorInfoListBox.Items = {'Motor 1', 'Motor 2', 'Motor 3', 'Motor 4'};
            app.MotorInfoListBox.ItemsData = [1 2 3 4];
            app.MotorInfoListBox.ValueChangedFcn = createCallbackFcn(app, @MotorInfoListBoxValueChanged, true);
            app.MotorInfoListBox.Value = 1;
            app.MotorInfoPanel = uipanel(app.UIFigure);
            app.MotorInfoPanel.Title = 'Motor Info';
            app.Label = uilabel(app.MotorInfoPanel);
            app.Label.Text = '';
            app.MovebyXdegreesButton = uibutton(app.UIFigure, 'push');
            app.MovebyXdegreesButton.ButtonPushedFcn = createCallbackFcn(app, @MovebyXdegreesButtonPushed, true);
            app.MovebyXdegreesButton.Text = 'Move by X degrees';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Motor_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end