classdef Polarization_camera_preview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        StopCameraButton   matlab.ui.control.Button
        StartCameraButton  matlab.ui.control.Button
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        keep_running = 0; % Description
        tlCameraSDK;
        tlCamera;
        hImage;
        hImage2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd("C:\Program Files\Thorlabs\Scientific Imaging\ThorCam\")
            addpath("C:\Program Files\Thorlabs\Scientific Imaging\ThorCam\")
            addpath("C:\Program Files\Thorlabs\Scientific Imaging")
            NET.addAssembly("C:\Program Files\Thorlabs\Scientific Imaging\ThorCam\Thorlabs.TSI.TLCamera.dll");
            disp('Dot NET assembly loaded.');

            app.hImage = imshow(0,'Parent',app.UIAxes);
            app.hImage2 = imshow(0,'Parent',app.UIAxes_2);
        end

        % Button pushed function: StartCameraButton
        function StartCameraButtonPushed(app, event)
            app.tlCameraSDK = Thorlabs.TSI.TLCamera.TLCameraSDK.OpenTLCameraSDK;

            % Get serial numbers of connected TLCameras.
            serialNumbers = app.tlCameraSDK.DiscoverAvailableCameras;
            disp([num2str(serialNumbers.Count), ' camera was discovered.']);

            if (serialNumbers.Count > 0)
                % Open the first TLCamera using the serial number.
                disp('Opening the first camera')
                app.tlCamera = app.tlCameraSDK.OpenCamera(serialNumbers.Item(0), false);

                % Set exposure time and gain of the camera.
                app.tlCamera.ExposureTime_us = 200000;

                % Check if the camera supports setting "Gain"
                gainRange = app.tlCamera.GainRange;
                if (gainRange.Maximum > 0)
                    app.tlCamera.Gain = 0;
                end
            end

            % Set the FIFO frame buffer size. Default size is 1.
            app.tlCamera.MaximumNumberOfFramesToQueue = 5;

            % Start continuous image acquisition
            disp('Starting continuous image acquisition.');
            app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
            app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
            app.tlCamera.Arm;
            app.tlCamera.IssueSoftwareTrigger;
            maxPixelIntensity = double(2^app.tlCamera.BitDepth - 1);
            app.keep_running = 1;

            numberOfFramesToAcquire = 150;
            movie_array = zeros(1224,1024,3,numberOfFramesToAcquire);
            frameCount = 0;
            numberOfFramesToAcquire = 150;
            movie_array = zeros(1224,1024,3,numberOfFramesToAcquire);
            frameCount = 0;
            tic
            %while frameCount < numberOfFramesToAcquire
            while app.keep_running
                % Check if image buffer has been filled
                if (app.tlCamera.NumberOfQueuedFrames > 0)

                    % If data processing in Matlab falls behind camera image
                    % acquisition, the FIFO image frame buffer could overflow,
                    % which would result in missed frames.
                    if (app.tlCamera.NumberOfQueuedFrames > 1)
                        disp(['Data processing falling behind acquisition. ' num2str(app.tlCamera.NumberOfQueuedFrames) ' remains']);
                    end

                    % Get the pending image frame.
                    imageFrame = app.tlCamera.GetPendingFrameOrNull;
                    if ~isempty(imageFrame)
                        frameCount = frameCount + 1;

                        % Get the image data as 1D uint16 array
                        imageData = uint16(imageFrame.ImageData.ImageData_monoOrBGR);

                        %disp(['Image frame number: ' num2str(imageFrame.FrameNumber)]);

                        % TODO: custom image processing code goes here
                        imageHeight = imageFrame.ImageData.Height_pixels;
                        imageWidth = imageFrame.ImageData.Width_pixels;
                        imageData2D = reshape(imageData, [imageWidth, imageHeight]);

                        %create RGB image from three polarizers
                        polar_img(:,:,1) = imageData2D(1:2:end,1:2:end).*16;
                        polar_img(:,:,2) = imageData2D(2:2:end,1:2:end).*16;
                        polar_img(:,:,3) = imageData2D(2:2:end,2:2:end).*16;
                        
                        img = double(polar_img);

                        I1 = img(:,:,1);
                        I2 = img(:,:,2);
                        I3 = img(:,:,3);

                        theta = 2*30*pi/180;
                        % solve
                        C_pr = (I1+I3)./2;
                        asinb = (I1-C_pr);
                        acosb = (I2-C_pr-asinb.*cos(theta))./sin(theta);
                        A= sqrt(asinb.^2+acosb.^2);

                        % create retardance and oreintation maps from 3 points
%                         [phi, A, C] = solve_qBRM(polar_img);
%                         this_ax = subplot(1,2,1);
%                         imshow(phi)
%                         set(this_ax, 'CLim', [0,pi]);
%                         colormap(cmap)
%                         clim([0,pi])
%                         that_ax = subplot(1,2,2);
%                         abs_hot = A./C;
%                         imagesc(abs_hot);
%                         axis image;
%                         colorbar;
%                         set(gca,'XTick',[],'YTick',[]);
%                         set(that_ax, 'Colormap', hot)
%                         %cl = prctile(abs_hot(:), [7 99.9]);
%                         cl = [0.0, 1.0];
%                         set(that_ax, 'CLim', [cl(1),cl(2)]);
                        
                        set(app.hImage2,'cData',A./C_pr)
                        set(app.hImage,'cData',uint16(polar_img))
                        %movie_array(:,:,:,frameCount) = polar_img;
                        %imshow(uint16(polar_img), 'Parent',app.UIAxes)
                    end
                    
                    % Release the image frame
                    delete(imageFrame);
                end
                drawnow;
            end
        end

        % Button pushed function: StopCameraButton
        function StopCameraButtonPushed(app, event)
            app.keep_running = 0;
            % Stop continuous image acquisition
            disp('Stopping continuous image acquisition.');
            app.tlCamera.Disarm;

            % Release the TLCamera
            disp('Releasing the camera');
            app.tlCamera.Dispose;

            % Release the TLCameraSDK.
            app.tlCameraSDK.Dispose;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.keep_running = 0;
            % Stop continuous image acquisition
            disp('Stopping continuous image acquisition.');
            app.tlCamera.Disarm;

            % Release the TLCamera
            disp('Releasing the camera');
            app.tlCamera.Dispose;
            delete(app.tlCamera);

            % Release the TLCameraSDK.
            app.tlCameraSDK.Dispose;
            delete(app.tlCameraSDK);
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.StartCameraButton = uibutton(app.UIFigure, 'push');
            app.StartCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StartCameraButtonPushed, true);
            app.StartCameraButton.Text = 'Start Camera';
            app.StopCameraButton = uibutton(app.UIFigure, 'push');
            app.StopCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StopCameraButtonPushed, true);
            app.StopCameraButton.Text = 'Stop Camera';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Polarization_camera_preview

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end