classdef motorXY_test_grid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Grid_axis  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        motor_x;
        motor_y;% Description
        current_grid_selection = [1 1];
    end
    
    methods (Access = private)
        
        function update_grid(app)
            FOV_microns_y = 100*2200./(100*4/4.5); % First column --> y
            FOV_microns_x = 100*3200./(100*4/4.5); % Second column --> x
            FOV_microns_stitching_y = FOV_microns_y*(0.9);
            FOV_microns_stitching_x = FOV_microns_x*(0.9);

            cla(app.Grid_axis,'reset') %reset axis
            %app.rect_handler = gobjects(app.Grid_xentry.Value,app.Grid_yentry.Value); % reinitialize the grid plot handler
            x = app.Grid_xentry.Value;
            y = app.Grid_yentry.Value;
            Generatearray = @(A,S,N) A+S*(0:N-1);

            x_max = 55; % the actual position of the ROI in the Img space
            x_step = FOV_microns_stitching_x/1000;
            %x_min = x_max - x*x_step;
            %center_posx = Generatearray(x_max,-x_step,x);
            %center_posx = x_max:-x_step:x_min;

            y_max = 37.5;  % its actually the min value but its the top left of our matrix
            y_step = FOV_microns_stitching_y/1000;
            %y_min = y_max + y*y_step;
            %center_posy = Generatearray(y_max,y_step,y);
            %center_posy = y_max:y_step:y_min;

            app.gridtile_array = gobjects(x,y); % reinitialize the grid plot handler
            %app.image_rectangles = gobjects(x,y); % reinitialize the grid plot handler
            for row = x:-1:1
                for col = y:-1:1
                    app.gridtile_array(col, row) = rectangle('Position', [(row-1), (col-1), 1, 1],...
                        'Parent', app.Grid_axis, ...
                        'EdgeColor', 'red', ...
                        'FaceColor', [0, 0, 0]);
                end
            end
            app.gridtile_array(app.current_grid_selection(2), app.current_grid_selection(1)).EdgeColor = [1 1 0];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("C:\Users\BMOadmin2\Documents\Control_GUI\BRMscope\Functions")
                addpath("C:\Users\BMOadmin2\Documents\Control_GUI\BRMscope\Properties\")
                app.motor_properties = load("properties.mat").properties;  %% load in the properties file so we know which motor is which and some attributes that we want to be saveable
        
                [app.motor_x, app.motor_y] = motor_init(app.motor_properties.XYMotorSN);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grid_axis = uiaxes(app.UIFigure);
            title(app.Grid_axis, 'Title')
            xlabel(app.Grid_axis, 'X')
            ylabel(app.Grid_axis, 'Y')
            zlabel(app.Grid_axis, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = motorXY_test_grid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end